classdef Covid19Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CTMUIFigure                     matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        SampleFilesMenu                 matlab.ui.container.Menu
        RegionInfoMenu                  matlab.ui.container.Menu
        RealDataMenu                    matlab.ui.container.Menu
        ParametersMenu                  matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        HelpMenu_2                      matlab.ui.container.Menu
        AboutCTMMenu                    matlab.ui.container.Menu
        RunButton                       matlab.ui.control.Button
        RegionDropDown                  matlab.ui.control.DropDown
        RegionalInformationPanel        matlab.ui.container.Panel
        millionsEditFieldLabel          matlab.ui.control.Label
        millionsEditField               matlab.ui.control.NumericEditField
        Below10EditField                matlab.ui.control.NumericEditField
        Between10and60EditField         matlab.ui.control.NumericEditField
        Above60EditField                matlab.ui.control.NumericEditField
        AgewiseDistributionLabel        matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Information                     matlab.ui.container.TabGroup
        InfectionRelatedInformationTab  matlab.ui.container.Tab
        FractioninfectedshowingsymptomsEditFieldLabel  matlab.ui.control.Label
        FractioninfectedshowingsymptomsEditField  matlab.ui.control.NumericEditField
        HowlongwillittaketoshowsymptomsdaysEditFieldLabel  matlab.ui.control.Label
        HowlongwillittaketoshowsymptomsdaysEditField  matlab.ui.control.NumericEditField
        HowlongwillittaketorecoverdaysEditFieldLabel  matlab.ui.control.Label
        HowlongwillittaketorecoverdaysEditField  matlab.ui.control.NumericEditField
        HowlongwillimmunitylastdaysEditFieldLabel  matlab.ui.control.Label
        HowlongwillimmunitylastdaysEditField  matlab.ui.control.NumericEditField
        TabGroup3                       matlab.ui.container.TabGroup
        Below10Tab                      matlab.ui.container.Tab
        SymptomaticTreatedEditFieldLabel  matlab.ui.control.Label
        SymptomaticTreatedEditField     matlab.ui.control.NumericEditField
        AsymptomaticTreatedEditFieldLabel  matlab.ui.control.Label
        AsymptomaticTreatedEditField    matlab.ui.control.NumericEditField
        AsymptomaticUntreatedEditFieldLabel  matlab.ui.control.Label
        AsymptomaticUntreatedEditField  matlab.ui.control.NumericEditField
        SymptomaticUntreatedEditFieldLabel  matlab.ui.control.Label
        SymptomaticUntreatedEditField   matlab.ui.control.NumericEditField
        Image4_3                        matlab.ui.control.Image
        Between10and60Tab               matlab.ui.container.Tab
        Image5_2                        matlab.ui.control.Image
        SymptomaticTreatedEditField_2Label  matlab.ui.control.Label
        SymptomaticTreatedEditField_2   matlab.ui.control.NumericEditField
        AsymptomaticTreatedEditField_2Label  matlab.ui.control.Label
        AsymptomaticTreatedEditField_2  matlab.ui.control.NumericEditField
        AsymptomaticUntreatedEditField_2Label  matlab.ui.control.Label
        AsymptomaticUntreatedEditField_2  matlab.ui.control.NumericEditField
        SymptomaticUntreatedEditField_2Label  matlab.ui.control.Label
        SymptomaticUntreatedEditField_2  matlab.ui.control.NumericEditField
        Above60Tab                      matlab.ui.container.Tab
        Image6_2                        matlab.ui.control.Image
        SymptomaticTreatedEditField_3Label  matlab.ui.control.Label
        SymptomaticTreatedEditField_3   matlab.ui.control.NumericEditField
        AsymptomaticTreatedEditField_3Label  matlab.ui.control.Label
        AsymptomaticTreatedEditField_3  matlab.ui.control.NumericEditField
        AsymptomaticUntreatedEditField_3Label  matlab.ui.control.Label
        AsymptomaticUntreatedEditField_3  matlab.ui.control.NumericEditField
        SymptomaticUntreatedEditField_3Label  matlab.ui.control.Label
        SymptomaticUntreatedEditField_3  matlab.ui.control.NumericEditField
        DeathRateLabel                  matlab.ui.control.Label
        HowlongwillvaccineimmunitylastdaysEditFieldLabel  matlab.ui.control.Label
        HowlongwillvaccineimmunitylastdaysEditField  matlab.ui.control.NumericEditField
        DataTab                         matlab.ui.container.Tab
        LoadDataFromxlsxEditFieldLabel  matlab.ui.control.Label
        LoadDataFromxlsxEditField       matlab.ui.control.EditField
        ShowButtonGroup                 matlab.ui.container.ButtonGroup
        ModelResultsOnlyButton          matlab.ui.control.RadioButton
        ModelandActualDataButton        matlab.ui.control.RadioButton
        DataBrowseButton                matlab.ui.control.Button
        StartingConditionsTab           matlab.ui.container.Tab
        exposedEditField_2Label         matlab.ui.control.Label
        exposedEditField_2              matlab.ui.control.NumericEditField
        infectedshowingsymptomquarantinedEditField_2Label  matlab.ui.control.Label
        infectedshowingsymptomquarantinedEditField_2  matlab.ui.control.NumericEditField
        infectedshowingsymptomnotquarantinedEditField_2Label  matlab.ui.control.Label
        infectedshowingsymptomnotquarantinedEditField_2  matlab.ui.control.NumericEditField
        infectednosymptomquarantinedEditField_2Label  matlab.ui.control.Label
        infectednosymptomquarantinedEditField_2  matlab.ui.control.NumericEditField
        infectednosymptomnotquarantinedEditField_2Label  matlab.ui.control.Label
        infectednosymptomnotquarantinedEditField_2  matlab.ui.control.NumericEditField
        showingsymptomrecoveredEditField_2Label  matlab.ui.control.Label
        showingsymptomrecoveredEditField_2  matlab.ui.control.NumericEditField
        showingnosymptomrecoveredEditField_2Label  matlab.ui.control.Label
        showingnosymptomrecoveredEditField_2  matlab.ui.control.NumericEditField
        showingsymptomdeadEditField_2Label  matlab.ui.control.Label
        showingsymptomdeadEditField_2   matlab.ui.control.NumericEditField
        showingnosymptomdeadEditField_2Label  matlab.ui.control.Label
        showingnosymptomdeadEditField_2  matlab.ui.control.NumericEditField
        VaccinatedEditFieldLabel        matlab.ui.control.Label
        VaccinatedEditField             matlab.ui.control.NumericEditField
        SpreadingVaccinationDetailsTab  matlab.ui.container.Tab
        TabGroup4_1                     matlab.ui.container.TabGroup
        Mar20Tab_1                      matlab.ui.container.Tab
        DuringthismonthPanel_01         matlab.ui.container.Panel
        BasicreproductionnumberLabel_01  matlab.ui.control.Label
        R0SymptomaticEditField_21Label  matlab.ui.control.Label
        R0SymptomaticEditField_01       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_20Label  matlab.ui.control.Label
        R0AsymptomaticEditField_01      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedLabel  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_01  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_23Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_01  matlab.ui.control.NumericEditField
        Apr20Tab_1                      matlab.ui.container.Tab
        DuringthismonthPanel_02         matlab.ui.container.Panel
        BasicreproductionnumberLabel_02  matlab.ui.control.Label
        R0SymptomaticEditField_22Label  matlab.ui.control.Label
        R0SymptomaticEditField_02       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_21Label  matlab.ui.control.Label
        R0AsymptomaticEditField_02      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditFieldLabel  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_02  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditFieldLabel  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_02  matlab.ui.control.NumericEditField
        May20Tab_1                      matlab.ui.container.Tab
        DuringthismonthPanel_03         matlab.ui.container.Panel
        BasicreproductionnumberLabel_03  matlab.ui.control.Label
        R0SymptomaticEditField_23Label  matlab.ui.control.Label
        R0SymptomaticEditField_03       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_22Label  matlab.ui.control.Label
        R0AsymptomaticEditField_03      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_2Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_03  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_2Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_03  matlab.ui.control.NumericEditField
        Jun20Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_04         matlab.ui.container.Panel
        BasicreproductionnumberLabel_04  matlab.ui.control.Label
        R0SymptomaticEditField_4Label   matlab.ui.control.Label
        R0SymptomaticEditField_04       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_4Label  matlab.ui.control.Label
        R0AsymptomaticEditField_04      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_3Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_04  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_3Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_04  matlab.ui.control.NumericEditField
        Jul20Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_5          matlab.ui.container.Panel
        BasicreproductionnumberLabel_5  matlab.ui.control.Label
        R0SymptomaticEditField_5Label   matlab.ui.control.Label
        R0SymptomaticEditField_05       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_5Label  matlab.ui.control.Label
        R0AsymptomaticEditField_05      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_4Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_05  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_4Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_05  matlab.ui.control.NumericEditField
        Aug20Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_6          matlab.ui.container.Panel
        BasicreproductionnumberLabel_6  matlab.ui.control.Label
        R0SymptomaticEditField_6Label   matlab.ui.control.Label
        R0SymptomaticEditField_06       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_6Label  matlab.ui.control.Label
        R0AsymptomaticEditField_06      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_5Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_06  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_5Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_06  matlab.ui.control.NumericEditField
        Sep20Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_7          matlab.ui.container.Panel
        BasicreproductionnumberLabel_7  matlab.ui.control.Label
        R0SymptomaticEditField_7Label   matlab.ui.control.Label
        R0SymptomaticEditField_07       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_7Label  matlab.ui.control.Label
        R0AsymptomaticEditField_07      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_6Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_07  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_6Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_07  matlab.ui.control.NumericEditField
        Oct20Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_8          matlab.ui.container.Panel
        BasicreproductionnumberLabel_8  matlab.ui.control.Label
        R0SymptomaticEditField_8Label   matlab.ui.control.Label
        R0SymptomaticEditField_08       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_8Label  matlab.ui.control.Label
        R0AsymptomaticEditField_08      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_7Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_08  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_7Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_08  matlab.ui.control.NumericEditField
        Nov20Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_9          matlab.ui.container.Panel
        BasicreproductionnumberLabel_9  matlab.ui.control.Label
        R0SymptomaticEditField_9Label   matlab.ui.control.Label
        R0SymptomaticEditField_09       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_9Label  matlab.ui.control.Label
        R0AsymptomaticEditField_09      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_8Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_09  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_8Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_09  matlab.ui.control.NumericEditField
        Dec20Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_10         matlab.ui.container.Panel
        BasicreproductionnumberLabel_10  matlab.ui.control.Label
        R0SymptomaticEditField_10Label  matlab.ui.control.Label
        R0SymptomaticEditField_10       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_10Label  matlab.ui.control.Label
        R0AsymptomaticEditField_10      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_9Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_10  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_9Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_10  matlab.ui.control.NumericEditField
        Jan21Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_11         matlab.ui.container.Panel
        BasicreproductionnumberLabel_11  matlab.ui.control.Label
        R0SymptomaticEditField_11Label  matlab.ui.control.Label
        R0SymptomaticEditField_11       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_11Label  matlab.ui.control.Label
        R0AsymptomaticEditField_11      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_10Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_11  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_10Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_11  matlab.ui.control.NumericEditField
        FractionVaccinatedLabel_11      matlab.ui.control.Label
        FractionAdultsEditFieldLabel    matlab.ui.control.Label
        FractionAdultsEditField_11      matlab.ui.control.NumericEditField
        FractionSeniorCitizensEditFieldLabel  matlab.ui.control.Label
        FractionSeniorCitizensEditField_11  matlab.ui.control.NumericEditField
        Feb21Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_12         matlab.ui.container.Panel
        BasicreproductionnumberLabel_12  matlab.ui.control.Label
        R0SymptomaticEditField_12Label  matlab.ui.control.Label
        R0SymptomaticEditField_12       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_12Label  matlab.ui.control.Label
        R0AsymptomaticEditField_12      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_11Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_12  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_11Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_12  matlab.ui.control.NumericEditField
        FractionVaccinatedLabel_12      matlab.ui.control.Label
        FractionAdultsEditField_12Label  matlab.ui.control.Label
        FractionAdultsEditField_12      matlab.ui.control.NumericEditField
        FractionSeniorCitizensEditField_12Label  matlab.ui.control.Label
        FractionSeniorCitizensEditField_12  matlab.ui.control.NumericEditField
        Mar21Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_13         matlab.ui.container.Panel
        BasicreproductionnumberLabel_13  matlab.ui.control.Label
        R0SymptomaticEditField_13Label  matlab.ui.control.Label
        R0SymptomaticEditField_13       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_13Label  matlab.ui.control.Label
        R0AsymptomaticEditField_13      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_12Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_13  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_12Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_13  matlab.ui.control.NumericEditField
        FractionVaccinatedLabel_13      matlab.ui.control.Label
        FractionAdultsEditField_13Label  matlab.ui.control.Label
        FractionAdultsEditField_13      matlab.ui.control.NumericEditField
        FractionSeniorCitizensEditField_13Label  matlab.ui.control.Label
        FractionSeniorCitizensEditField_13  matlab.ui.control.NumericEditField
        Apr21Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_14         matlab.ui.container.Panel
        BasicreproductionnumberLabel_14  matlab.ui.control.Label
        R0SymptomaticEditField_14Label  matlab.ui.control.Label
        R0SymptomaticEditField_14       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_14Label  matlab.ui.control.Label
        R0AsymptomaticEditField_14      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_13Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_14  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_13Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_14  matlab.ui.control.NumericEditField
        FractionVaccinatedLabel_14      matlab.ui.control.Label
        FractionAdultsEditField_14Label  matlab.ui.control.Label
        FractionAdultsEditField_14      matlab.ui.control.NumericEditField
        FractionSeniorCitizensEditField_14Label  matlab.ui.control.Label
        FractionSeniorCitizensEditField_14  matlab.ui.control.NumericEditField
        May21Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_15         matlab.ui.container.Panel
        BasicreproductionnumberLabel_15  matlab.ui.control.Label
        R0SymptomaticEditField_15Label  matlab.ui.control.Label
        R0SymptomaticEditField_15       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_15Label  matlab.ui.control.Label
        R0AsymptomaticEditField_15      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_14Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_15  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_14Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_15  matlab.ui.control.NumericEditField
        FractionVaccinatedLabel_15      matlab.ui.control.Label
        FractionAdultsEditField_15Label  matlab.ui.control.Label
        FractionAdultsEditField_15      matlab.ui.control.NumericEditField
        FractionSeniorCitizensEditField_15Label  matlab.ui.control.Label
        FractionSeniorCitizensEditField_15  matlab.ui.control.NumericEditField
        Jun21Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_16         matlab.ui.container.Panel
        BasicreproductionnumberLabel_16  matlab.ui.control.Label
        R0SymptomaticEditField_16Label  matlab.ui.control.Label
        R0SymptomaticEditField_16       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_16Label  matlab.ui.control.Label
        R0AsymptomaticEditField_16      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_15Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_16  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_15Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_16  matlab.ui.control.NumericEditField
        FractionVaccinatedLabel_16      matlab.ui.control.Label
        FractionAdultsEditField_16Label  matlab.ui.control.Label
        FractionAdultsEditField_16      matlab.ui.control.NumericEditField
        FractionSeniorCitizensEditField_16Label  matlab.ui.control.Label
        FractionSeniorCitizensEditField_16  matlab.ui.control.NumericEditField
        Jul21Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_17         matlab.ui.container.Panel
        BasicreproductionnumberLabel_17  matlab.ui.control.Label
        R0SymptomaticEditField_17Label  matlab.ui.control.Label
        R0SymptomaticEditField_17       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_17Label  matlab.ui.control.Label
        R0AsymptomaticEditField_17      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_16Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_17  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_16Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_17  matlab.ui.control.NumericEditField
        FractionVaccinatedLabel_17      matlab.ui.control.Label
        FractionAdultsEditField_17Label  matlab.ui.control.Label
        FractionAdultsEditField_17      matlab.ui.control.NumericEditField
        FractionSeniorCitizensEditField_17Label  matlab.ui.control.Label
        FractionSeniorCitizensEditField_17  matlab.ui.control.NumericEditField
        Aug21Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_18         matlab.ui.container.Panel
        BasicreproductionnumberLabel_18  matlab.ui.control.Label
        R0SymptomaticEditField_18Label  matlab.ui.control.Label
        R0SymptomaticEditField_18       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_18Label  matlab.ui.control.Label
        R0AsymptomaticEditField_18      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_17Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_18  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_17Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_18  matlab.ui.control.NumericEditField
        FractionVaccinatedLabel_18      matlab.ui.control.Label
        FractionAdultsEditField_18Label  matlab.ui.control.Label
        FractionAdultsEditField_18      matlab.ui.control.NumericEditField
        FractionSeniorCitizensEditField_18Label  matlab.ui.control.Label
        FractionSeniorCitizensEditField_18  matlab.ui.control.NumericEditField
        Sep21Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_19         matlab.ui.container.Panel
        BasicreproductionnumberLabel_19  matlab.ui.control.Label
        R0SymptomaticEditField_19Label  matlab.ui.control.Label
        R0SymptomaticEditField_19       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_19Label  matlab.ui.control.Label
        R0AsymptomaticEditField_19      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_18Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_19  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_18Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_19  matlab.ui.control.NumericEditField
        FractionVaccinatedLabel_19      matlab.ui.control.Label
        FractionAdultsEditField_19Label  matlab.ui.control.Label
        FractionAdultsEditField_19      matlab.ui.control.NumericEditField
        FractionSeniorCitizensEditField_19Label  matlab.ui.control.Label
        FractionSeniorCitizensEditField_19  matlab.ui.control.NumericEditField
        Oct21Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_20         matlab.ui.container.Panel
        BasicreproductionnumberLabel_20  matlab.ui.control.Label
        R0SymptomaticEditField_20Label  matlab.ui.control.Label
        R0SymptomaticEditField_20       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_20Label_2  matlab.ui.control.Label
        R0AsymptomaticEditField_20      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_19Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_20  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_19Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_20  matlab.ui.control.NumericEditField
        FractionVaccinatedLabel_20      matlab.ui.control.Label
        FractionAdultsEditField_20Label  matlab.ui.control.Label
        FractionAdultsEditField_20      matlab.ui.control.NumericEditField
        FractionSeniorCitizensEditField_20Label  matlab.ui.control.Label
        FractionSeniorCitizensEditField_20  matlab.ui.control.NumericEditField
        Nov21Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_21         matlab.ui.container.Panel
        BasicreproductionnumberLabel_21  matlab.ui.control.Label
        R0SymptomaticEditField_21Label_2  matlab.ui.control.Label
        R0SymptomaticEditField_21       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_21Label_2  matlab.ui.control.Label
        R0AsymptomaticEditField_21      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_20Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_21  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_20Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_21  matlab.ui.control.NumericEditField
        FractionVaccinatedLabel_21      matlab.ui.control.Label
        FractionAdultsEditField_21Label  matlab.ui.control.Label
        FractionAdultsEditField_21      matlab.ui.control.NumericEditField
        FractionSeniorCitizensEditField_21Label  matlab.ui.control.Label
        FractionSeniorCitizensEditField_21  matlab.ui.control.NumericEditField
        Dec21Tab                        matlab.ui.container.Tab
        DuringthismonthPanel_22         matlab.ui.container.Panel
        BasicreproductionnumberLabel_22  matlab.ui.control.Label
        R0SymptomaticEditField_22Label_2  matlab.ui.control.Label
        R0SymptomaticEditField_22       matlab.ui.control.NumericEditField
        R0AsymptomaticEditField_22Label_2  matlab.ui.control.Label
        R0AsymptomaticEditField_22      matlab.ui.control.NumericEditField
        FractionofsymptomaticpeopletreatedEditField_21Label  matlab.ui.control.Label
        FractionofsymptomaticpeopletreatedEditField_22  matlab.ui.control.NumericEditField
        FractionofAsymptomaticpeopletreatedEditField_21Label  matlab.ui.control.Label
        FractionofAsymptomaticpeopletreatedEditField_22  matlab.ui.control.NumericEditField
        FractionVaccinatedLabel_22      matlab.ui.control.Label
        FractionAdultsEditField_22Label  matlab.ui.control.Label
        FractionAdultsEditField_22      matlab.ui.control.NumericEditField
        FractionSeniorCitizensEditField_22Label  matlab.ui.control.Label
        FractionSeniorCitizensEditField_22  matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        VariationEditFieldLabel         matlab.ui.control.Label
        VariationEditField              matlab.ui.control.NumericEditField
        SimulationTypeButtonGroup       matlab.ui.container.ButtonGroup
        DeterministicDefaultButton      matlab.ui.control.RadioButton
        MonteCarloButton                matlab.ui.control.RadioButton
        RegionLabel                     matlab.ui.control.Label
        Image7                          matlab.ui.control.Image
        Image8                          matlab.ui.control.Image
        Image9                          matlab.ui.control.Image
        LoadRegionInfoButton            matlab.ui.control.Button
    end


    properties (Access = private)
 N_millions = 1375; % number of individuals in the population, in millions

 
% Age wise distribution

AdultFraction = 0.5;
SenCitizenFraction= 0.3;
ChildrenFraction = 0.2; 

SimulationType = 0; % Default is deterministic, =0.  MonteCarlo =1.
Variation_percent = 5; % Default variation in Monte Carlo simulation is 5%
NumRuns = 100;%Number of runs to perform, if we choose Monte Carlo type
 
% infection related

SymptomaticFraction = 0.5; % how much fraction of infected population shows symptom
Tau_E = 5; % Exposed period
Tau_I = 14; % infectious period (before recovery)
Tau_R = 90; % immunity duration, time spent in recovery period
Tau_V = 750; % Vaccine immunity duration


deathrate_Chi_Symptomatic_Treated = 0.01; % 
deathrate_Chi_Symptomatic_Untreated  = 0.02;%
deathrate_Chi_Asymptomatic_Treated = 0.005;% 
deathrate_Chi_Asymptomatic_Untreated  = 0.005;%

deathrate_Adu_Symptomatic_Treated = 0.01; % 
deathrate_Adu_Symptomatic_Untreated  = 0.02;%
deathrate_Adu_Asymptomatic_Treated = 0.005;% 
deathrate_Adu_Asymptomatic_Untreated  = 0.005;%

deathrate_Sen_Symptomatic_Treated = 0.02; % 
deathrate_Sen_Symptomatic_Untreated  = 0.04;%
deathrate_Sen_Asymptomatic_Treated = 0.01;% 
deathrate_Sen_Asymptomatic_Untreated  = 0.01;%

% Use dummy values here, fill with correct values later

NumMonths = 22;
% Spreading details

% Basic reproduction #
% R0 of symptomatic
R0_Array_Symptomatic = 2.2* ones(22,1);

% R0 of asymptomatic
R0_Array_Asymptomatic =  2.28* ones(22,1);

% Fraction children vaccinated
Array_Fraction_Chi_Vaccinated = zeros(22,1);

% Fraction adults vaccinated
Array_Fraction_Adu_Vaccinated =  zeros(22,1);
% Fraction senior citizens vaccinated
Array_Fraction_Sen_Vaccinated =  zeros(22,1);


%Fraction Symptomatic Treated
FractionTreated_Array_Symptomatic=  0.9* ones(22,1);

% Fraction Asymptomatic Treated
FractionTreated_Array_Asymptomatic= 0.01 * ones(22,1);


% Data
DataFileName = 'IndiaData.xlsx';
CompareWithData = 1; % 0 means don't compare with data, 1 means compare with data

% Initial Conditions
    Initial_exposed = 5000;
    Initial_Vaccinated = 0;
    Initial_Symptomatic_Infected_Quarantined = 1000;
    Initial_Symptomatic_Infected_NonQuarantined =100 ;
    Initial_Asymptomatic_Infected_Quarantined = 100 ;
    Initial_Asymptomatic_Infected_NonQuarantined =5000;
    Initial_Symptomatic_Recovered  = 1000;
    Initial_Asymptomatic_Recovered = 1000;
    Initial_Symptomatic_Dead=0;
    Initial_Asymptomatic_Dead=0;

%

UniformValues = 0; % No by default. 1 means yes. 0 means no.

% 
    ResultsFileName = 'C:\Covid19_Transmission_Model_Results.xlsx';
    RegionInformationFileName = "C:\RegionInformation.xlsx";
    RegionInformation;
    ParameterFileName;
    ParameterTable01;
    ParameterTable02;
    ParameterTable03;
    ParameterTable04;
    ParameterTable05;
    
    NumMatrix;ProcessedModelDataSet;ModelStdevDataSet;
    
RegionArray = {'India','Custom'}
RegionArrayLength = 1;
PopulationArray=[132 0];
ChildrenFractionArray =[0.2 0 ];
AdultFractionArray=[0.5 ] ;
SenCitizenFractionArray =[0.3 ];


RegionDataFileNames={'TT.xlsx','AP.xlsx'};
RegionParamFileNames = {'TT.xlsx','AP.xlsx'};


    end % properties, private


methods (Access = public)
        
function  LoadParamSet(app,filename)
        if isdeployed % Stand-alone mode.
            [status, result] = system('path');
            currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
        else % MATLAB mode.
            currentDir = pwd;
        end

% disp(currentDir)
app.ParameterFileName = append(currentDir, '\', filename);
% disp(app.ParameterFileName)

x = app.ParameterFileName;
% disp(x)
% class(x)


readtableBoolean = 1;
try
A= readtable(app.ParameterFileName,'Sheet','Monthly') ;%,'DataRange','A2' )
app.ParameterTable01 =A;
catch ME
readtableBoolean =0;
    uiwait(warndlg(ME.message));
end

if (readtableBoolean ==1)
% R0 symptomatic array
R0S = A{:,'R0Symptomatic'};
app.R0_Array_Symptomatic = R0S;


app.R0SymptomaticEditField_01.Value =R0S(1); 
app.R0SymptomaticEditField_02.Value =R0S(2); 
app.R0SymptomaticEditField_03.Value =R0S(3); 
app.R0SymptomaticEditField_04.Value =R0S(4); 
app.R0SymptomaticEditField_05.Value =R0S(5); 
app.R0SymptomaticEditField_06.Value =R0S(6); 
app.R0SymptomaticEditField_07.Value =R0S(7); 
app.R0SymptomaticEditField_08.Value =R0S(8); 
app.R0SymptomaticEditField_09.Value =R0S(9); 
app.R0SymptomaticEditField_10.Value =R0S(10); 
app.R0SymptomaticEditField_11.Value =R0S(11); 
app.R0SymptomaticEditField_12.Value =R0S(12); 
app.R0SymptomaticEditField_13.Value =R0S(13); 
app.R0SymptomaticEditField_14.Value =R0S(14); 
app.R0SymptomaticEditField_15.Value =R0S(15); 
app.R0SymptomaticEditField_16.Value =R0S(16); 
app.R0SymptomaticEditField_17.Value =R0S(17); 
app.R0SymptomaticEditField_18.Value =R0S(18); 
app.R0SymptomaticEditField_19.Value =R0S(19); 
app.R0SymptomaticEditField_20.Value =R0S(20); 
app.R0SymptomaticEditField_21.Value =R0S(21); 
app.R0SymptomaticEditField_22.Value =R0S(22); 

%R0 Asymptomatic array
R0A = A{:,'R0Asymptomatic'};

app.R0_Array_Asymptomatic = R0A;


app.R0AsymptomaticEditField_01.Value =R0A(1); 
app.R0AsymptomaticEditField_02.Value =R0A(2); 
app.R0AsymptomaticEditField_03.Value =R0A(3); 
app.R0AsymptomaticEditField_04.Value =R0A(4); 
app.R0AsymptomaticEditField_05.Value =R0A(5); 
app.R0AsymptomaticEditField_06.Value =R0A(6); 
app.R0AsymptomaticEditField_07.Value =R0A(7); 
app.R0AsymptomaticEditField_08.Value =R0A(8); 
app.R0AsymptomaticEditField_09.Value =R0A(9); 
app.R0AsymptomaticEditField_10.Value =R0A(10); 
app.R0AsymptomaticEditField_11.Value =R0A(11); 
app.R0AsymptomaticEditField_12.Value =R0A(12); 
app.R0AsymptomaticEditField_13.Value =R0A(13); 
app.R0AsymptomaticEditField_14.Value =R0A(14); 
app.R0AsymptomaticEditField_15.Value =R0A(15); 
app.R0AsymptomaticEditField_16.Value =R0A(16); 
app.R0AsymptomaticEditField_17.Value =R0A(17); 
app.R0AsymptomaticEditField_18.Value =R0A(18); 
app.R0AsymptomaticEditField_19.Value =R0A(19); 
app.R0AsymptomaticEditField_20.Value =R0A(20); 
app.R0AsymptomaticEditField_21.Value =R0A(21); 
app.R0AsymptomaticEditField_22.Value =R0A(22); 

%Symptomatic fraction treated
SFT = A{:,'SymptomaticFractionTreated'};
app.FractionTreated_Array_Symptomatic = SFT;


app.FractionofsymptomaticpeopletreatedEditField_01.Value =SFT(1); 
% app.FractionofsymptomaticpeopletreatedEditField_02
app.FractionofsymptomaticpeopletreatedEditField_02.Value =SFT(2); 
app.FractionofsymptomaticpeopletreatedEditField_03.Value =SFT(3); 
app.FractionofsymptomaticpeopletreatedEditField_04.Value =SFT(4); 
app.FractionofsymptomaticpeopletreatedEditField_05.Value =SFT(5); 
app.FractionofsymptomaticpeopletreatedEditField_06.Value =SFT(6); 
app.FractionofsymptomaticpeopletreatedEditField_07.Value =SFT(7); 
app.FractionofsymptomaticpeopletreatedEditField_08.Value =SFT(8); 
app.FractionofsymptomaticpeopletreatedEditField_09.Value =SFT(9); 
app.FractionofsymptomaticpeopletreatedEditField_10.Value =SFT(10); 
app.FractionofsymptomaticpeopletreatedEditField_11.Value =SFT(11); 
app.FractionofsymptomaticpeopletreatedEditField_12.Value =SFT(12); 
app.FractionofsymptomaticpeopletreatedEditField_13.Value =SFT(13); 
app.FractionofsymptomaticpeopletreatedEditField_14.Value =SFT(14); 
app.FractionofsymptomaticpeopletreatedEditField_15.Value =SFT(15); 
app.FractionofsymptomaticpeopletreatedEditField_16.Value =SFT(16); 
app.FractionofsymptomaticpeopletreatedEditField_17.Value =SFT(17); 
app.FractionofsymptomaticpeopletreatedEditField_18.Value =SFT(18); 
app.FractionofsymptomaticpeopletreatedEditField_19.Value =SFT(19); 
app.FractionofsymptomaticpeopletreatedEditField_20.Value =SFT(20); 
app.FractionofsymptomaticpeopletreatedEditField_21.Value =SFT(21); 
app.FractionofsymptomaticpeopletreatedEditField_22.Value =SFT(22); 

%Asymptomatic fraction treated
AFT = A{:,'AsymptomaticFractionTreated'};

app.FractionTreated_Array_Asymptomatic = AFT;

app.FractionofAsymptomaticpeopletreatedEditField_01.Value =AFT(1); 
app.FractionofAsymptomaticpeopletreatedEditField_02.Value =AFT(2); 
app.FractionofAsymptomaticpeopletreatedEditField_03.Value =AFT(3); 
app.FractionofAsymptomaticpeopletreatedEditField_04.Value =AFT(4); 
app.FractionofAsymptomaticpeopletreatedEditField_05.Value =AFT(5); 
app.FractionofAsymptomaticpeopletreatedEditField_06.Value =AFT(6); 
app.FractionofAsymptomaticpeopletreatedEditField_07.Value =AFT(7); 
app.FractionofAsymptomaticpeopletreatedEditField_08.Value =AFT(8); 
app.FractionofAsymptomaticpeopletreatedEditField_09.Value =AFT(9); 
app.FractionofAsymptomaticpeopletreatedEditField_10.Value =AFT(10); 
app.FractionofAsymptomaticpeopletreatedEditField_11.Value =AFT(11); 
app.FractionofAsymptomaticpeopletreatedEditField_12.Value =AFT(12); 
app.FractionofAsymptomaticpeopletreatedEditField_13.Value =AFT(13); 
app.FractionofAsymptomaticpeopletreatedEditField_14.Value =AFT(14); 
app.FractionofAsymptomaticpeopletreatedEditField_15.Value =AFT(15); 
app.FractionofAsymptomaticpeopletreatedEditField_16.Value =AFT(16); 
app.FractionofAsymptomaticpeopletreatedEditField_17.Value =AFT(17); 
app.FractionofAsymptomaticpeopletreatedEditField_18.Value =AFT(18); 
app.FractionofAsymptomaticpeopletreatedEditField_19.Value =AFT(19); 
app.FractionofAsymptomaticpeopletreatedEditField_20.Value =AFT(20); 
app.FractionofAsymptomaticpeopletreatedEditField_21.Value =AFT(21); 
app.FractionofAsymptomaticpeopletreatedEditField_22.Value =AFT(22); 


R0FracAduVac = A{:,'R0FracAduVac'};
app.Array_Fraction_Adu_Vaccinated= R0FracAduVac;


% app.FractionAdultsEditField_01.Value =R0FracAduVac(1); 
% app.FractionAdultsEditField_02.Value =R0FracAduVac(2); 
% app.FractionAdultsEditField_03.Value =R0FracAduVac(3); 
% app.FractionAdultsEditField_04.Value =R0FracAduVac(4); 
% app.FractionAdultsEditField_05.Value =R0FracAduVac(5); 
% app.FractionAdultsEditField_06.Value =R0FracAduVac(6); 
% app.FractionAdultsEditField_07.Value =R0FracAduVac(7); 
% app.FractionAdultsEditField_08.Value =R0FracAduVac(8); 
% app.FractionAdultsEditField_09.Value =R0FracAduVac(9); 
% app.FractionAdultsEditField_10.Value =R0FracAduVac(10); 
app.FractionAdultsEditField_11.Value =R0FracAduVac(11); 
app.FractionAdultsEditField_12.Value =R0FracAduVac(12); 
app.FractionAdultsEditField_13.Value =R0FracAduVac(13); 
app.FractionAdultsEditField_14.Value =R0FracAduVac(14); 
app.FractionAdultsEditField_15.Value =R0FracAduVac(15); 
app.FractionAdultsEditField_16.Value =R0FracAduVac(16); 
app.FractionAdultsEditField_17.Value =R0FracAduVac(17); 
app.FractionAdultsEditField_18.Value =R0FracAduVac(18); 
app.FractionAdultsEditField_19.Value =R0FracAduVac(19); 
app.FractionAdultsEditField_20.Value =R0FracAduVac(20); 
app.FractionAdultsEditField_21.Value =R0FracAduVac(21); 
app.FractionAdultsEditField_22.Value =R0FracAduVac(22); 


% Fraction Adults Vaccinated
R0FracSenVac = A{:,'R0FracSenVac'};
app.Array_Fraction_Sen_Vaccinated = R0FracSenVac;


% app.FractionSeniorCitizensEditField_01.Value =R0FracSenVac(1); 
% app.FractionSeniorCitizensEditField_02.Value =R0FracSenVac(2); 
% app.FractionSeniorCitizensEditField_03.Value =R0FracSenVac(3); 
% app.FractionSeniorCitizensEditField_04.Value =R0FracSenVac(4); 
% app.FractionSeniorCitizensEditField_05.Value =R0FracSenVac(5); 
% app.FractionSeniorCitizensEditField_06.Value =R0FracSenVac(6); 
% app.FractionSeniorCitizensEditField_07.Value =R0FracSenVac(7); 
% app.FractionSeniorCitizensEditField_08.Value =R0FracSenVac(8); 
% app.FractionSeniorCitizensEditField_09.Value =R0FracSenVac(9); 
% app.FractionSeniorCitizensEditField_10.Value =R0FracSenVac(10); 
app.FractionSeniorCitizensEditField_11.Value =R0FracSenVac(11); 
app.FractionSeniorCitizensEditField_12.Value =R0FracSenVac(12); 
app.FractionSeniorCitizensEditField_13.Value =R0FracSenVac(13); 
app.FractionSeniorCitizensEditField_14.Value =R0FracSenVac(14); 
app.FractionSeniorCitizensEditField_15.Value =R0FracSenVac(15); 
app.FractionSeniorCitizensEditField_16.Value =R0FracSenVac(16); 
app.FractionSeniorCitizensEditField_17.Value =R0FracSenVac(17); 
app.FractionSeniorCitizensEditField_18.Value =R0FracSenVac(18); 
app.FractionSeniorCitizensEditField_19.Value =R0FracSenVac(19); 
app.FractionSeniorCitizensEditField_20.Value =R0FracSenVac(20); 
app.FractionSeniorCitizensEditField_21.Value =R0FracSenVac(21); 
app.FractionSeniorCitizensEditField_22.Value =R0FracSenVac(22); 

% Next we need to assign values to the parameters in each month.

% app.R0SymptomaticEditField_01.Value =

clear A;
A= readtable(app.ParameterFileName,'Sheet','DeathRate');%,'DataRange','A2' )
app.ParameterTable02 =A;

app.SymptomaticTreatedEditField.Value =A{1,'DeathRateChild'};
app.SymptomaticUntreatedEditField.Value =A{2,'DeathRateChild'};
app.AsymptomaticTreatedEditField.Value =A{3,'DeathRateChild'};
app.AsymptomaticUntreatedEditField.Value =A{4,'DeathRateChild'};

app.deathrate_Chi_Symptomatic_Treated=app.SymptomaticTreatedEditField.Value ;
app.deathrate_Chi_Symptomatic_Untreated=app.SymptomaticUntreatedEditField.Value ;
app.deathrate_Chi_Asymptomatic_Treated=app.AsymptomaticTreatedEditField.Value ;
app.deathrate_Chi_Asymptomatic_Untreated =app.AsymptomaticUntreatedEditField.Value;

app.SymptomaticTreatedEditField_2.Value =A{1,'DeathRateAdult'};
app.SymptomaticUntreatedEditField_2.Value =A{2,'DeathRateAdult'};
app.AsymptomaticTreatedEditField_2.Value =A{3,'DeathRateAdult'};
app.AsymptomaticUntreatedEditField_2.Value =A{4,'DeathRateAdult'};
app.deathrate_Adu_Symptomatic_Treated =app.SymptomaticTreatedEditField_2.Value;
app.deathrate_Adu_Symptomatic_Untreated=app.SymptomaticUntreatedEditField_2.Value ;
app.deathrate_Adu_Asymptomatic_Treated =app.AsymptomaticTreatedEditField_2.Value;
app.deathrate_Adu_Asymptomatic_Untreated=app.AsymptomaticUntreatedEditField_2.Value;


app.SymptomaticTreatedEditField_3.Value =A{1,'DeathRateSeniorCitizen'};
app.SymptomaticUntreatedEditField_3.Value =A{2,'DeathRateSeniorCitizen'};
app.AsymptomaticTreatedEditField_3.Value =A{3,'DeathRateSeniorCitizen'};
app.AsymptomaticUntreatedEditField_3.Value =A{4,'DeathRateSeniorCitizen'};

app.deathrate_Sen_Symptomatic_Treated =app.SymptomaticTreatedEditField_3.Value ;
app.deathrate_Sen_Symptomatic_Untreated =app.SymptomaticUntreatedEditField_3.Value;
app.deathrate_Sen_Asymptomatic_Treated=app.AsymptomaticTreatedEditField_3.Value;
app.deathrate_Sen_Asymptomatic_Untreated =app.AsymptomaticUntreatedEditField_3.Value;

A= readtable(app.ParameterFileName ,'Sheet','Infection');%,'DataRange','A2')
app.ParameterTable03 =A;
app.FractioninfectedshowingsymptomsEditField.Value = A{1,'Values'};
app.HowlongwillittaketoshowsymptomsdaysEditField.Value= A{2,'Values'};
app.HowlongwillittaketorecoverdaysEditField.Value= A{3,'Values'};
app.HowlongwillimmunitylastdaysEditField.Value= A{4,'Values'};

clear A;

A= readtable(app.ParameterFileName ,'Sheet','InitConditions');%,'DataRange','A2')
app.ParameterTable04 =A;

app.exposedEditField_2.Value = A{1,'Values'};
app.infectedshowingsymptomquarantinedEditField_2.Value= A{2,'Values'};
app.infectedshowingsymptomnotquarantinedEditField_2.Value = A{3,'Values'};
app.infectednosymptomquarantinedEditField_2.Value = A{4,'Values'};
app.infectednosymptomnotquarantinedEditField_2.Value = A{5,'Values'};
app.showingsymptomrecoveredEditField_2.Value = A{6,'Values'};
app.showingnosymptomrecoveredEditField_2.Value = A{7,'Values'};
app.showingsymptomdeadEditField_2.Value = A{8,'Values'};
app.showingnosymptomdeadEditField_2.Value = A{9,'Values'};


app.Initial_exposed = app.exposedEditField_2.Value;
app.Initial_Symptomatic_Infected_Quarantined= app.infectedshowingsymptomquarantinedEditField_2.Value;
app.Initial_Symptomatic_Infected_NonQuarantined = app.infectedshowingsymptomnotquarantinedEditField_2.Value;
app.Initial_Asymptomatic_Infected_Quarantined= app.infectednosymptomquarantinedEditField_2.Value;
app.Initial_Asymptomatic_Infected_NonQuarantined= app.infectednosymptomnotquarantinedEditField_2.Value;
app.Initial_Symptomatic_Recovered= app.showingsymptomrecoveredEditField_2.Value;
app.Initial_Asymptomatic_Recovered= app.showingnosymptomrecoveredEditField_2.Value;
app.Initial_Symptomatic_Dead= app.showingsymptomdeadEditField_2.Value;
app.Initial_Asymptomatic_Dead= app.showingnosymptomdeadEditField_2.Value;


clear A;

end% if readtable boolean

% results = 0;  
end % function LoadParamSet

end % methods

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Start up;
           
           
            app.ChildrenFraction = 1-app.AdultFraction-app.SenCitizenFraction;
            % populate regional information
            app.millionsEditField.Value = app.N_millions;
            app.Above60EditField.Value = app.SenCitizenFraction;
            app.Between10and60EditField.Value = app.AdultFraction;
            app.Below10EditField.Value = app.ChildrenFraction;
            app.Above60EditField.Editable=false;
            app.Between10and60EditField.Editable=false;
            
            % populate infection related information
            
            app.FractioninfectedshowingsymptomsEditField.Value =app.SymptomaticFraction;
            app.HowlongwillittaketoshowsymptomsdaysEditField.Value = app.Tau_E;
            app.HowlongwillittaketorecoverdaysEditField.Value = app.Tau_I;
            app.HowlongwillimmunitylastdaysEditField.Value = app.Tau_R;


            app.SymptomaticTreatedEditField.Value = app.deathrate_Chi_Symptomatic_Treated;
            app.SymptomaticUntreatedEditField.Value = app.deathrate_Chi_Symptomatic_Untreated;
            app.AsymptomaticTreatedEditField.Value = app.deathrate_Chi_Asymptomatic_Treated;
            app.AsymptomaticUntreatedEditField.Value = app.deathrate_Chi_Asymptomatic_Untreated;
            
            
            app.SymptomaticTreatedEditField_2.Value = app.deathrate_Adu_Symptomatic_Treated;
            app.SymptomaticUntreatedEditField_2.Value = app.deathrate_Adu_Symptomatic_Untreated;
            app.AsymptomaticTreatedEditField_2.Value = app.deathrate_Adu_Asymptomatic_Treated;
            app.AsymptomaticUntreatedEditField_2.Value = app.deathrate_Adu_Asymptomatic_Untreated;
            
            app.SymptomaticTreatedEditField_3.Value = app.deathrate_Sen_Symptomatic_Treated;
            app.SymptomaticUntreatedEditField_3.Value = app.deathrate_Sen_Symptomatic_Untreated;
            app.AsymptomaticTreatedEditField_3.Value = app.deathrate_Sen_Asymptomatic_Treated;
            app.AsymptomaticUntreatedEditField_3.Value = app.deathrate_Sen_Asymptomatic_Untreated;

            
            % populate data information
            app.ModelResultsOnlyButton.set;
            
            app.LoadDataFromxlsxEditField.Value = app.DataFileName;
            set(app.LoadDataFromxlsxEditField,'Enable', 'On');
            
            
            % populate starting conditions
            
            % populate spreading information
            
            % populate the remaining items
            
            app.DeterministicDefaultButton.set;
            app.VariationEditField.Enable= 'Off';
           
%             app.SaveresultsinxlsxEditField.Value =  app.ResultsFileName;
            
       
               app.R0SymptomaticEditField_01.Value = 2.2;
               app.R0AsymptomaticEditField_01.Limits = [0,1000];
       
               app.R0SymptomaticEditField_02.Value = 2.2;
               app.R0AsymptomaticEditField_02.Limits = [0,1000];
       
               app.R0SymptomaticEditField_03.Value = 2.2;
               app.R0AsymptomaticEditField_03.Limits = [0,1000];
       
               app.R0SymptomaticEditField_04.Value = 2.2;
               app.R0AsymptomaticEditField_04.Limits = [0,1000];
               
               app.R0SymptomaticEditField_05.Value = 2.2;
               app.R0AsymptomaticEditField_05.Limits = [0,1000];
               
               app.R0SymptomaticEditField_06.Value = 2.2;
               app.R0AsymptomaticEditField_06.Limits = [0,1000];

               app.R0SymptomaticEditField_07.Value = 2.2;
               app.R0AsymptomaticEditField_07.Limits = [0,1000];

               app.R0SymptomaticEditField_08.Value = 2.2;
               app.R0AsymptomaticEditField_08.Limits = [0,1000];
               
               app.R0SymptomaticEditField_09.Value = 2.2;
               app.R0AsymptomaticEditField_09.Limits = [0,1000];

               app.R0SymptomaticEditField_10.Value = 2.2;
               app.R0AsymptomaticEditField_10.Limits = [0,1000];

               app.R0SymptomaticEditField_11.Value = 2.2;
               app.R0AsymptomaticEditField_11.Limits = [0,1000];
               
               app.R0SymptomaticEditField_12.Value = 2.2;
               app.R0AsymptomaticEditField_12.Limits = [0,1000];
               app.R0SymptomaticEditField_13.Value = 2.2;
               app.R0AsymptomaticEditField_13.Limits = [0,1000];
               app.R0SymptomaticEditField_14.Value = 2.2;
               app.R0AsymptomaticEditField_14.Limits = [0,1000];
               app.R0SymptomaticEditField_15.Value = 2.2;
               app.R0AsymptomaticEditField_15.Limits = [0,1000];
               app.R0SymptomaticEditField_16.Value = 2.2;
               app.R0AsymptomaticEditField_16.Limits = [0,1000];
               app.R0SymptomaticEditField_17.Value = 2.2;
               app.R0AsymptomaticEditField_17.Limits = [0,1000];
               app.R0SymptomaticEditField_18.Value = 2.2;
               app.R0AsymptomaticEditField_18.Limits = [0,1000];
               app.R0SymptomaticEditField_19.Value = 2.2;
               app.R0AsymptomaticEditField_19.Limits = [0,1000];
               app.R0SymptomaticEditField_20.Value = 2.2;
               app.R0AsymptomaticEditField_20.Limits = [0,1000];
               app.R0SymptomaticEditField_21.Value = 2.2;
               app.R0AsymptomaticEditField_21.Limits = [0,1000];
               app.R0SymptomaticEditField_22.Value = 2.2;
               app.R0AsymptomaticEditField_22.Limits = [0,1000];
               
               app.RegionDropDown.ItemsData = [1 2];
               %


%% next load default paramter values


                if isdeployed % Stand-alone mode.
                [status, result] = system('path');
                currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                else % MATLAB mode.
                currentDir = pwd;
                end
                
                
                % Next get the file name
%                 app.ParameterFileName = [currentDir '\DefaultParamSet.xlsx'];
                app.ParameterFileName = [currentDir '\INParamSet.xlsx'];
                

readtableBoolean = 1;
try
clear A;    
A= readtable(app.ParameterFileName,'Sheet','Monthly') ;%,'DataRange','A2' )
app.ParameterTable01 =A;
catch ME
readtableBoolean =0;
    uiwait(warndlg(ME.message));
   
end

if (readtableBoolean ==1)
% R0 symptomatic array
R0S = A{:,'R0Symptomatic'};
app.R0_Array_Symptomatic = R0S;


app.R0SymptomaticEditField_01.Value =R0S(1); 
app.R0SymptomaticEditField_02.Value =R0S(2); 
app.R0SymptomaticEditField_03.Value =R0S(3); 
app.R0SymptomaticEditField_04.Value =R0S(4); 
app.R0SymptomaticEditField_05.Value =R0S(5); 
app.R0SymptomaticEditField_06.Value =R0S(6); 
app.R0SymptomaticEditField_07.Value =R0S(7); 
app.R0SymptomaticEditField_08.Value =R0S(8); 
app.R0SymptomaticEditField_09.Value =R0S(9); 
app.R0SymptomaticEditField_10.Value =R0S(10); 
app.R0SymptomaticEditField_11.Value =R0S(11); 
app.R0SymptomaticEditField_12.Value =R0S(12); 
app.R0SymptomaticEditField_13.Value =R0S(13); 
app.R0SymptomaticEditField_14.Value =R0S(14); 
app.R0SymptomaticEditField_15.Value =R0S(15); 
app.R0SymptomaticEditField_16.Value =R0S(16); 
app.R0SymptomaticEditField_17.Value =R0S(17); 
app.R0SymptomaticEditField_18.Value =R0S(18); 
app.R0SymptomaticEditField_19.Value =R0S(19); 
app.R0SymptomaticEditField_20.Value =R0S(20); 
app.R0SymptomaticEditField_21.Value =R0S(21); 
app.R0SymptomaticEditField_22.Value =R0S(22); 

%R0 Asymptomatic array
R0A = A{:,'R0Asymptomatic'};

app.R0_Array_Asymptomatic = R0A;


app.R0AsymptomaticEditField_01.Value =R0A(1); 
app.R0AsymptomaticEditField_02.Value =R0A(2); 
app.R0AsymptomaticEditField_03.Value =R0A(3); 
app.R0AsymptomaticEditField_04.Value =R0A(4); 
app.R0AsymptomaticEditField_05.Value =R0A(5); 
app.R0AsymptomaticEditField_06.Value =R0A(6); 
app.R0AsymptomaticEditField_07.Value =R0A(7); 
app.R0AsymptomaticEditField_08.Value =R0A(8); 
app.R0AsymptomaticEditField_09.Value =R0A(9); 
app.R0AsymptomaticEditField_10.Value =R0A(10); 
app.R0AsymptomaticEditField_11.Value =R0A(11); 
app.R0AsymptomaticEditField_12.Value =R0A(12); 
app.R0AsymptomaticEditField_13.Value =R0A(13); 
app.R0AsymptomaticEditField_14.Value =R0A(14); 
app.R0AsymptomaticEditField_15.Value =R0A(15); 
app.R0AsymptomaticEditField_16.Value =R0A(16); 
app.R0AsymptomaticEditField_17.Value =R0A(17); 
app.R0AsymptomaticEditField_18.Value =R0A(18); 
app.R0AsymptomaticEditField_19.Value =R0A(19); 
app.R0AsymptomaticEditField_20.Value =R0A(20); 
app.R0AsymptomaticEditField_21.Value =R0A(21); 
app.R0AsymptomaticEditField_22.Value =R0A(22); 

%Symptomatic fraction treated
SFT = A{:,'SymptomaticFractionTreated'};
app.FractionTreated_Array_Symptomatic = SFT;


app.FractionofsymptomaticpeopletreatedEditField_01.Value =SFT(1); 
% app.FractionofsymptomaticpeopletreatedEditField_02
app.FractionofsymptomaticpeopletreatedEditField_02.Value =SFT(2); 
app.FractionofsymptomaticpeopletreatedEditField_03.Value =SFT(3); 
app.FractionofsymptomaticpeopletreatedEditField_04.Value =SFT(4); 
app.FractionofsymptomaticpeopletreatedEditField_05.Value =SFT(5); 
app.FractionofsymptomaticpeopletreatedEditField_06.Value =SFT(6); 
app.FractionofsymptomaticpeopletreatedEditField_07.Value =SFT(7); 
app.FractionofsymptomaticpeopletreatedEditField_08.Value =SFT(8); 
app.FractionofsymptomaticpeopletreatedEditField_09.Value =SFT(9); 
app.FractionofsymptomaticpeopletreatedEditField_10.Value =SFT(10); 
app.FractionofsymptomaticpeopletreatedEditField_11.Value =SFT(11); 
app.FractionofsymptomaticpeopletreatedEditField_12.Value =SFT(12); 
app.FractionofsymptomaticpeopletreatedEditField_13.Value =SFT(13); 
app.FractionofsymptomaticpeopletreatedEditField_14.Value =SFT(14); 
app.FractionofsymptomaticpeopletreatedEditField_15.Value =SFT(15); 
app.FractionofsymptomaticpeopletreatedEditField_16.Value =SFT(16); 
app.FractionofsymptomaticpeopletreatedEditField_17.Value =SFT(17); 
app.FractionofsymptomaticpeopletreatedEditField_18.Value =SFT(18); 
app.FractionofsymptomaticpeopletreatedEditField_19.Value =SFT(19); 
app.FractionofsymptomaticpeopletreatedEditField_20.Value =SFT(20); 
app.FractionofsymptomaticpeopletreatedEditField_21.Value =SFT(21); 
app.FractionofsymptomaticpeopletreatedEditField_22.Value =SFT(22); 

%Asymptomatic fraction treated
AFT = A{:,'AsymptomaticFractionTreated'};

app.FractionTreated_Array_Asymptomatic = AFT;

app.FractionofAsymptomaticpeopletreatedEditField_01.Value =AFT(1); 
app.FractionofAsymptomaticpeopletreatedEditField_02.Value =AFT(2); 
app.FractionofAsymptomaticpeopletreatedEditField_03.Value =AFT(3); 
app.FractionofAsymptomaticpeopletreatedEditField_04.Value =AFT(4); 
app.FractionofAsymptomaticpeopletreatedEditField_05.Value =AFT(5); 
app.FractionofAsymptomaticpeopletreatedEditField_06.Value =AFT(6); 
app.FractionofAsymptomaticpeopletreatedEditField_07.Value =AFT(7); 
app.FractionofAsymptomaticpeopletreatedEditField_08.Value =AFT(8); 
app.FractionofAsymptomaticpeopletreatedEditField_09.Value =AFT(9); 
app.FractionofAsymptomaticpeopletreatedEditField_10.Value =AFT(10); 
app.FractionofAsymptomaticpeopletreatedEditField_11.Value =AFT(11); 
app.FractionofAsymptomaticpeopletreatedEditField_12.Value =AFT(12); 
app.FractionofAsymptomaticpeopletreatedEditField_13.Value =AFT(13); 
app.FractionofAsymptomaticpeopletreatedEditField_14.Value =AFT(14); 
app.FractionofAsymptomaticpeopletreatedEditField_15.Value =AFT(15); 
app.FractionofAsymptomaticpeopletreatedEditField_16.Value =AFT(16); 
app.FractionofAsymptomaticpeopletreatedEditField_17.Value =AFT(17); 
app.FractionofAsymptomaticpeopletreatedEditField_18.Value =AFT(18); 
app.FractionofAsymptomaticpeopletreatedEditField_19.Value =AFT(19); 
app.FractionofAsymptomaticpeopletreatedEditField_20.Value =AFT(20); 
app.FractionofAsymptomaticpeopletreatedEditField_21.Value =AFT(21); 
app.FractionofAsymptomaticpeopletreatedEditField_22.Value =AFT(22); 


R0FracAduVac= A{:,'R0FracAduVac'};
app.Array_Fraction_Adu_Vaccinated= R0FracAduVac;


% app.FractionAdultsEditField_01.Value =R0FracAduVac(1); 
% app.FractionAdultsEditField_02.Value =R0FracAduVac(2); 
% app.FractionAdultsEditField_03.Value =R0FracAduVac(3); 
% app.FractionAdultsEditField_04.Value =R0FracAduVac(4); 
% app.FractionAdultsEditField_05.Value =R0FracAduVac(5); 
% app.FractionAdultsEditField_06.Value =R0FracAduVac(6); 
% app.FractionAdultsEditField_07.Value =R0FracAduVac(7); 
% app.FractionAdultsEditField_08.Value =R0FracAduVac(8); 
% app.FractionAdultsEditField_09.Value =R0FracAduVac(9); 
% app.FractionAdultsEditField_10.Value =R0FracAduVac(10); 
app.FractionAdultsEditField_11.Value =R0FracAduVac(11); 
app.FractionAdultsEditField_12.Value =R0FracAduVac(12); 
app.FractionAdultsEditField_13.Value =R0FracAduVac(13); 
app.FractionAdultsEditField_14.Value =R0FracAduVac(14); 
app.FractionAdultsEditField_15.Value =R0FracAduVac(15); 
app.FractionAdultsEditField_16.Value =R0FracAduVac(16); 
app.FractionAdultsEditField_17.Value =R0FracAduVac(17); 
app.FractionAdultsEditField_18.Value =R0FracAduVac(18); 
app.FractionAdultsEditField_19.Value =R0FracAduVac(19); 
app.FractionAdultsEditField_20.Value =R0FracAduVac(20); 
app.FractionAdultsEditField_21.Value =R0FracAduVac(21); 
app.FractionAdultsEditField_22.Value =R0FracAduVac(22); 


R0FracSenVac= A{:,'R0FracSenVac'};
app.Array_Fraction_Sen_Vaccinated = R0FracSenVac;


% app.FractionSeniorCitizensEditField_01.Value =R0FracSenVac(1); 
% app.FractionSeniorCitizensEditField_02.Value =R0FracSenVac(2); 
% app.FractionSeniorCitizensEditField_03.Value =R0FracSenVac(3); 
% app.FractionSeniorCitizensEditField_04.Value =R0FracSenVac(4); 
% app.FractionSeniorCitizensEditField_05.Value =R0FracSenVac(5); 
% app.FractionSeniorCitizensEditField_06.Value =R0FracSenVac(6); 
% app.FractionSeniorCitizensEditField_07.Value =R0FracSenVac(7); 
% app.FractionSeniorCitizensEditField_08.Value =R0FracSenVac(8); 
% app.FractionSeniorCitizensEditField_09.Value =R0FracSenVac(9); 
% app.FractionSeniorCitizensEditField_10.Value =R0FracSenVac(10); 
app.FractionSeniorCitizensEditField_11.Value =R0FracSenVac(11); 
app.FractionSeniorCitizensEditField_12.Value =R0FracSenVac(12); 
app.FractionSeniorCitizensEditField_13.Value =R0FracSenVac(13); 
app.FractionSeniorCitizensEditField_14.Value =R0FracSenVac(14); 
app.FractionSeniorCitizensEditField_15.Value =R0FracSenVac(15); 
app.FractionSeniorCitizensEditField_16.Value =R0FracSenVac(16); 
app.FractionSeniorCitizensEditField_17.Value =R0FracSenVac(17); 
app.FractionSeniorCitizensEditField_18.Value =R0FracSenVac(18); 
app.FractionSeniorCitizensEditField_19.Value =R0FracSenVac(19); 
app.FractionSeniorCitizensEditField_20.Value =R0FracSenVac(20); 
app.FractionSeniorCitizensEditField_21.Value =R0FracSenVac(21); 
app.FractionSeniorCitizensEditField_22.Value =R0FracSenVac(22); 

% Next we need to assign values to the parameters in each month.

% app.R0SymptomaticEditField_01.Value =

clear A;
A= readtable(app.ParameterFileName,'Sheet','DeathRate');%,'DataRange','A2' )
app.ParameterTable02 =A;

app.SymptomaticTreatedEditField.Value =A{1,'DeathRateChild'};
app.SymptomaticUntreatedEditField.Value =A{2,'DeathRateChild'};
app.AsymptomaticTreatedEditField.Value =A{3,'DeathRateChild'};
app.AsymptomaticUntreatedEditField.Value =A{4,'DeathRateChild'};

app.deathrate_Chi_Symptomatic_Treated=app.SymptomaticTreatedEditField.Value ;
app.deathrate_Chi_Symptomatic_Untreated=app.SymptomaticUntreatedEditField.Value ;
app.deathrate_Chi_Asymptomatic_Treated=app.AsymptomaticTreatedEditField.Value ;
app.deathrate_Chi_Asymptomatic_Untreated =app.AsymptomaticUntreatedEditField.Value;

app.SymptomaticTreatedEditField_2.Value =A{1,'DeathRateAdult'};
app.SymptomaticUntreatedEditField_2.Value =A{2,'DeathRateAdult'};
app.AsymptomaticTreatedEditField_2.Value =A{3,'DeathRateAdult'};
app.AsymptomaticUntreatedEditField_2.Value =A{4,'DeathRateAdult'};
app.deathrate_Adu_Symptomatic_Treated =app.SymptomaticTreatedEditField_2.Value;
app.deathrate_Adu_Symptomatic_Untreated=app.SymptomaticUntreatedEditField_2.Value ;
app.deathrate_Adu_Asymptomatic_Treated =app.AsymptomaticTreatedEditField_2.Value;
app.deathrate_Adu_Asymptomatic_Untreated=app.AsymptomaticUntreatedEditField_2.Value;


app.SymptomaticTreatedEditField_3.Value =A{1,'DeathRateSeniorCitizen'};
app.SymptomaticUntreatedEditField_3.Value =A{2,'DeathRateSeniorCitizen'};
app.AsymptomaticTreatedEditField_3.Value =A{3,'DeathRateSeniorCitizen'};
app.AsymptomaticUntreatedEditField_3.Value =A{4,'DeathRateSeniorCitizen'};

app.deathrate_Sen_Symptomatic_Treated =app.SymptomaticTreatedEditField_3.Value ;
app.deathrate_Sen_Symptomatic_Untreated =app.SymptomaticUntreatedEditField_3.Value;
app.deathrate_Sen_Asymptomatic_Treated=app.AsymptomaticTreatedEditField_3.Value;
app.deathrate_Sen_Asymptomatic_Untreated =app.AsymptomaticUntreatedEditField_3.Value;

A= readtable(app.ParameterFileName ,'Sheet','Infection');%,'DataRange','A2')
app.ParameterTable03 =A;
app.FractioninfectedshowingsymptomsEditField.Value = A{1,'Values'};
app.HowlongwillittaketoshowsymptomsdaysEditField.Value= A{2,'Values'};
app.HowlongwillittaketorecoverdaysEditField.Value= A{3,'Values'};
app.HowlongwillimmunitylastdaysEditField.Value= A{4,'Values'};

clear A;

A= readtable(app.ParameterFileName ,'Sheet','InitConditions');%,'DataRange','A2')
app.ParameterTable04 =A;

app.exposedEditField_2.Value = A{1,'Values'};
app.infectedshowingsymptomquarantinedEditField_2.Value= A{2,'Values'};
app.infectedshowingsymptomnotquarantinedEditField_2.Value = A{3,'Values'};
app.infectednosymptomquarantinedEditField_2.Value = A{4,'Values'};
app.infectednosymptomnotquarantinedEditField_2.Value = A{5,'Values'};
app.showingsymptomrecoveredEditField_2.Value = A{6,'Values'};
app.showingnosymptomrecoveredEditField_2.Value = A{7,'Values'};
app.showingsymptomdeadEditField_2.Value = A{8,'Values'};
app.showingnosymptomdeadEditField_2.Value = A{9,'Values'};


app.Initial_exposed = app.exposedEditField_2.Value;
app.Initial_Symptomatic_Infected_Quarantined= app.infectedshowingsymptomquarantinedEditField_2.Value;
app.Initial_Symptomatic_Infected_NonQuarantined = app.infectedshowingsymptomnotquarantinedEditField_2.Value;
app.Initial_Asymptomatic_Infected_Quarantined= app.infectednosymptomquarantinedEditField_2.Value;
app.Initial_Asymptomatic_Infected_NonQuarantined= app.infectednosymptomnotquarantinedEditField_2.Value;
app.Initial_Symptomatic_Recovered= app.showingsymptomrecoveredEditField_2.Value;
app.Initial_Asymptomatic_Recovered= app.showingnosymptomrecoveredEditField_2.Value;
app.Initial_Symptomatic_Dead= app.showingsymptomdeadEditField_2.Value;
app.Initial_Asymptomatic_Dead= app.showingnosymptomdeadEditField_2.Value;


clear A;
end ; % if readtableboolean == 1

% Now, load default region file; First identify the
               % directory
                if isdeployed % Stand-alone mode.
                [status, result] = system('path');
                currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                else % MATLAB mode.
                currentDir = pwd;
                end
                
                
                % Next get the file name
                app.RegionInformationFileName = [currentDir '\DefaultRegionInfo.xlsx']

                readtableBoolean = 1;
    try
    app.RegionInformation = readtable(app.RegionInformationFileName );
    catch ME
    readtableBoolean =0;
        uiwait(warndlg(ME.message));
    end

if (readtableBoolean ==1)
            
app.RegionArray = string( app.RegionInformation{:,'Region'}) ;

app.RegionArrayLength = length(app.RegionArray);

app.RegionDataFileNames =  string( app.RegionInformation{:,'ShortName'})
app.RegionParamFileNames =  string( app.RegionInformation{:,'ShortName'})
% size(app.RegionDataFileNames)
% size(app.RegionParamFileNames)
for i1 = 1:app.RegionArrayLength
    app.RegionDataFileNames(i1) = append(app.RegionDataFileNames(i1), '.xlsx');
    app.RegionParamFileNames(i1) = append(app.RegionParamFileNames(i1), 'ParamSet.xlsx');
end

% disp(['Region Array Length = ' num2str(app.RegionArrayLength) ])


app.RegionArray(app.RegionArrayLength+1) = {'Custom'};
app.PopulationArray =  app.RegionInformation{:,'PopulationMillion'};
% app.ChildrenFractionArray = app.RegionInformation{:,'ChildrenFraction'};
app.AdultFractionArray = app.RegionInformation{:,'AdultFraction'};
app.SenCitizenFractionArray = app.RegionInformation{:,'SenCitizenFraction'};

%% loaded information. Next update the dropdown menu

app.RegionDropDown.Items = app.RegionArray;
app.RegionDropDown.ItemsData = 1:1:length(app.RegionArray);

app.RegionDropDownValueChanged();
app.RegionDropDown.Value = 1;
app.RegionDropDownValueChanged();

app.Above60EditField.Editable = false;
app.Between10and60EditField.Editable= false;


%% 
end % if read table is OK, then we implement the above changes, if not, we skip it
               
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            
[file,path] = uigetfile('*.xlsx');
if isequal(file,0)
%    disp('User selected Cancel');
else
 app.ParameterFileName = fullfile(path,file);

% disp(app.ParameterFileName)

readtableBoolean = 1;
try
A= readtable(app.ParameterFileName,'Sheet','Monthly') ;%,'DataRange','A2' )
app.ParameterTable01 =A;
catch ME
readtableBoolean =0;
    uiwait(warndlg(ME.message));
end

if (readtableBoolean ==1)
% R0 symptomatic array
R0S = A{:,'R0Symptomatic'};
app.R0_Array_Symptomatic = R0S;


app.R0SymptomaticEditField_01.Value =R0S(1); 
app.R0SymptomaticEditField_02.Value =R0S(2); 
app.R0SymptomaticEditField_03.Value =R0S(3); 
app.R0SymptomaticEditField_04.Value =R0S(4); 
app.R0SymptomaticEditField_05.Value =R0S(5); 
app.R0SymptomaticEditField_06.Value =R0S(6); 
app.R0SymptomaticEditField_07.Value =R0S(7); 
app.R0SymptomaticEditField_08.Value =R0S(8); 
app.R0SymptomaticEditField_09.Value =R0S(9); 
app.R0SymptomaticEditField_10.Value =R0S(10); 
app.R0SymptomaticEditField_11.Value =R0S(11); 
app.R0SymptomaticEditField_12.Value =R0S(12); 
app.R0SymptomaticEditField_13.Value =R0S(13); 
app.R0SymptomaticEditField_14.Value =R0S(14); 
app.R0SymptomaticEditField_15.Value =R0S(15); 
app.R0SymptomaticEditField_16.Value =R0S(16); 
app.R0SymptomaticEditField_17.Value =R0S(17); 
app.R0SymptomaticEditField_18.Value =R0S(18); 
app.R0SymptomaticEditField_19.Value =R0S(19); 
app.R0SymptomaticEditField_20.Value =R0S(20); 
app.R0SymptomaticEditField_21.Value =R0S(21); 
app.R0SymptomaticEditField_22.Value =R0S(22); 

%R0 Asymptomatic array
R0A = A{:,'R0Asymptomatic'};

app.R0_Array_Asymptomatic = R0A;


app.R0AsymptomaticEditField_01.Value =R0A(1); 
app.R0AsymptomaticEditField_02.Value =R0A(2); 
app.R0AsymptomaticEditField_03.Value =R0A(3); 
app.R0AsymptomaticEditField_04.Value =R0A(4); 
app.R0AsymptomaticEditField_05.Value =R0A(5); 
app.R0AsymptomaticEditField_06.Value =R0A(6); 
app.R0AsymptomaticEditField_07.Value =R0A(7); 
app.R0AsymptomaticEditField_08.Value =R0A(8); 
app.R0AsymptomaticEditField_09.Value =R0A(9); 
app.R0AsymptomaticEditField_10.Value =R0A(10); 
app.R0AsymptomaticEditField_11.Value =R0A(11); 
app.R0AsymptomaticEditField_12.Value =R0A(12); 
app.R0AsymptomaticEditField_13.Value =R0A(13); 
app.R0AsymptomaticEditField_14.Value =R0A(14); 
app.R0AsymptomaticEditField_15.Value =R0A(15); 
app.R0AsymptomaticEditField_16.Value =R0A(16); 
app.R0AsymptomaticEditField_17.Value =R0A(17); 
app.R0AsymptomaticEditField_18.Value =R0A(18); 
app.R0AsymptomaticEditField_19.Value =R0A(19); 
app.R0AsymptomaticEditField_20.Value =R0A(20); 
app.R0AsymptomaticEditField_21.Value =R0A(21); 
app.R0AsymptomaticEditField_22.Value =R0A(22); 

%Symptomatic fraction treated
SFT = A{:,'SymptomaticFractionTreated'};
app.FractionTreated_Array_Symptomatic = SFT;


app.FractionofsymptomaticpeopletreatedEditField_01.Value =SFT(1); 
% app.FractionofsymptomaticpeopletreatedEditField_02
app.FractionofsymptomaticpeopletreatedEditField_02.Value =SFT(2); 
app.FractionofsymptomaticpeopletreatedEditField_03.Value =SFT(3); 
app.FractionofsymptomaticpeopletreatedEditField_04.Value =SFT(4); 
app.FractionofsymptomaticpeopletreatedEditField_05.Value =SFT(5); 
app.FractionofsymptomaticpeopletreatedEditField_06.Value =SFT(6); 
app.FractionofsymptomaticpeopletreatedEditField_07.Value =SFT(7); 
app.FractionofsymptomaticpeopletreatedEditField_08.Value =SFT(8); 
app.FractionofsymptomaticpeopletreatedEditField_09.Value =SFT(9); 
app.FractionofsymptomaticpeopletreatedEditField_10.Value =SFT(10); 
app.FractionofsymptomaticpeopletreatedEditField_11.Value =SFT(11); 
app.FractionofsymptomaticpeopletreatedEditField_12.Value =SFT(12); 
app.FractionofsymptomaticpeopletreatedEditField_13.Value =SFT(13); 
app.FractionofsymptomaticpeopletreatedEditField_14.Value =SFT(14); 
app.FractionofsymptomaticpeopletreatedEditField_15.Value =SFT(15); 
app.FractionofsymptomaticpeopletreatedEditField_16.Value =SFT(16); 
app.FractionofsymptomaticpeopletreatedEditField_17.Value =SFT(17); 
app.FractionofsymptomaticpeopletreatedEditField_18.Value =SFT(18); 
app.FractionofsymptomaticpeopletreatedEditField_19.Value =SFT(19); 
app.FractionofsymptomaticpeopletreatedEditField_20.Value =SFT(20); 
app.FractionofsymptomaticpeopletreatedEditField_21.Value =SFT(21); 
app.FractionofsymptomaticpeopletreatedEditField_22.Value =SFT(22); 

%Asymptomatic fraction treated
AFT = A{:,'AsymptomaticFractionTreated'};

app.FractionTreated_Array_Asymptomatic = AFT;

app.FractionofAsymptomaticpeopletreatedEditField_01.Value =AFT(1); 
app.FractionofAsymptomaticpeopletreatedEditField_02.Value =AFT(2); 
app.FractionofAsymptomaticpeopletreatedEditField_03.Value =AFT(3); 
app.FractionofAsymptomaticpeopletreatedEditField_04.Value =AFT(4); 
app.FractionofAsymptomaticpeopletreatedEditField_05.Value =AFT(5); 
app.FractionofAsymptomaticpeopletreatedEditField_06.Value =AFT(6); 
app.FractionofAsymptomaticpeopletreatedEditField_07.Value =AFT(7); 
app.FractionofAsymptomaticpeopletreatedEditField_08.Value =AFT(8); 
app.FractionofAsymptomaticpeopletreatedEditField_09.Value =AFT(9); 
app.FractionofAsymptomaticpeopletreatedEditField_10.Value =AFT(10); 
app.FractionofAsymptomaticpeopletreatedEditField_11.Value =AFT(11); 
app.FractionofAsymptomaticpeopletreatedEditField_12.Value =AFT(12); 
app.FractionofAsymptomaticpeopletreatedEditField_13.Value =AFT(13); 
app.FractionofAsymptomaticpeopletreatedEditField_14.Value =AFT(14); 
app.FractionofAsymptomaticpeopletreatedEditField_15.Value =AFT(15); 
app.FractionofAsymptomaticpeopletreatedEditField_16.Value =AFT(16); 
app.FractionofAsymptomaticpeopletreatedEditField_17.Value =AFT(17); 
app.FractionofAsymptomaticpeopletreatedEditField_18.Value =AFT(18); 
app.FractionofAsymptomaticpeopletreatedEditField_19.Value =AFT(19); 
app.FractionofAsymptomaticpeopletreatedEditField_20.Value =AFT(20); 
app.FractionofAsymptomaticpeopletreatedEditField_21.Value =AFT(21); 
app.FractionofAsymptomaticpeopletreatedEditField_22.Value =AFT(22); 

% Fraction Adults Vaccinated
R0FracAduVac = A{:,'R0FracAduVac'};
app.Array_Fraction_Adu_Vaccinated= R0FracAduVac;


app.FractionAdultsEditField_01.Value =R0FracAduVac(1); 
app.FractionAdultsEditField_02.Value =R0FracAduVac(2); 
app.FractionAdultsEditField_03.Value =R0FracAduVac(3); 
app.FractionAdultsEditField_04.Value =R0FracAduVac(4); 
app.FractionAdultsEditField_05.Value =R0FracAduVac(5); 
app.FractionAdultsEditField_06.Value =R0FracAduVac(6); 
app.FractionAdultsEditField_07.Value =R0FracAduVac(7); 
app.FractionAdultsEditField_08.Value =R0FracAduVac(8); 
app.FractionAdultsEditField_09.Value =R0FracAduVac(9); 
app.FractionAdultsEditField_10.Value =R0FracAduVac(10); 
app.FractionAdultsEditField_11.Value =R0FracAduVac(11); 
app.FractionAdultsEditField_12.Value =R0FracAduVac(12); 
app.FractionAdultsEditField_13.Value =R0FracAduVac(13); 
app.FractionAdultsEditField_14.Value =R0FracAduVac(14); 
app.FractionAdultsEditField_15.Value =R0FracAduVac(15); 
app.FractionAdultsEditField_16.Value =R0FracAduVac(16); 
app.FractionAdultsEditField_17.Value =R0FracAduVac(17); 
app.FractionAdultsEditField_18.Value =R0FracAduVac(18); 
app.FractionAdultsEditField_19.Value =R0FracAduVac(19); 
app.FractionAdultsEditField_20.Value =R0FracAduVac(20); 
app.FractionAdultsEditField_21.Value =R0FracAduVac(21); 
app.FractionAdultsEditField_22.Value =R0FracAduVac(22); 


% Fraction Adults Vaccinated
R0FracSenVac = A{:,'R0FracSenVac'};
app.Array_Fraction_Sen_Vaccinated = R0FracSenVac;


app.FractionSeniorCitizensEditField_01.Value =R0FracSenVac(1); 
app.FractionSeniorCitizensEditField_02.Value =R0FracSenVac(2); 
app.FractionSeniorCitizensEditField_03.Value =R0FracSenVac(3); 
app.FractionSeniorCitizensEditField_04.Value =R0FracSenVac(4); 
app.FractionSeniorCitizensEditField_05.Value =R0FracSenVac(5); 
app.FractionSeniorCitizensEditField_06.Value =R0FracSenVac(6); 
app.FractionSeniorCitizensEditField_07.Value =R0FracSenVac(7); 
app.FractionSeniorCitizensEditField_08.Value =R0FracSenVac(8); 
app.FractionSeniorCitizensEditField_09.Value =R0FracSenVac(9); 
app.FractionSeniorCitizensEditField_10.Value =R0FracSenVac(10); 
app.FractionSeniorCitizensEditField_11.Value =R0FracSenVac(11); 
app.FractionSeniorCitizensEditField_12.Value =R0FracSenVac(12); 
app.FractionSeniorCitizensEditField_13.Value =R0FracSenVac(13); 
app.FractionSeniorCitizensEditField_14.Value =R0FracSenVac(14); 
app.FractionSeniorCitizensEditField_15.Value =R0FracSenVac(15); 
app.FractionSeniorCitizensEditField_16.Value =R0FracSenVac(16); 
app.FractionSeniorCitizensEditField_17.Value =R0FracSenVac(17); 
app.FractionSeniorCitizensEditField_18.Value =R0FracSenVac(18); 
app.FractionSeniorCitizensEditField_19.Value =R0FracSenVac(19); 
app.FractionSeniorCitizensEditField_20.Value =R0FracSenVac(20); 
app.FractionSeniorCitizensEditField_21.Value =R0FracSenVac(21); 
app.FractionSeniorCitizensEditField_22.Value =R0FracSenVac(22); 

% Next we need to assign values to the parameters in each month.

% app.R0SymptomaticEditField_01.Value =

clear A;
A= readtable(app.ParameterFileName,'Sheet','DeathRate');%,'DataRange','A2' )
app.ParameterTable02 =A;

app.SymptomaticTreatedEditField.Value =A{1,'DeathRateChild'};
app.SymptomaticUntreatedEditField.Value =A{2,'DeathRateChild'};
app.AsymptomaticTreatedEditField.Value =A{3,'DeathRateChild'};
app.AsymptomaticUntreatedEditField.Value =A{4,'DeathRateChild'};

app.deathrate_Chi_Symptomatic_Treated=app.SymptomaticTreatedEditField.Value ;
app.deathrate_Chi_Symptomatic_Untreated=app.SymptomaticUntreatedEditField.Value ;
app.deathrate_Chi_Asymptomatic_Treated=app.AsymptomaticTreatedEditField.Value ;
app.deathrate_Chi_Asymptomatic_Untreated =app.AsymptomaticUntreatedEditField.Value;

app.SymptomaticTreatedEditField_2.Value =A{1,'DeathRateAdult'};
app.SymptomaticUntreatedEditField_2.Value =A{2,'DeathRateAdult'};
app.AsymptomaticTreatedEditField_2.Value =A{3,'DeathRateAdult'};
app.AsymptomaticUntreatedEditField_2.Value =A{4,'DeathRateAdult'};
app.deathrate_Adu_Symptomatic_Treated =app.SymptomaticTreatedEditField_2.Value;
app.deathrate_Adu_Symptomatic_Untreated=app.SymptomaticUntreatedEditField_2.Value ;
app.deathrate_Adu_Asymptomatic_Treated =app.AsymptomaticTreatedEditField_2.Value;
app.deathrate_Adu_Asymptomatic_Untreated=app.AsymptomaticUntreatedEditField_2.Value;


app.SymptomaticTreatedEditField_3.Value =A{1,'DeathRateSeniorCitizen'};
app.SymptomaticUntreatedEditField_3.Value =A{2,'DeathRateSeniorCitizen'};
app.AsymptomaticTreatedEditField_3.Value =A{3,'DeathRateSeniorCitizen'};
app.AsymptomaticUntreatedEditField_3.Value =A{4,'DeathRateSeniorCitizen'};

app.deathrate_Sen_Symptomatic_Treated =app.SymptomaticTreatedEditField_3.Value ;
app.deathrate_Sen_Symptomatic_Untreated =app.SymptomaticUntreatedEditField_3.Value;
app.deathrate_Sen_Asymptomatic_Treated=app.AsymptomaticTreatedEditField_3.Value;
app.deathrate_Sen_Asymptomatic_Untreated =app.AsymptomaticUntreatedEditField_3.Value;

A= readtable(app.ParameterFileName ,'Sheet','Infection');%,'DataRange','A2')
app.ParameterTable03 =A;
app.FractioninfectedshowingsymptomsEditField.Value = A{1,'Values'};
app.HowlongwillittaketoshowsymptomsdaysEditField.Value= A{2,'Values'};
app.HowlongwillittaketorecoverdaysEditField.Value= A{3,'Values'};
app.HowlongwillimmunitylastdaysEditField.Value= A{4,'Values'};

clear A;

A= readtable(app.ParameterFileName ,'Sheet','InitConditions');%,'DataRange','A2')
app.ParameterTable04 =A;

app.exposedEditField_2.Value = A{1,'Values'};
app.VaccinatedEditField.Value = A{2,'Values'};
app.infectedshowingsymptomquarantinedEditField_2.Value= A{3,'Values'};
app.infectedshowingsymptomnotquarantinedEditField_2.Value = A{4,'Values'};
app.infectednosymptomquarantinedEditField_2.Value = A{5,'Values'};
app.infectednosymptomnotquarantinedEditField_2.Value = A{6,'Values'};
app.showingsymptomrecoveredEditField_2.Value = A{7,'Values'};
app.showingnosymptomrecoveredEditField_2.Value = A{8,'Values'};
app.showingsymptomdeadEditField_2.Value = A{9,'Values'};
app.showingnosymptomdeadEditField_2.Value = A{10,'Values'};


app.Initial_exposed = app.exposedEditField_2.Value;
app.Initial_Vaccinated = app.VaccinatedEditField.Value;

app.Initial_Symptomatic_Infected_Quarantined= app.infectedshowingsymptomquarantinedEditField_2.Value;
app.Initial_Symptomatic_Infected_NonQuarantined = app.infectedshowingsymptomnotquarantinedEditField_2.Value;
app.Initial_Asymptomatic_Infected_Quarantined= app.infectednosymptomquarantinedEditField_2.Value;
app.Initial_Asymptomatic_Infected_NonQuarantined= app.infectednosymptomnotquarantinedEditField_2.Value;
app.Initial_Symptomatic_Recovered= app.showingsymptomrecoveredEditField_2.Value;
app.Initial_Asymptomatic_Recovered= app.showingnosymptomrecoveredEditField_2.Value;
app.Initial_Symptomatic_Dead= app.showingsymptomdeadEditField_2.Value;
app.Initial_Asymptomatic_Dead= app.showingnosymptomdeadEditField_2.Value;


clear A;
% app.ParameterTable05 = readtable(app.ParameterFileName );

end% if readtable boolean

end


   figure(app.CTMUIFigure);

        end

        % Menu selected function: AboutCTMMenu
        function AboutCTMMenuSelected(app, event)
            AboutCovid19DialogBox();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
close all;
% app.RunButton.Text = 'Wait';
% app.RunButton.Enable = false;


p1(1) = app.N_millions * 1e6;
p1(2) = app.SenCitizenFraction;
p1(3) = app.AdultFraction;
p1(4) = app.ChildrenFraction;
p1(5) = app.SymptomaticFraction;


p1(6) = app.Tau_E ; % Exposed period
p1(7) = app.Tau_I ; % infectious period (before recovery)
p1(8) = app.Tau_R ; % immunity duration, time spent in recovery period
p1(9) = app.Tau_V ; % vaccine immunity duration, 


p2{1} = app.R0_Array_Symptomatic;
p2{2} = app.R0_Array_Asymptomatic;
p2{3} = app.FractionTreated_Array_Symptomatic;
p2{4} = app.FractionTreated_Array_Asymptomatic;
p2{5} = app.Array_Fraction_Chi_Vaccinated;
p2{6} = app.Array_Fraction_Adu_Vaccinated;
p2{7} = app.Array_Fraction_Sen_Vaccinated;


pChi(1)= app.deathrate_Chi_Symptomatic_Treated;
pChi(2)= app.deathrate_Chi_Symptomatic_Untreated;
pChi(3)= app.deathrate_Chi_Asymptomatic_Treated;
pChi(4)= app.deathrate_Chi_Asymptomatic_Untreated;

pAdu(1)= app.deathrate_Adu_Symptomatic_Treated;
pAdu(2)= app.deathrate_Adu_Symptomatic_Untreated;
pAdu(3)= app.deathrate_Adu_Asymptomatic_Treated;
pAdu(4)= app.deathrate_Adu_Asymptomatic_Untreated;
pSen(1)= app.deathrate_Sen_Symptomatic_Treated;
pSen(2)= app.deathrate_Sen_Symptomatic_Untreated;
pSen(3)= app.deathrate_Sen_Asymptomatic_Treated;
pSen(4)= app.deathrate_Sen_Asymptomatic_Untreated;


    InitConditions(1)= app.Initial_exposed ;
    InitConditions(2)= app.Initial_Vaccinated ;
    InitConditions(3) = app.Initial_Symptomatic_Infected_Quarantined ;
    InitConditions(4) = app.Initial_Symptomatic_Infected_NonQuarantined ;
    InitConditions(5) = app.Initial_Asymptomatic_Infected_Quarantined ;
    InitConditions(6) = app.Initial_Asymptomatic_Infected_NonQuarantined ;
    
    InitConditions(7)=app.Initial_Symptomatic_Recovered  ;
    InitConditions(8) = app.Initial_Asymptomatic_Recovered ;
   
    InitConditions(9)=app.Initial_Symptomatic_Dead;
    InitConditions(10)=app.Initial_Asymptomatic_Dead;


    % 
            if app.SimulationType ==0  % deterministic
                [app.NumMatrix,app.ProcessedModelDataSet,ActualDataSet] =    Covid19MainFnDeterministic(p1, p2, pChi,pAdu,pSen,InitConditions,app.CompareWithData, app.DataFileName) ;   
                
                Plot_Covid19_Results_Deterministic(app.ProcessedModelDataSet,app.CompareWithData,ActualDataSet);
 
                
            else % MonteCarlo Type
                %  Give several sets of parameters, perform the runs, collect the results, and organize them 
                [app.NumMatrix,app.ProcessedModelDataSet,app.ModelStdevDataSet,ActualDataset] =    Covid19MainFnMonteCarlo(p1, p2, pChi,pAdu,pSen,InitConditions,app.CompareWithData, app.DataFileName,app.Variation_percent,app.NumRuns) ;    
                Plot_Covid19_Results_MonteCarlo(app.ProcessedModelDataSet, app.ModelStdevDataSet,app.CompareWithData,ActualDataset);


            end % end of if app.simulationtype 
            
            app.SaveMenu.Enable = 'On';
% app.RunButton.Text = 'Run';
% app.RunButton.Enable = true;

        end

        % Menu selected function: HelpMenu_2
        function HelpMenu_2Selected(app, event)
         if isdeployed % Stand-alone mode.
            [status, result] = system('path');
            currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
        else % MATLAB mode.
            currentDir = pwd;
        end
web([currentDir '/CTM_v_2_0_Help.pdf'])
        end

        % Value changed function: millionsEditField
        function millionsEditFieldValueChanged(app, event)
             app.N_millions  = app.millionsEditField.Value;
            
        end

        % Value changed function: 
        % FractioninfectedshowingsymptomsEditField
        function FractioninfectedshowingsymptomsEditFieldValueChanged(app, event)
         app.SymptomaticFraction  = app.FractioninfectedshowingsymptomsEditField.Value;
            
        end

        % Value changed function: 
        % HowlongwillittaketoshowsymptomsdaysEditField
        function HowlongwillittaketoshowsymptomsdaysEditFieldValueChanged(app, event)
             app.Tau_E = app.HowlongwillittaketoshowsymptomsdaysEditField.Value;
            
        end

        % Value changed function: 
        % HowlongwillittaketorecoverdaysEditField
        function HowlongwillittaketorecoverdaysEditFieldValueChanged(app, event)
            app.Tau_I = app.HowlongwillittaketorecoverdaysEditField.Value;
            
        end

        % Value changed function: 
        % HowlongwillimmunitylastdaysEditField
        function HowlongwillimmunitylastdaysEditFieldValueChanged(app, event)
             app.Tau_R = app.HowlongwillimmunitylastdaysEditField.Value;
            
        end

        % Callback function
        function SymptomaticTreatedDeathrateEditFieldValueChanged(app, event)
            app.deathrate_Symptomatic_Quarantined   = app.SymptomaticTreatedEditField.Value;
            
        end

        % Callback function
        function SymptomaticUntreatedDeathrateEditFieldValueChanged(app, event)
            app.deathrate_Symptomatic_Nonquarantined   = app.SymptomaticUntreatedEditField.Value;
            
        end

        % Callback function
        function AsymptomaticQuarantinedDeathrateEditFieldValueChanged(app, event)
            app.deathrate_Asymptomatic_Quarantined   = app.AsymptomaticTreatedEditField.Value;
            
        end

        % Callback function
        function AsymptomaticNonquarantinedDeathrateEditFieldValueChanged(app, event)
            app.deathrate_Asymptomatic_Nonquarantined  = app.AsymptomaticUntreatedEditField.Value;
            
        end

        % Callback function
        function exposedEditFieldValueChanged(app, event)
            app.Initial_exposed      = app.exposedEditField.Value;
            
        end

        % Callback function
        function infectedshowingsymptomquarantinedEditFieldValueChanged(app, event)
            app.Initial_Symptomatic_Infected_Quarantined     = app.infectedshowingsymptomquarantinedEditField.Value;
            
        end

        % Callback function
        function infectedshowingsymptomnotquarantinedEditFieldValueChanged(app, event)
            app.Initial_Symptomatic_Infected_NonQuarantined     = app.infectedshowingsymptomnotquarantinedEditField.Value;
            
        end

        % Callback function
        function infectednosymptomquarantinedEditFieldValueChanged(app, event)
            app.Initial_Asymptomatic_Infected_Quarantined     = app.infectednosymptomquarantinedEditField.Value;
            
        end

        % Callback function
        function infectednosymptomnotquarantinedEditFieldValueChanged(app, event)
            app.Initial_Asymptomatic_Infected_NonQuarantined     = app.infectednosymptomnotquarantinedEditField.Value;
            
        end

        % Callback function
        function showingsymptomrecoveredEditFieldValueChanged(app, event)
            app.Initial_Symptomatic_Recovered     = app.showingsymptomrecoveredEditField.Value;
            
        end

        % Callback function
        function showingnosymptomrecoveredEditFieldValueChanged(app, event)
            app.Initial_Asymptomatic_Recovered     = app.showingnosymptomrecoveredEditField.Value;
            
        end

        % Callback function
        function showingsymptomdeadEditFieldValueChanged(app, event)
            app.Initial_Symptomatic_Dead     = app.showingsymptomdeadEditField.Value;
            
        end

        % Callback function
        function showingnosymptomdeadEditFieldValueChanged(app, event)
            app.Initial_Asymptomatic_Dead     = app.showingnosymptomdeadEditField.Value;
            
        end

        % Callback function
        function RelaxedPhase0InitialPerioddaysEditFieldValueChanged(app, event)
            app.InitPeriod      = app.RelaxedPhase0InitialPerioddaysEditField.Value;
            
        end

        % Callback function
        function Lockdown1daysEditFieldValueChanged(app, event)
            app.Lockdown1     = app.Lockdown1daysEditField.Value;
            
        end

        % Callback function
        function Relaxedphase1daysEditFieldValueChanged(app, event)
            app.Relaxed1     = app.Relaxedphase1daysEditField.Value;
            
        end

        % Callback function
        function Lockdown2daysEditFieldValueChanged(app, event)
            app.Lockdown2     = app.Lockdown2daysEditField.Value;
            
        end

        % Callback function
        function Relaxedphase2daysEditFieldValueChanged(app, event)
            app.Relaxed2     = app.Relaxedphase2daysEditField.Value;
            
        end

        % Callback function
        function Lockdown3daysEditFieldValueChanged(app, event)
            app.Lockdown3     = app.Lockdown3daysEditField.Value;
            
        end

        % Callback function
        function Relaxedphase3daysEditFieldValueChanged(app, event)
            app.Relaxed3     = app.Relaxedphase3daysEditField.Value;
            
        end

        % Callback function
        function R0SymptomaticEditField_6ValueChanged(app, event)
            app.R0_LD_Symptomatic     = app.R0SymptomaticEditField_06.Value;
            
        end

        % Callback function
        function R0AsymptomaticEditField_5ValueChanged(app, event)
            app.R0_LD_Asymptomatic     = app.R0AsymptomaticEditField_05.Value;
            
        end

        % Callback function
        function FractionofsymptomaticpeoplequarantinedEditField_5ValueChanged(app, event)
            app.q_fraction_LDSymptomatic     = app.FractionofsymptomaticpeopletreatedEditField_05.Value;
            
        end

        % Callback function
        function FractionofAsymptomaticpeoplequarantinedEditField_5ValueChanged(app, event)
            app.q_fraction_LDAsymptomatic     = app.FractionofAsymptomaticpeopletreatedEditField_05.Value;
            
        end

        % Callback function
        function R0SymptomaticEditField_7ValueChanged(app, event)
            app.R0_RD_Symptomatic     = app.R0SymptomaticEditField_07.Value;
            
        end

        % Callback function
        function R0AsymptomaticEditField_6ValueChanged(app, event)
            app.R0_RD_Asymptomatic     = app.R0AsymptomaticEditField_06.Value;
            
        end

        % Callback function
        function FractionofsymptomaticpeoplequarantinedEditField_6ValueChanged(app, event)
            app.q_fraction_RDSymptomatic     = app.FractionofsymptomaticpeopletreatedEditField_06.Value;
            
        end

        % Callback function
        function FractionofAsymptomaticpeoplequarantinedEditField_6ValueChanged(app, event)
            app.q_fraction_RDAsymptomatic     = app.FractionofAsymptomaticpeopletreatedEditField_06.Value;
            
        end

        % Callback function
        function SaveresultsinxlsxEditFieldValueChanged(app, event)
%             app.ResultsFileName = app.SaveresultsinxlsxEditField.Value;
            
        end

        % Callback function
        function CustomizePushed(app, event)
            value = app.CustomizeDropDown.Value;
            
if strcmp(value,'Yes')
    app.Below10EditField.Editable=true;
else

% first we should put back default values
% then make them non-editable
    app.Below10EditField.Editable=false;
end
            
        end

        % Selection changed function: ShowButtonGroup
        function ShowButtonGroupSelectionChanged(app, event)
            switch app.ShowButtonGroup.SelectedObject.Text
            
                case 'Model Results Only'
                
                    app.LoadDataFromxlsxEditField.Enable = 'Off';
                    app.DataBrowseButton.Enable = 'Off';
                    app.CompareWithData = 0;
                    
                case 'Model and Actual Data'
                app.LoadDataFromxlsxEditField.Enable = 'On';
                app.DataBrowseButton.Enable='On';
                    app.CompareWithData = 1;
                otherwise
                    % do nothing;
            end
            
        end

        % Selection changed function: SimulationTypeButtonGroup
        function SimulationTypeButtonGroupSelectionChanged(app, event)
            switch app.SimulationTypeButtonGroup.SelectedObject.Text
                
                case 'Deterministic (Default)'
                    app.VariationEditField.Enable = 'Off';
                    app.SimulationType=0;
                    
                case 'Monte Carlo'
                    app.VariationEditField.Enable= 'On';
                    app.SimulationType=1;
                otherwise 
                    % do nothing
            end
            
        end

        % Value changed function: Between10and60EditField
        function Between10and60EditFieldValueChanged(app, event)
           oldValue = app.AdultFraction; 
           try

        app.AdultFraction = app.Between10and60EditField.Value;
        app.ChildrenFraction = 1-app.AdultFraction - app.SenCitizenFraction;
           app.Below10EditField.Value = app.ChildrenFraction;
            
           catch ME
        app.AdultFraction = oldValue;
        app.Between10and60EditField.Value = app.AdultFraction;
        app.ChildrenFraction = 1-app.AdultFraction - app.SenCitizenFraction;
           app.Below10EditField.Value = app.ChildrenFraction;
        
           i1 = app.RegionDropDown.Value ;
           if i1== (app.RegionArrayLength+1)
           message=sprintf('Error! We cannot force Young group fraction below 0\n Reverting to previous values')   ;
           else
            ErrorMessage1 = strcat('Error! We cannot force Young group fraction below 0\n', 'Please check the data of ', string(app.RegionDropDown.Items(i1)) ,' region');
          
           
               message=sprintf(ErrorMessage1)   ;
               
           end
           uiwait(warndlg(message))
           
           end
            
        end

        % Value changed function: Above60EditField
        function Above60EditFieldValueChanged(app, event)
            oldValue = app.SenCitizenFraction;
            try
                app.SenCitizenFraction= app.Above60EditField.Value;
                app.ChildrenFraction= 1-app.AdultFraction - app.SenCitizenFraction;
                       app.Below10EditField.Value = app.ChildrenFraction;
            catch ME
              app.SenCitizenFraction= oldValue;
               app.Above60EditField.Value=app.SenCitizenFraction;
                app.ChildrenFraction= 1-app.AdultFraction - app.SenCitizenFraction;
                app.Below10EditField.Value = app.ChildrenFraction;
           
              
   i1 = app.RegionDropDown.Value ;
           if i1== (app.RegionArrayLength+1)
           message=sprintf('Error! We cannot force Young group fraction below 0\n Reverting to previous values')   ;
           else
           ErrorMessage1 = strcat('Error! We cannot force Young group fraction below 0\n', 'Please check the data of ', string(app.RegionDropDown.Items(i1)) ,' region');
           
               message=sprintf(ErrorMessage1)   ;
               
           end
           uiwait(warndlg(message))
             
            end
     
            
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close all;
            app.delete  
        end

        % Value changed function: RegionDropDown
        function RegionDropDownValueChanged(app, event)
            IndexValue = app.RegionDropDown.Value;
            
% if it is custom, then enable  changing population details
            if IndexValue == app.RegionArrayLength+1
                app.millionsEditField.Editable=true;
                app.Above60EditField.Editable=true;
%                 app.Below10EditField.Editable = true;
                app.Between10and60EditField.Editable = true;
                
                
                % if it is not custom, then disable changing population
                % details
            else
                app.millionsEditField.Editable=false;
                app.Above60EditField.Editable=false;
%                 app.Below10EditField.Editable = false;
                app.Between10and60EditField.Editable = false;
                
                app.millionsEditField.Value =app.PopulationArray(IndexValue);
                app.millionsEditFieldValueChanged();
                
                app.Above60EditField.Value = app.SenCitizenFractionArray(IndexValue);
                app.Above60EditFieldValueChanged();
                %                 app.Below10EditField.Value = app.ChildrenFractionArray(IndexValue);
                app.Between10and60EditField.Value = app.AdultFractionArray(IndexValue);
                app.Between10and60EditFieldValueChanged();
                
            filename = app.RegionParamFileNames(IndexValue);
            app.LoadParamSet(filename)

            app.DataFileName = app.RegionDataFileNames(IndexValue);
            app.LoadDataFromxlsxEditField.Value = app.DataFileName;
            end % end of if else
            

        end

        % Value changed function: R0SymptomaticEditField_01
        function R0SymptomaticEditField_01ValueChanged(app, event)
            app.R0_Array_Symptomatic(1)= app.R0SymptomaticEditField_01.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_01
        function R0AsymptomaticEditField_01ValueChanged(app, event)
            app.R0_Array_Asymptomatic(1) = app.R0AsymptomaticEditField_01.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_01
        function FractionofsymptomaticpeopletreatedEditField_01ValueChanged(app, event)
            app.FractionTreated_Array_Symptomatic(1) = app.FractionofsymptomaticpeopletreatedEditField_01.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_01
        function FractionofAsymptomaticpeopletreatedEditField_01ValueChanged(app, event)
            app.FractionTreated_Array_Asymptomatic(1) = app.FractionofAsymptomaticpeopletreatedEditField_01.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_02
        function R0SymptomaticEditField_02ValueChanged(app, event)
            app.R0_Array_Symptomatic(2) = app.R0SymptomaticEditField_02.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_02
        function R0AsymptomaticEditField_02ValueChanged(app, event)
            app.R0_Array_Aymptomatic(2) = app.R0AsymptomaticEditField_02.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_02
        function FractionofsymptomaticpeopletreatedEditField_02ValueChanged(app, event)
            app.FractionTreated_Array_Symptomatic(2) = app.FractionofsymptomaticpeopletreatedEditField_02.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_02
        function FractionofAsymptomaticpeopletreatedEditField_02ValueChanged(app, event)
            app.FractionTreated_Array_Asymptomatic(2)= app.FractionofAsymptomaticpeopletreatedEditField_02.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_03
        function R0SymptomaticEditField_03ValueChanged(app, event)
            app.R0_Array_Symptomatic(3) = app.R0SymptomaticEditField_03.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_03
        function R0AsymptomaticEditField_03ValueChanged(app, event)
            app.R0_Array_Asymptomatic(3) = app.R0AsymptomaticEditField_03.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_03
        function FractionofsymptomaticpeopletreatedEditField_03ValueChanged(app, event)
            app.FractionTreated_Array_Symptomatic(3) = app.FractionofsymptomaticpeopletreatedEditField_03.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_03
        function FractionofAsymptomaticpeopletreatedEditField_03ValueChanged(app, event)
            app.FractionTreated_Array_Asymptomatic(3) = app.FractionofAsymptomaticpeopletreatedEditField_03.Value;
            
        end

        % Value changed function: SymptomaticTreatedEditField
        function SymptomaticTreatedEditFieldValueChanged(app, event)
            app.deathrate_Chi_Symptomatic_Treated = app.SymptomaticTreatedEditField.Value;
            
        end

        % Value changed function: SymptomaticUntreatedEditField
        function SymptomaticUntreatedEditFieldValueChanged(app, event)
            app.deathrate_Chi_Symptomatic_Untreated = app.SymptomaticUntreatedEditField.Value;
            
        end

        % Value changed function: AsymptomaticTreatedEditField
        function AsymptomaticTreatedEditFieldValueChanged(app, event)
            app.deathrate_Chi_Asymptomatic_Treated = app.AsymptomaticTreatedEditField.Value;
            
        end

        % Value changed function: AsymptomaticUntreatedEditField
        function AsymptomaticUntreatedEditFieldValueChanged(app, event)
            app.deathrate_Chi_Asymptomatic_Untreated = app.AsymptomaticUntreatedEditField.Value;
            
        end

        % Value changed function: SymptomaticTreatedEditField_2
        function SymptomaticTreatedEditField_2ValueChanged(app, event)
            app.deathrate_Adu_Symptomatic_Treated = app.SymptomaticTreatedEditField_2.Value;
            
        end

        % Value changed function: SymptomaticUntreatedEditField_2
        function SymptomaticUntreatedEditField_2ValueChanged(app, event)
            app.deathrate_Adu_Symptomatic_Untreated= app.SymptomaticUntreatedEditField_2.Value;
            
        end

        % Value changed function: AsymptomaticTreatedEditField_2
        function AsymptomaticTreatedEditField_2ValueChanged(app, event)
            app.deathrate_Adu_Asymptomatic_Treated = app.AsymptomaticTreatedEditField_2.Value;
            
        end

        % Value changed function: AsymptomaticUntreatedEditField_2
        function AsymptomaticUntreatedEditField_2ValueChanged(app, event)
            app.deathrate_Adu_Asymptomatic_Untreated = app.AsymptomaticUntreatedEditField_2.Value;
            
        end

        % Value changed function: SymptomaticTreatedEditField_3
        function SymptomaticTreatedEditField_3ValueChanged(app, event)
            app.deathrate_Sen_Symptomatic_Treated = app.SymptomaticTreatedEditField_3.Value;
            
        end

        % Value changed function: SymptomaticUntreatedEditField_3
        function SymptomaticUntreatedEditField_3ValueChanged(app, event)
            app.deathrate_Sen_Symptomatic_Untreated = app.SymptomaticUntreatedEditField_3.Value;
            
        end

        % Value changed function: AsymptomaticTreatedEditField_3
        function AsymptomaticTreatedEditField_3ValueChanged(app, event)
            app.deathrate_Sen_Asymptomatic_Treated = app.AsymptomaticTreatedEditField_3.Value;
            
        end

        % Value changed function: AsymptomaticUntreatedEditField_3
        function AsymptomaticUntreatedEditField_3ValueChanged(app, event)
            app.deathrate_Sen_Asymptomatic_Untreated = app.AsymptomaticUntreatedEditField_3.Value;
            
        end

        % Value changed function: LoadDataFromxlsxEditField
        function LoadDataFromxlsxEditFieldValueChanged(app, event)
            value = app.LoadDataFromxlsxEditField.Value;
            
        end

        % Value changed function: exposedEditField_2
        function exposedEditField_2ValueChanged(app, event)
            app.Initial_exposed = app.exposedEditField_2.Value;
            
        end

        % Value changed function: 
        % infectedshowingsymptomquarantinedEditField_2
        function infectedshowingsymptomquarantinedEditField_2ValueChanged(app, event)
            app.Initial_Symptomatic_Infected_Quarantined= app.infectedshowingsymptomquarantinedEditField_2.Value;
            
        end

        % Value changed function: 
        % infectedshowingsymptomnotquarantinedEditField_2
        function infectedshowingsymptomnotquarantinedEditField_2ValueChanged(app, event)
            app.Initial_Symptomatic_Infected_NonQuarantined = app.infectedshowingsymptomnotquarantinedEditField_2.Value;
            
        end

        % Value changed function: 
        % infectednosymptomquarantinedEditField_2
        function infectednosymptomquarantinedEditField_2ValueChanged(app, event)
            app.Initial_Asymptomatic_Infected_Quarantined= app.infectednosymptomquarantinedEditField_2.Value;
            
        end

        % Value changed function: 
        % infectednosymptomnotquarantinedEditField_2
        function infectednosymptomnotquarantinedEditField_2ValueChanged(app, event)
            app.Initial_Asymptomatic_Infected_NonQuarantined= app.infectednosymptomnotquarantinedEditField_2.Value;
            
        end

        % Value changed function: showingsymptomrecoveredEditField_2
        function showingsymptomrecoveredEditField_2ValueChanged(app, event)
            app.Initial_Symptomatic_Recovered= app.showingsymptomrecoveredEditField_2.Value;
            
        end

        % Value changed function: 
        % showingnosymptomrecoveredEditField_2
        function showingnosymptomrecoveredEditField_2ValueChanged(app, event)
            app.Initial_Asymptomatic_Recovered= app.showingnosymptomrecoveredEditField_2.Value;
            
        end

        % Value changed function: showingsymptomdeadEditField_2
        function showingsymptomdeadEditField_2ValueChanged(app, event)
            app.Initial_Symptomatic_Dead= app.showingsymptomdeadEditField_2.Value;
            
        end

        % Value changed function: showingnosymptomdeadEditField_2
        function showingnosymptomdeadEditField_2ValueChanged(app, event)
            app.Initial_Asymptomatic_Dead= app.showingnosymptomdeadEditField_2.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_04
        function R0SymptomaticEditField_04ValueChanged(app, event)
            app.R0_Array_Symptomatic(4) = app.R0SymptomaticEditField_04.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_04
        function R0AsymptomaticEditField_04ValueChanged(app, event)
            app.R0_Array_Asymptomatic(4) = app.R0AsymptomaticEditField_04.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_04
        function FractionofsymptomaticpeopletreatedEditField_04ValueChanged(app, event)
            app.FractionTreated_Array_Symptomatic(4) = app.FractionofsymptomaticpeopletreatedEditField_04.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_04
        function FractionofAsymptomaticpeopletreatedEditField_04ValueChanged(app, event)
            app.FractionTreated_Array_Asymptomatic(4) = app.FractionofAsymptomaticpeopletreatedEditField_04.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_05
        function R0SymptomaticEditField_05ValueChanged(app, event)
             app.R0_Array_Symptomatic(5) = app.R0SymptomaticEditField_05.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_05
        function R0AsymptomaticEditField_05ValueChanged2(app, event)
            app.R0_Array_Asymptomatic(5)  = app.R0AsymptomaticEditField_05.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_05
        function FractionofsymptomaticpeopletreatedEditField_05ValueChanged2(app, event)
             app.FractionTreated_Array_Symptomatic(5) = app.FractionofsymptomaticpeopletreatedEditField_05.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_05
        function FractionofAsymptomaticpeopletreatedEditField_05ValueChanged2(app, event)
            app.FractionTreated_Array_Asymptomatic(5) = app.FractionofAsymptomaticpeopletreatedEditField_05.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_06
        function R0SymptomaticEditField_06ValueChanged2(app, event)
            app.R0_Array_Symptomatic(6) = app.R0SymptomaticEditField_06.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_06
        function R0AsymptomaticEditField_06ValueChanged2(app, event)
            app.R0_Array_Asymptomatic(6) = app.R0AsymptomaticEditField_06.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_06
        function FractionofsymptomaticpeopletreatedEditField_06ValueChanged2(app, event)
             app.FractionTreated_Array_Symptomatic(6) = app.FractionofsymptomaticpeopletreatedEditField_06.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_06
        function FractionofAsymptomaticpeopletreatedEditField_06ValueChanged2(app, event)
             app.FractionTreated_Array_Asymptomatic(6) = app.FractionofAsymptomaticpeopletreatedEditField_06.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_07
        function R0SymptomaticEditField_07ValueChanged2(app, event)
            app.R0_Array_Symptomatic(7) = app.R0SymptomaticEditField_07.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_07
        function R0AsymptomaticEditField_07ValueChanged(app, event)
            app.R0_Array_Asymptomatic(7) = app.R0AsymptomaticEditField_07.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_07
        function FractionofsymptomaticpeopletreatedEditField_07ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(7) = app.FractionofsymptomaticpeopletreatedEditField_07.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_07
        function FractionofAsymptomaticpeopletreatedEditField_07ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(7) = app.FractionofAsymptomaticpeopletreatedEditField_07.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_08
        function R0SymptomaticEditField_08ValueChanged(app, event)
            app.R0_Array_Symptomatic(8) = app.R0SymptomaticEditField_08.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_08
        function R0AsymptomaticEditField_08ValueChanged(app, event)
            app.R0_Array_Asymptomatic(8) = app.R0AsymptomaticEditField_08.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_08
        function FractionofsymptomaticpeopletreatedEditField_08ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(8) = app.FractionofsymptomaticpeopletreatedEditField_08.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_08
        function FractionofAsymptomaticpeopletreatedEditField_08ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(8) = app.FractionofAsymptomaticpeopletreatedEditField_08.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_09
        function R0SymptomaticEditField_09ValueChanged(app, event)
            app.R0_Array_Symptomatic(9) = app.R0SymptomaticEditField_09.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_09
        function R0AsymptomaticEditField_09ValueChanged(app, event)
            app.R0_Array_Asymptomatic(9) = app.R0AsymptomaticEditField_09.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_09
        function FractionofsymptomaticpeopletreatedEditField_09ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(9) = app.FractionofsymptomaticpeopletreatedEditField_09.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_09
        function FractionofAsymptomaticpeopletreatedEditField_09ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(9) = app.FractionofAsymptomaticpeopletreatedEditField_09.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_10
        function R0SymptomaticEditField_10ValueChanged(app, event)
            app.R0_Array_Symptomatic(10) = app.R0SymptomaticEditField_10.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_10
        function R0AsymptomaticEditField_10ValueChanged(app, event)
            app.R0_Array_Asymptomatic(10) = app.R0AsymptomaticEditField_10.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_10
        function FractionofsymptomaticpeopletreatedEditField_10ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(10) = app.FractionofsymptomaticpeopletreatedEditField_10.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_10
        function FractionofAsymptomaticpeopletreatedEditField_10ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(10) = app.FractionofAsymptomaticpeopletreatedEditField_10.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_11
        function R0SymptomaticEditField_11ValueChanged(app, event)
            app.R0_Array_Symptomatic(11) = app.R0SymptomaticEditField_11.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_11
        function R0AsymptomaticEditField_11ValueChanged(app, event)
            app.R0_Array_Asymptomatic(11) = app.R0AsymptomaticEditField_11.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_11
        function FractionofsymptomaticpeopletreatedEditField_11ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(11) = app.FractionofsymptomaticpeopletreatedEditField_11.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_11
        function FractionofAsymptomaticpeopletreatedEditField_11ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(11) = app.FractionofAsymptomaticpeopletreatedEditField_11.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_12
        function R0SymptomaticEditField_12ValueChanged(app, event)
            app.R0_Array_Symptomatic(12) = app.R0SymptomaticEditField_12.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_12
        function R0AsymptomaticEditField_12ValueChanged(app, event)
            app.R0_Array_Asymptomatic(12) = app.R0AsymptomaticEditField_12.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_12
        function FractionofsymptomaticpeopletreatedEditField_12ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(12) = app.FractionofsymptomaticpeopletreatedEditField_12.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_12
        function FractionofAsymptomaticpeopletreatedEditField_12ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(12) = app.FractionofAsymptomaticpeopletreatedEditField_12.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_13
        function R0SymptomaticEditField_13ValueChanged(app, event)
            app.R0_Array_Symptomatic(13) = app.R0SymptomaticEditField_13.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_13
        function R0AsymptomaticEditField_13ValueChanged(app, event)
            app.R0_Array_Asymptomatic(13) = app.R0AsymptomaticEditField_13.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_13
        function FractionofsymptomaticpeopletreatedEditField_13ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(13) = app.FractionofsymptomaticpeopletreatedEditField_13.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_13
        function FractionofAsymptomaticpeopletreatedEditField_13ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(13) = app.FractionofAsymptomaticpeopletreatedEditField_13.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_14
        function R0SymptomaticEditField_14ValueChanged(app, event)
            app.R0_Array_Symptomatic(14) = app.R0SymptomaticEditField_14.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_14
        function R0AsymptomaticEditField_14ValueChanged(app, event)
            app.R0_Array_Asymptomatic(14) = app.R0AsymptomaticEditField_14.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_14
        function FractionofsymptomaticpeopletreatedEditField_14ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(14) = app.FractionofsymptomaticpeopletreatedEditField_14.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_14
        function FractionofAsymptomaticpeopletreatedEditField_14ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(14) = app.FractionofAsymptomaticpeopletreatedEditField_14.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_15
        function R0SymptomaticEditField_15ValueChanged(app, event)
            app.R0_Array_Symptomatic(15) = app.R0SymptomaticEditField_15.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_15
        function R0AsymptomaticEditField_15ValueChanged(app, event)
            app.R0_Array_Asymptomatic(15) = app.R0AsymptomaticEditField_15.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_15
        function FractionofsymptomaticpeopletreatedEditField_15ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(15) = app.FractionofsymptomaticpeopletreatedEditField_15.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_15
        function FractionofAsymptomaticpeopletreatedEditField_15ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(15) = app.FractionofAsymptomaticpeopletreatedEditField_15.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_16
        function R0SymptomaticEditField_16ValueChanged(app, event)
            app.R0_Array_Symptomatic(16) = app.R0SymptomaticEditField_16.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_16
        function R0AsymptomaticEditField_16ValueChanged(app, event)
            app.R0_Array_Asymptomatic(16) = app.R0AsymptomaticEditField_16.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_16
        function FractionofsymptomaticpeopletreatedEditField_16ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(16) = app.FractionofsymptomaticpeopletreatedEditField_16.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_16
        function FractionofAsymptomaticpeopletreatedEditField_16ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(16) = app.FractionofAsymptomaticpeopletreatedEditField_16.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_17
        function R0SymptomaticEditField_17ValueChanged(app, event)
            app.R0_Array_Symptomatic(17) = app.R0SymptomaticEditField_17.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_17
        function R0AsymptomaticEditField_17ValueChanged(app, event)
            app.R0_Array_Asymptomatic(17) = app.R0AsymptomaticEditField_17.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_17
        function FractionofsymptomaticpeopletreatedEditField_17ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(17) = app.FractionofsymptomaticpeopletreatedEditField_17.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_17
        function FractionofAsymptomaticpeopletreatedEditField_17ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(17) = app.FractionofAsymptomaticpeopletreatedEditField_17.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_18
        function R0SymptomaticEditField_18ValueChanged(app, event)
            app.R0_Array_Symptomatic(18) = app.R0SymptomaticEditField_18.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_18
        function R0AsymptomaticEditField_18ValueChanged(app, event)
            app.R0_Array_Asymptomatic(18) = app.R0AsymptomaticEditField_18.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_18
        function FractionofsymptomaticpeopletreatedEditField_18ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(18) = app.FractionofsymptomaticpeopletreatedEditField_18.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_18
        function FractionofAsymptomaticpeopletreatedEditField_18ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(18) = app.FractionofAsymptomaticpeopletreatedEditField_18.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_19
        function R0SymptomaticEditField_19ValueChanged(app, event)
            app.R0_Array_Symptomatic(19) = app.R0SymptomaticEditField_19.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_19
        function R0AsymptomaticEditField_19ValueChanged(app, event)
            app.R0_Array_Asymptomatic(19) = app.R0AsymptomaticEditField_19.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_19
        function FractionofsymptomaticpeopletreatedEditField_19ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(19) = app.FractionofsymptomaticpeopletreatedEditField_19.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_19
        function FractionofAsymptomaticpeopletreatedEditField_19ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(19) = app.FractionofAsymptomaticpeopletreatedEditField_19.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_20
        function R0SymptomaticEditField_20ValueChanged(app, event)
            app.R0_Array_Symptomatic(20) = app.R0SymptomaticEditField_20.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_20
        function R0AsymptomaticEditField_20ValueChanged(app, event)
            app.R0_Array_Asymptomatic(20) = app.R0AsymptomaticEditField_20.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_20
        function FractionofsymptomaticpeopletreatedEditField_20ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(20) = app.FractionofsymptomaticpeopletreatedEditField_20.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_20
        function FractionofAsymptomaticpeopletreatedEditField_20ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(20) = app.FractionofAsymptomaticpeopletreatedEditField_20.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_21
        function R0SymptomaticEditField_21ValueChanged(app, event)
            app.R0_Array_Symptomatic(21) = app.R0SymptomaticEditField_21.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_21
        function R0AsymptomaticEditField_21ValueChanged(app, event)
            app.R0_Array_Asymptomatic(21) = app.R0AsymptomaticEditField_21.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_21
        function FractionofsymptomaticpeopletreatedEditField_21ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(21) = app.FractionofsymptomaticpeopletreatedEditField_21.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_21
        function FractionofAsymptomaticpeopletreatedEditField_21ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(21) = app.FractionofAsymptomaticpeopletreatedEditField_21.Value;
            
        end

        % Value changed function: R0SymptomaticEditField_22
        function R0SymptomaticEditField_22ValueChanged(app, event)
            app.R0_Array_Symptomatic(22) = app.R0SymptomaticEditField_22.Value;
            
        end

        % Value changed function: R0AsymptomaticEditField_22
        function R0AsymptomaticEditField_22ValueChanged(app, event)
            app.R0_Array_Asymptomatic(22) = app.R0AsymptomaticEditField_22.Value;
            
        end

        % Value changed function: 
        % FractionofsymptomaticpeopletreatedEditField_22
        function FractionofsymptomaticpeopletreatedEditField_22ValueChanged(app, event)
             app.FractionTreated_Array_Symptomatic(22) = app.FractionofsymptomaticpeopletreatedEditField_22.Value;
            
        end

        % Value changed function: 
        % FractionofAsymptomaticpeopletreatedEditField_22
        function FractionofAsymptomaticpeopletreatedEditField_22ValueChanged(app, event)
             app.FractionTreated_Array_Asymptomatic(22) = app.FractionofAsymptomaticpeopletreatedEditField_22.Value;
            
        end

        % Callback function
        function LoadRegionInfoMenuSelected(app, event)
            
[file,path] = uigetfile('*.xlsx');
if isequal(file,0)
%    disp('User selected Cancel');
else
%    disp(['User selected ', fullfile(path,file)]);

app.RegionInformationFileName = fullfile(path,file);
readtableBoolean = 1;
try
app.RegionInformation = readtable(app.RegionInformationFileName );
catch ME
readtableBoolean =0;
    uiwait(warndlg(ME.message));
end

if (readtableBoolean ==1)
            
app.RegionArray = string( app.RegionInformation{:,'Region'});

app.RegionArrayLength = length(app.RegionArray);
% disp(['Region Array Length = ' num2str(app.RegionArrayLength) ])


app.RegionArray(app.RegionArrayLength+1) = {'Custom'};
app.PopulationArray =  app.RegionInformation{:,'PopulationMillion'};
% app.ChildrenFractionArray = app.RegionInformation{:,'ChildrenFraction'};
app.AdultFractionArray = app.RegionInformation{:,'AdultFraction'};
app.SenCitizenFractionArray = app.RegionInformation{:,'SenCitizenFraction'};

%% loaded information. Next update the dropdown menu

app.RegionDropDown.Items = app.RegionArray;
app.RegionDropDown.ItemsData = 1:1:length(app.RegionArray);

app.RegionDropDownValueChanged();
app.RegionDropDown.Value = 1;
app.RegionDropDownValueChanged();

app.Above60EditField.Editable = false;
app.Between10and60EditField.Editable= false;
end % if read table is OK, then we implement the above changes, if not, we skip it

% disp(num2str(app.RegionDropDown.ItemsData))
end


        end

        % Button pushed function: DataBrowseButton
        function DataBrowseButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx');
if isequal(file,0)
%    disp('User selected Cancel');
else
%    disp(['User selected ', fullfile(path,file)]);

app.DataFileName = fullfile(path,file);
app.LoadDataFromxlsxEditField.Value = app.DataFileName;

end
   figure(app.CTMUIFigure);
        end

        % Callback function
        function ResultsBrowseButtonPushed(app, event)
            [file,path] = uiputfile('*.xlsx');
        if isequal(file,0)
    %    disp('User selected Cancel');
        else
%    disp(['User selected ', fullfile(path,file)]);

app.ResultsFileName = fullfile(path,file);
% app.SaveresultsinxlsxEditField.Value = app.ResultsFileName;

        end
        end

        % Callback function
        function RegionInfoMenuSelected(app, event)
        if isdeployed % Stand-alone mode.
            [status, result] = system('path');
            currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
        else % MATLAB mode.
            currentDir = pwd;
        end
winopen([currentDir '/SampleRegionInfo.xlsx'])
        
        end

        % Callback function
        function RealDataMenuSelected(app, event)

        if isdeployed % Stand-alone mode.
            [status, result] = system('path');
            currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
        else % MATLAB mode.
            currentDir = pwd;
        end
winopen([currentDir '/SampleActualData.xlsx'])
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
   [file,path] = uiputfile('*.xlsx');
if isequal(file,0)
    %    disp('User selected Cancel');
        else
%    disp(['User selected ', fullfile(path,file)]);

app.ResultsFileName = fullfile(path,file);
% app.SaveresultsinxlsxEditField.Value = app.ResultsFileName;            

p1(1) = app.N_millions * 1e6;
p1(2) = app.SenCitizenFraction;
p1(3) = app.AdultFraction;
p1(4) = app.ChildrenFraction;


p1(5) = app.SymptomaticFraction;
p1(6) = app.Tau_E ; % Exposed period
p1(7) = app.Tau_I ; % infectious period (before recovery)
p1(8) = app.Tau_R ; % immunity duration, time spent in recovery period
p1(9) = app.Tau_V ; % vaccine immunity duration, 

pChi(1)= app.deathrate_Chi_Symptomatic_Treated;
pChi(2)= app.deathrate_Chi_Symptomatic_Untreated;
pChi(3)= app.deathrate_Chi_Asymptomatic_Treated;
pChi(4)= app.deathrate_Chi_Asymptomatic_Untreated;

pAdu(1)= app.deathrate_Adu_Symptomatic_Treated;
pAdu(2)= app.deathrate_Adu_Symptomatic_Untreated;
pAdu(3)= app.deathrate_Adu_Asymptomatic_Treated;
pAdu(4)= app.deathrate_Adu_Asymptomatic_Untreated;
pSen(1)= app.deathrate_Sen_Symptomatic_Treated;
pSen(2)= app.deathrate_Sen_Symptomatic_Untreated;
pSen(3)= app.deathrate_Sen_Asymptomatic_Treated;
pSen(4)= app.deathrate_Sen_Asymptomatic_Untreated;


    InitConditions(1)= app.Initial_exposed ;
    InitConditions(2)= app.Initial_Vaccinated ;
    InitConditions(3) = app.Initial_Symptomatic_Infected_Quarantined ;
    InitConditions(4) = app.Initial_Symptomatic_Infected_NonQuarantined ;
    InitConditions(5) = app.Initial_Asymptomatic_Infected_Quarantined ;
    InitConditions(6) = app.Initial_Asymptomatic_Infected_NonQuarantined ;
    InitConditions(7)=app.Initial_Symptomatic_Recovered  ;
    InitConditions(8) = app.Initial_Asymptomatic_Recovered ;
    InitConditions(9) = app.Initial_Symptomatic_Dead;
    InitConditions(10) = app.Initial_Asymptomatic_Dead;


TableMatrix01 = table(app.ProcessedModelDataSet(:,1),app.ProcessedModelDataSet(:,2),app.ProcessedModelDataSet(:,3),app.ProcessedModelDataSet(:,4),app.ProcessedModelDataSet(:,5),...
    app.ProcessedModelDataSet(:,6),app.ProcessedModelDataSet(:,7),app.ProcessedModelDataSet(:,8),app.ProcessedModelDataSet(:,9),app.ProcessedModelDataSet(:,10),app.ProcessedModelDataSet(:,11),...
    app.ProcessedModelDataSet(:,12),app.ProcessedModelDataSet(:,13),app.ProcessedModelDataSet(:,14),app.ProcessedModelDataSet(:,15),app.ProcessedModelDataSet(:,16),app.ProcessedModelDataSet(:,17),...
    app.ProcessedModelDataSet(:,18),app.ProcessedModelDataSet(:,19),app.ProcessedModelDataSet(:,20),app.ProcessedModelDataSet(:,21),app.ProcessedModelDataSet(:,22),app.ProcessedModelDataSet(:,23),...
    app.ProcessedModelDataSet(:,24),app.ProcessedModelDataSet(:,25),app.ProcessedModelDataSet(:,26),app.ProcessedModelDataSet(:,27),app.ProcessedModelDataSet(:,28),app.ProcessedModelDataSet(:,29),...
    app.ProcessedModelDataSet(:,30),app.ProcessedModelDataSet(:,31),app.ProcessedModelDataSet(:,32),app.ProcessedModelDataSet(:,33),app.ProcessedModelDataSet(:,34));

header01={'Time(days)','Chi_Num_Susceptible','Chi_Num_Vaccinated','Chi_NumExposed','Chi_Num_Symptomatic_Quarantined', 'Chi_Num_Symptomatic_NonQuarantined',...
    'Chi_Num_Asymptomatic_Quarantined','Chi_Num_Asymptomatic_NonQuarantined','Chi_Num_Symptomatic_Recovered','Chi_Num_Asymptomatic_Recovered',...
    'Chi_Num_Symptomatic_Dead','Chi_Num_Asymptomatic_Dead',...
    'Adu_Num_Susceptible','Adu_Num_Vaccinated','Adu_NumExposed','Adu_Num_Symptomatic_Quarantined', 'Adu_Num_Symptomatic_NonQuarantined',...
    'Adu_Num_Asymptomatic_Quarantined','Adu_Num_Asymptomatic_NonQuarantined','Adu_Num_Symptomatic_Recovered','Adu_Num_Asymptomatic_Recovered',...
    'Adu_Num_Symptomatic_Dead','Adu_Num_Asymptomatic_Dead',...
    'Sen_Num_Susceptible','Sen_Num_Vaccinated','Sen_NumExposed','Sen_Num_Symptomatic_Quarantined', 'Sen_Num_Symptomatic_NonQuarantined',...
    'Sen_Num_Asymptomatic_Quarantined','Sen_Num_Asymptomatic_NonQuarantined','Sen_Num_Symptomatic_Recovered','Sen_Num_Asymptomatic_Recovered',...
    'Sen_Num_Symptomatic_Dead','Sen_Num_Asymptomatic_Dead'};
TableMatrix01.Properties.VariableNames = header01;

if app.SimulationType ==0  % deterministic
    
else % MonteCarlo Type
TableMatrix01A = table(app.ProcessedModelDataSet(:,1),app.ModelStdevDataSet(:,2),app.ModelStdevDataSet(:,3),app.ModelStdevDataSet(:,4),app.ModelStdevDataSet(:,5),...
    app.ModelStdevDataSet(:,6),app.ModelStdevDataSet(:,7),app.ModelStdevDataSet(:,8),app.ModelStdevDataSet(:,9),app.ModelStdevDataSet(:,10),app.ModelStdevDataSet(:,11),...
    app.ModelStdevDataSet(:,12),app.ModelStdevDataSet(:,13),app.ModelStdevDataSet(:,14),app.ModelStdevDataSet(:,15),app.ModelStdevDataSet(:,16),app.ModelStdevDataSet(:,17),...
    app.ModelStdevDataSet(:,18),app.ModelStdevDataSet(:,19),app.ModelStdevDataSet(:,20),app.ModelStdevDataSet(:,21),app.ModelStdevDataSet(:,22),app.ModelStdevDataSet(:,23),...
    app.ModelStdevDataSet(:,24),app.ModelStdevDataSet(:,25),app.ModelStdevDataSet(:,26),app.ModelStdevDataSet(:,27),app.ModelStdevDataSet(:,28),app.ModelStdevDataSet(:,29),...
    app.ModelStdevDataSet(:,30),app.ModelStdevDataSet(:,31));

header01A={'Time(days)','Chi_Num_Susceptible','Chi_Num_Vaccinated','Chi_NumExposed','Chi_Num_Symptomatic_Quarantined', 'Chi_Num_Symptomatic_NonQuarantined',...
    'Chi_Num_Asymptomatic_Quarantined','Chi_Num_Asymptomatic_NonQuarantined','Chi_Num_Symptomatic_Recovered','Chi_Num_Asymptomatic_Recovered',...
    'Chi_Num_Symptomatic_Dead','Chi_Num_Asymptomatic_Dead',...
    'Adu_Num_Susceptible','Adu_Num_Vaccinated','Adu_NumExposed','Adu_Num_Symptomatic_Quarantined', 'Adu_Num_Symptomatic_NonQuarantined',...
    'Adu_Num_Asymptomatic_Quarantined','Adu_Num_Asymptomatic_NonQuarantined','Adu_Num_Symptomatic_Recovered','Adu_Num_Asymptomatic_Recovered',...
    'Adu_Num_Symptomatic_Dead','Adu_Num_Asymptomatic_Dead',...
    'Sen_Num_Susceptible','Sen_Num_Vaccinated','Sen_NumExposed','Sen_Num_Symptomatic_Quarantined', 'Sen_Num_Symptomatic_NonQuarantined',...
    'Sen_Num_Asymptomatic_Quarantined','Sen_Num_Asymptomatic_NonQuarantined','Sen_Num_Symptomatic_Recovered','Sen_Num_Asymptomatic_Recovered',...
    'Sen_Num_Symptomatic_Dead','Sen_Num_Asymptomatic_Dead'};
TableMatrix01A.Properties.VariableNames = header01A;
         
end % end of if app.simulationtype 

%% All other works sheets are the same regardless of whether we use deterministic or monte carlo
    
   MonthLabel{1} = 'Mar-20';
   MonthLabel{2} = 'Apr-20';
   MonthLabel{3} = 'May-20';
   MonthLabel{4} = 'Jun-20';
   MonthLabel{5} = 'Jul-20';
   MonthLabel{6} = 'Aug-20';
   MonthLabel{7} = 'Sep-20';
   MonthLabel{8} = 'Oct-20';
   MonthLabel{9} = 'Nov-20';
   MonthLabel{10} = 'Dec-20';
   MonthLabel{11} = 'Jan-21';
   MonthLabel{12} = 'Feb-21';
   MonthLabel{13} = 'Mar-21';
   MonthLabel{14} = 'Apr-21';
   MonthLabel{15} = 'May-21';
   MonthLabel{16} = 'Jun-21';
   MonthLabel{17} = 'Jul-21';
   MonthLabel{18} = 'Aug-21';
   MonthLabel{19} = 'Sep-21';
   MonthLabel{20} = 'Oct-21';
   MonthLabel{21} = 'Nov-21';
   MonthLabel{22} = 'Dec-21';


TableMatrix02 = table( MonthLabel',app.R0_Array_Symptomatic, ...
    app.R0_Array_Asymptomatic, app.FractionTreated_Array_Symptomatic,...
    app.FractionTreated_Array_Asymptomatic,app.Array_Fraction_Adu_Vaccinated,app.Array_Fraction_Sen_Vaccinated); 
header02={'Month-Year','R0Symptomatic','R0Asymptomatic',...
    'SymptomaticFractionTreated','AsymptomaticFractionTreated','FractionAdultsVaccinated','FractionSeniorCitizenVaccinated'};
TableMatrix02.Properties.VariableNames = header02;

CategoryValues = {'Symptomatic-Treated','Symtomatic-Untreated','Asymptomatic-Treated',...
    'Asymptomatic-Untreated'};
TableMatrix03 = table( CategoryValues', pChi', pAdu', pSen'); 
header03={'Category','DeathRateChild','DeathRateAdult','DeathRateSeniorCitizen'};
TableMatrix03.Properties.VariableNames = header03;

InfectionValues = {'Fraction Symptomatic','Exposed Duration(days)',...
    'Infection Duration (days)','Immunity(Recovered) Duration (days)'};
TableMatrix04 = table( InfectionValues', p1(5:8)'); 
header04={'InfectionRelated','Values'};
TableMatrix04.Properties.VariableNames = header04;

InitValues = {'# Exposed','#Vaccinated','# Symptomatic Treated','# Symptomatic Untreated',...
    '# Asymptomatic Treated','# Asymptomatic Untreated',...
    '# Symptomatic Recovered','# Asymptomatic Recovered',...
    '# Symptomatic Dead','# Asymptomatic Dead'};
TableMatrix05 = table( InitValues', InitConditions'); 
header05={'InitialConditions','Values'};
TableMatrix05.Properties.VariableNames = header05;


RegionValues = {'Population','SeniorCitizenFraction','AdultFraction','ChildrenFraction'};
TableMatrix06 = table(RegionValues', p1(1:4)'); 
header06={'RegionInfo','Values'};
TableMatrix06.Properties.VariableNames = header06;

SimType= {'Simulation Type','Variability (%)'};

    if app.SimulationType ==0  % deterministic
        SimValues= {'Deterministic',0};
        % No need to add extra worksheet
    else % MonteCarlo Type
        
        SimValues = {'Monte Carlo', app.Variation_percent};
        
    end % end of if app.simulationtype 

TableMatrix07 = table(SimType',SimValues' );

header07 = {'Info','Value'};
TableMatrix07.Properties.VariableNames = header07;


if app.SimulationType ==0  % deterministic
    
else % MonteCarlo Type
             
end % end of if app.simulationtype 


try
    recycle on;
    delete(string(app.ResultsFileName));
    writetable(TableMatrix01,string(app.ResultsFileName ),'Sheet','Model Results','Range','A1');
if app.SimulationType ==0  % deterministic
    
else % MonteCarlo Type
    writetable(TableMatrix01A,string(app.ResultsFileName ),'Sheet','ResultsStdev','Range','A1');          
end % end of if app.simulationtype 
    writetable(TableMatrix02,string(app.ResultsFileName ),'Sheet','Monthly','Range','A1');
    writetable(TableMatrix03,string(app.ResultsFileName ),'Sheet','DeathRate','Range','A1');
    writetable(TableMatrix04,string(app.ResultsFileName ),'Sheet','Infection','Range','A1');
    writetable(TableMatrix05,string(app.ResultsFileName ),'Sheet','InitConditions','Range','A1');
    writetable(TableMatrix06,string(app.ResultsFileName ),'Sheet','Region','Range','A1');
    writetable(TableMatrix07,string(app.ResultsFileName ),'Sheet','SimType','Range','A1');
    

    if app.CompareWithData
        
        % we can save the loaded data also in the same excel file. do we
        % want to do that?
    end % end compare with data
    
catch ME
           
uiwait(warndlg(ME.message));
end


end
   figure(app.CTMUIFigure); % Bring main window to focus
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
            
        end

        % Button pushed function: LoadRegionInfoButton
        function LoadRegionInfoButtonPushed(app, event)
                 
[file,path] = uigetfile('*.xlsx');
if isequal(file,0)
%    disp('User selected Cancel');
else
%    disp(['User selected ', fullfile(path,file)]);

app.RegionInformationFileName = fullfile(path,file);
readtableBoolean = 1;
        try
        app.RegionInformation = readtable(app.RegionInformationFileName );
        catch ME
        readtableBoolean =0;
        uiwait(warndlg(ME.message));
        end

    if (readtableBoolean ==1)
            
    app.RegionArray = string( app.RegionInformation{:,'Region'});

    app.RegionArrayLength = length(app.RegionArray);
    % disp(['Region Array Length = ' num2str(app.RegionArrayLength) ])


    app.RegionArray(app.RegionArrayLength+1) = {'Custom'};
    app.PopulationArray =  app.RegionInformation{:,'PopulationMillion'};
    % app.ChildrenFractionArray = app.RegionInformation{:,'ChildrenFraction'};
    app.AdultFractionArray = app.RegionInformation{:,'AdultFraction'};
    app.SenCitizenFractionArray = app.RegionInformation{:,'SenCitizenFraction'};

    %% loaded information. Next update the dropdown menu

    app.RegionDropDown.Items = app.RegionArray;
    app.RegionDropDown.ItemsData = 1:1:length(app.RegionArray);

    app.RegionDropDownValueChanged();
    app.RegionDropDown.Value = 1;
    app.RegionDropDownValueChanged();

    app.Above60EditField.Editable = false;
    app.Between10and60EditField.Editable= false;
    end % if read table is OK, then we implement the above changes, if not, we skip it
end


figure(app.CTMUIFigure);

        end

        % Close request function: CTMUIFigure
        function CTMUIFigureCloseRequest(app, event)
            close all;
            delete(app)
            
        end

        % Callback function
        function ParametersMenuSelected(app, event)
             if isdeployed % Stand-alone mode.
            [status, result] = system('path');
            currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
        else % MATLAB mode.
            currentDir = pwd;
        end
winopen([currentDir '/SampleParameters.xlsx'])
        end

        % Value changed function: VariationEditField
        function VariationEditFieldValueChanged(app, event)
            app.Variation_percent= app.VariationEditField.Value;
            
        end

        % Menu selected function: RegionInfoMenu
        function RegionInfoMenuSelected2(app, event)
            
 if isdeployed % Stand-alone mode.
            [status, result] = system('path');
            currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
        else % MATLAB mode.
            currentDir = pwd;
        end
winopen([currentDir '/SampleRegionInfo.xlsx'])
        end

        % Menu selected function: RealDataMenu
        function RealDataMenuSelected2(app, event)
                   if isdeployed % Stand-alone mode.
            [status, result] = system('path');
            currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
        else % MATLAB mode.
            currentDir = pwd;
        end
winopen([currentDir '/SampleActualData.xlsx'])
        end

        % Menu selected function: ParametersMenu
        function ParametersMenuSelected2(app, event)
            
    if isdeployed % Stand-alone mode.
            [status, result] = system('path');
            currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
        else % MATLAB mode.
            currentDir = pwd;
        end
winopen([currentDir '/SampleParameters.xlsx'])
        end

        % Callback function
        function testButtonPushed(app, event)
            
            if isdeployed % Stand-alone mode.
            [status, result] = system('path');
            currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
        else % MATLAB mode.
            currentDir = pwd;
        end

           app.ParameterFileName = [currentDir '\TNParamSet.xlsx'];


readtableBoolean = 1;
try

disp(['Reading file ' app.ParameterFileName])

readtable(app.ParameterFileName,'Sheet','Monthly') %,'DataRange','A2' )

catch ME
    uiwait(warndlg(['Problem in reading ' app.ParameterFileName]));
    uiwait(warndlg(ME.message));
%     uiwait(warndlg(size(A)))
    readtableBoolean = 0;

end
if readtableBoolean == 1
uiwait(warndlg('Successfully read the file'));
end
results=0;
          % app.testfunction();
        end

        % Value changed function: 
        % HowlongwillvaccineimmunitylastdaysEditField
        function HowlongwillvaccineimmunitylastdaysEditFieldValueChanged(app, event)
            app.Tau_V= app.HowlongwillvaccineimmunitylastdaysEditField.Value;
            
        end

        % Value changed function: FractionAdultsEditField_11
        function FractionAdultsEditField_11ValueChanged(app, event)
            app.Array_Fraction_Adu_Vaccinated(11)= app.FractionAdultsEditField_11.Value;
            
        end

        % Value changed function: FractionSeniorCitizensEditField_11
        function FractionSeniorCitizensEditField_11ValueChanged(app, event)
            app.Array_Fraction_Sen_Vaccinted(11)= app.FractionSeniorCitizensEditField_11.Value;
            
        end

        % Value changed function: FractionAdultsEditField_12
        function FractionAdultsEditField_12ValueChanged(app, event)
             app.Array_Fraction_Adu_Vaccinated(12) = app.FractionAdultsEditField_12.Value;
            
        end

        % Value changed function: FractionSeniorCitizensEditField_12
        function FractionSeniorCitizensEditField_12ValueChanged(app, event)
             app.Array_Fraction_Sen_Vaccinated(12) = app.FractionSeniorCitizensEditField_12.Value;
            
        end

        % Value changed function: FractionAdultsEditField_13
        function FractionAdultsEditField_13ValueChanged(app, event)
             app.Array_Fraction_Adu_Vaccinated(13) = app.FractionAdultsEditField_13.Value;
            
        end

        % Value changed function: FractionSeniorCitizensEditField_13
        function FractionSeniorCitizensEditField_13ValueChanged(app, event)
             app.Array_Fraction_Sen_Vaccinated(13) = app.FractionSeniorCitizensEditField_13.Value;
            
        end

        % Value changed function: FractionAdultsEditField_14
        function FractionAdultsEditField_14ValueChanged(app, event)
             app.Array_Fraction_Adu_Vaccinated(14) = app.FractionAdultsEditField_14.Value;
            
        end

        % Value changed function: FractionSeniorCitizensEditField_14
        function FractionSeniorCitizensEditField_14ValueChanged(app, event)
             app.Array_Fraction_Sen_Vaccinated(14) = app.FractionSeniorCitizensEditField_14.Value;
            
        end

        % Value changed function: FractionAdultsEditField_15
        function FractionAdultsEditField_15ValueChanged(app, event)
             app.Array_Fraction_Adu_Vaccinated(15) = app.FractionAdultsEditField_15.Value;
            
        end

        % Value changed function: FractionSeniorCitizensEditField_15
        function FractionSeniorCitizensEditField_15ValueChanged(app, event)
             app.Array_Fraction_Sen_Vaccinated(15) = app.FractionSeniorCitizensEditField_15.Value;
            
        end

        % Value changed function: FractionAdultsEditField_16
        function FractionAdultsEditField_16ValueChanged(app, event)
             app.Array_Fraction_Adu_Vaccinated(16) = app.FractionAdultsEditField_16.Value;
            
        end

        % Value changed function: FractionSeniorCitizensEditField_16
        function FractionSeniorCitizensEditField_16ValueChanged(app, event)
             app.Array_Fraction_Sen_Vaccinated(16) = app.FractionSeniorCitizensEditField_16.Value;
            
        end

        % Value changed function: FractionAdultsEditField_17
        function FractionAdultsEditField_17ValueChanged(app, event)
             app.Array_Fraction_Adu_Vaccinated(17) = app.FractionAdultsEditField_17.Value;
            
        end

        % Value changed function: FractionSeniorCitizensEditField_17
        function FractionSeniorCitizensEditField_17ValueChanged(app, event)
             app.Array_Fraction_Sen_Vaccinated(17) = app.FractionSeniorCitizensEditField_17.Value;
            
        end

        % Value changed function: FractionAdultsEditField_18
        function FractionAdultsEditField_18ValueChanged(app, event)
             app.Array_Fraction_Adu_Vaccinated(18) = app.FractionAdultsEditField_18.Value;
            
        end

        % Value changed function: FractionSeniorCitizensEditField_18
        function FractionSeniorCitizensEditField_18ValueChanged(app, event)
             app.Array_Fraction_Sen_Vaccinated(18) = app.FractionSeniorCitizensEditField_18.Value;
            
        end

        % Value changed function: FractionAdultsEditField_19
        function FractionAdultsEditField_19ValueChanged(app, event)
             app.Array_Fraction_Adu_Vaccinated(19) = app.FractionAdultsEditField_19.Value;
            
        end

        % Value changed function: FractionSeniorCitizensEditField_19
        function FractionSeniorCitizensEditField_19ValueChanged(app, event)
             app.Array_Fraction_Sen_Vaccinated(19) = app.FractionSeniorCitizensEditField_19.Value;
            
        end

        % Value changed function: FractionAdultsEditField_20
        function FractionAdultsEditField_20ValueChanged(app, event)
             app.Array_Fraction_Adu_Vaccinated(20) = app.FractionAdultsEditField_20.Value;
            
        end

        % Value changed function: FractionSeniorCitizensEditField_20
        function FractionSeniorCitizensEditField_20ValueChanged(app, event)
             app.Array_Fraction_Sen_Vaccinated(20) = app.FractionSeniorCitizensEditField_20.Value;
            
        end

        % Value changed function: FractionAdultsEditField_21
        function FractionAdultsEditField_21ValueChanged(app, event)
             app.Array_Fraction_Adu_Vaccinated(21) = app.FractionAdultsEditField_21.Value;
            
        end

        % Value changed function: FractionSeniorCitizensEditField_21
        function FractionSeniorCitizensEditField_21ValueChanged(app, event)
             app.Array_Fraction_Sen_Vaccinated(21) = app.FractionSeniorCitizensEditField_21.Value;
            
        end

        % Value changed function: FractionAdultsEditField_22
        function FractionAdultsEditField_22ValueChanged(app, event)
             app.Array_Fraction_Adu_Vaccinated(22) = app.FractionAdultsEditField_22.Value;
            
        end

        % Value changed function: FractionSeniorCitizensEditField_22
        function FractionSeniorCitizensEditField_22ValueChanged(app, event)
             app.Array_Fraction_Sen_Vaccinated(22) = app.FractionSeniorCitizensEditField_22.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CTMUIFigure = uifigure('Visible', 'off');
            app.CTMUIFigure.Name = 'CTM';
            app.CTMUIFigure.CloseRequestFcn = createCallbackFcn(app, @CTMUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.CTMUIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SampleFilesMenu = uimenu(app.FileMenu);
            app.SampleFilesMenu.Text = 'Sample Files';
            app.RegionInfoMenu = uimenu(app.SampleFilesMenu);
            app.RegionInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @RegionInfoMenuSelected2, true);
            app.RegionInfoMenu.Text = 'Region Info';
            app.RealDataMenu = uimenu(app.SampleFilesMenu);
            app.RealDataMenu.MenuSelectedFcn = createCallbackFcn(app, @RealDataMenuSelected2, true);
            app.RealDataMenu.Text = 'Real Data';
            app.ParametersMenu = uimenu(app.SampleFilesMenu);
            app.ParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ParametersMenuSelected2, true);
            app.ParametersMenu.Text = 'Parameters';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'E&xit';
            app.HelpMenu = uimenu(app.CTMUIFigure);
            app.HelpMenu.Text = 'Help';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.MenuSelectedFcn = createCallbackFcn(app, @HelpMenu_2Selected, true);
            app.HelpMenu_2.Text = 'Help';
            app.AboutCTMMenu = uimenu(app.HelpMenu);
            app.AboutCTMMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutCTMMenuSelected, true);
            app.AboutCTMMenu.Text = 'About CTM';
            app.RunButton = uibutton(app.CTMUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RegionDropDown = uidropdown(app.CTMUIFigure);
            app.RegionDropDown.Items = {'India', 'Custom'};
            app.RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionDropDownValueChanged, true);
            app.RegionDropDown.Value = 'India';
            app.RegionalInformationPanel = uipanel(app.CTMUIFigure);
            app.RegionalInformationPanel.Title = 'Regional Information';
            app.millionsEditFieldLabel = uilabel(app.RegionalInformationPanel);
            app.millionsEditFieldLabel.Text = '(millions)';
            app.millionsEditField = uieditfield(app.RegionalInformationPanel, 'numeric');
            app.millionsEditField.Limits = [1e-06 10000];
            app.millionsEditField.ValueDisplayFormat = '%11.6g';
            app.millionsEditField.ValueChangedFcn = createCallbackFcn(app, @millionsEditFieldValueChanged, true);
            app.millionsEditField.Editable = 'off';
            app.millionsEditField.Value = 137.5;
            app.Below10EditField = uieditfield(app.RegionalInformationPanel, 'numeric');
            app.Below10EditField.Limits = [0 1];
            app.Below10EditField.Enable = 'off';
            app.Below10EditField.Value = 0.3;
            app.Between10and60EditField = uieditfield(app.RegionalInformationPanel, 'numeric');
            app.Between10and60EditField.Limits = [0 1];
            app.Between10and60EditField.ValueChangedFcn = createCallbackFcn(app, @Between10and60EditFieldValueChanged, true);
            app.Between10and60EditField.Value = 0.3;
            app.Above60EditField = uieditfield(app.RegionalInformationPanel, 'numeric');
            app.Above60EditField.Limits = [0 1];
            app.Above60EditField.ValueChangedFcn = createCallbackFcn(app, @Above60EditFieldValueChanged, true);
            app.Above60EditField.Value = 0.3;
            app.AgewiseDistributionLabel = uilabel(app.RegionalInformationPanel);
            app.AgewiseDistributionLabel.Text = 'Age-wise Distribution';
            app.Image3 = uiimage(app.RegionalInformationPanel);
            app.Image3.ImageSource = 'population.png';
            app.Image5 = uiimage(app.RegionalInformationPanel);
            app.Image5.ImageSource = 'MiddleAgeIcon.jpg';
            app.Image6 = uiimage(app.RegionalInformationPanel);
            app.Image6.ImageSource = 'SeniorCitizenIcon.jpg';
            app.Image4 = uiimage(app.RegionalInformationPanel);
            app.Image4.ImageSource = 'ChildrenIcon.jpg';
            app.Information = uitabgroup(app.CTMUIFigure);
            app.InfectionRelatedInformationTab = uitab(app.Information);
            app.InfectionRelatedInformationTab.Title = 'Infection Related Information';
            app.FractioninfectedshowingsymptomsEditFieldLabel = uilabel(app.InfectionRelatedInformationTab);
            app.FractioninfectedshowingsymptomsEditFieldLabel.Text = 'Fraction infected showing symptoms';
            app.FractioninfectedshowingsymptomsEditField = uieditfield(app.InfectionRelatedInformationTab, 'numeric');
            app.FractioninfectedshowingsymptomsEditField.Limits = [0 1];
            app.FractioninfectedshowingsymptomsEditField.ValueChangedFcn = createCallbackFcn(app, @FractioninfectedshowingsymptomsEditFieldValueChanged, true);
            app.FractioninfectedshowingsymptomsEditField.Value = 0.5;
            app.HowlongwillittaketoshowsymptomsdaysEditFieldLabel = uilabel(app.InfectionRelatedInformationTab);
            app.HowlongwillittaketoshowsymptomsdaysEditFieldLabel.Text = 'How long will it take to show symptoms? (days)';
            app.HowlongwillittaketoshowsymptomsdaysEditField = uieditfield(app.InfectionRelatedInformationTab, 'numeric');
            app.HowlongwillittaketoshowsymptomsdaysEditField.Limits = [0.1 10];
            app.HowlongwillittaketoshowsymptomsdaysEditField.ValueChangedFcn = createCallbackFcn(app, @HowlongwillittaketoshowsymptomsdaysEditFieldValueChanged, true);
            app.HowlongwillittaketoshowsymptomsdaysEditField.Value = 5;
            app.HowlongwillittaketorecoverdaysEditFieldLabel = uilabel(app.InfectionRelatedInformationTab);
            app.HowlongwillittaketorecoverdaysEditFieldLabel.Text = 'How long will it take to recover? (days)';
            app.HowlongwillittaketorecoverdaysEditField = uieditfield(app.InfectionRelatedInformationTab, 'numeric');
            app.HowlongwillittaketorecoverdaysEditField.Limits = [0.1 30];
            app.HowlongwillittaketorecoverdaysEditField.ValueChangedFcn = createCallbackFcn(app, @HowlongwillittaketorecoverdaysEditFieldValueChanged, true);
            app.HowlongwillittaketorecoverdaysEditField.Value = 14;
            app.HowlongwillimmunitylastdaysEditFieldLabel = uilabel(app.InfectionRelatedInformationTab);
            app.HowlongwillimmunitylastdaysEditFieldLabel.Text = 'How long will immunity last? (days)';
            app.HowlongwillimmunitylastdaysEditField = uieditfield(app.InfectionRelatedInformationTab, 'numeric');
            app.HowlongwillimmunitylastdaysEditField.Limits = [0.1 10000];
            app.HowlongwillimmunitylastdaysEditField.ValueChangedFcn = createCallbackFcn(app, @HowlongwillimmunitylastdaysEditFieldValueChanged, true);
            app.HowlongwillimmunitylastdaysEditField.Value = 90;
            app.TabGroup3 = uitabgroup(app.InfectionRelatedInformationTab);
            app.Below10Tab = uitab(app.TabGroup3);
            app.Below10Tab.Title = 'Below 10';
            app.SymptomaticTreatedEditFieldLabel = uilabel(app.Below10Tab);
            app.SymptomaticTreatedEditFieldLabel.Text = 'Symptomatic, Treated';
            app.SymptomaticTreatedEditField = uieditfield(app.Below10Tab, 'numeric');
            app.SymptomaticTreatedEditField.Limits = [0 100];
            app.SymptomaticTreatedEditField.ValueChangedFcn = createCallbackFcn(app, @SymptomaticTreatedEditFieldValueChanged, true);
            app.SymptomaticTreatedEditField.Value = 0.43;
            app.AsymptomaticTreatedEditFieldLabel = uilabel(app.Below10Tab);
            app.AsymptomaticTreatedEditFieldLabel.Text = 'Asymptomatic, Treated';
            app.AsymptomaticTreatedEditField = uieditfield(app.Below10Tab, 'numeric');
            app.AsymptomaticTreatedEditField.Limits = [0 100];
            app.AsymptomaticTreatedEditField.ValueChangedFcn = createCallbackFcn(app, @AsymptomaticTreatedEditFieldValueChanged, true);
            app.AsymptomaticTreatedEditField.Value = 0.43;
            app.AsymptomaticUntreatedEditFieldLabel = uilabel(app.Below10Tab);
            app.AsymptomaticUntreatedEditFieldLabel.Text = 'Asymptomatic, Untreated';
            app.AsymptomaticUntreatedEditField = uieditfield(app.Below10Tab, 'numeric');
            app.AsymptomaticUntreatedEditField.Limits = [0 100];
            app.AsymptomaticUntreatedEditField.ValueChangedFcn = createCallbackFcn(app, @AsymptomaticUntreatedEditFieldValueChanged, true);
            app.AsymptomaticUntreatedEditField.Value = 0.43;
            app.SymptomaticUntreatedEditFieldLabel = uilabel(app.Below10Tab);
            app.SymptomaticUntreatedEditFieldLabel.Text = 'Symptomatic, Untreated';
            app.SymptomaticUntreatedEditField = uieditfield(app.Below10Tab, 'numeric');
            app.SymptomaticUntreatedEditField.ValueChangedFcn = createCallbackFcn(app, @SymptomaticUntreatedEditFieldValueChanged, true);
            app.SymptomaticUntreatedEditField.Value = 0.43;
            app.Image4_3 = uiimage(app.Below10Tab);
            app.Image4_3.ImageSource = 'ChildrenIcon.jpg';
            app.Between10and60Tab = uitab(app.TabGroup3);
            app.Between10and60Tab.Title = 'Between 10 and 60';
            app.Image5_2 = uiimage(app.Between10and60Tab);
            app.Image5_2.ImageSource = 'MiddleAgeIcon.jpg';
            app.SymptomaticTreatedEditField_2Label = uilabel(app.Between10and60Tab);
            app.SymptomaticTreatedEditField_2Label.Text = 'Symptomatic, Treated';
            app.SymptomaticTreatedEditField_2 = uieditfield(app.Between10and60Tab, 'numeric');
            app.SymptomaticTreatedEditField_2.Limits = [0 100];
            app.SymptomaticTreatedEditField_2.ValueChangedFcn = createCallbackFcn(app, @SymptomaticTreatedEditField_2ValueChanged, true);
            app.SymptomaticTreatedEditField_2.Value = 0.43;
            app.AsymptomaticTreatedEditField_2Label = uilabel(app.Between10and60Tab);
            app.AsymptomaticTreatedEditField_2Label.Text = 'Asymptomatic, Treated';
            app.AsymptomaticTreatedEditField_2 = uieditfield(app.Between10and60Tab, 'numeric');
            app.AsymptomaticTreatedEditField_2.Limits = [0 100];
            app.AsymptomaticTreatedEditField_2.ValueChangedFcn = createCallbackFcn(app, @AsymptomaticTreatedEditField_2ValueChanged, true);
            app.AsymptomaticTreatedEditField_2.Value = 0.43;
            app.AsymptomaticUntreatedEditField_2Label = uilabel(app.Between10and60Tab);
            app.AsymptomaticUntreatedEditField_2Label.Text = 'Asymptomatic, Untreated';
            app.AsymptomaticUntreatedEditField_2 = uieditfield(app.Between10and60Tab, 'numeric');
            app.AsymptomaticUntreatedEditField_2.Limits = [0 100];
            app.AsymptomaticUntreatedEditField_2.ValueChangedFcn = createCallbackFcn(app, @AsymptomaticUntreatedEditField_2ValueChanged, true);
            app.AsymptomaticUntreatedEditField_2.Value = 0.43;
            app.SymptomaticUntreatedEditField_2Label = uilabel(app.Between10and60Tab);
            app.SymptomaticUntreatedEditField_2Label.Text = 'Symptomatic, Untreated';
            app.SymptomaticUntreatedEditField_2 = uieditfield(app.Between10and60Tab, 'numeric');
            app.SymptomaticUntreatedEditField_2.ValueChangedFcn = createCallbackFcn(app, @SymptomaticUntreatedEditField_2ValueChanged, true);
            app.SymptomaticUntreatedEditField_2.Value = 0.43;
            app.Above60Tab = uitab(app.TabGroup3);
            app.Above60Tab.Title = 'Above 60';
            app.Image6_2 = uiimage(app.Above60Tab);
            app.Image6_2.ImageSource = 'SeniorCitizenIcon.jpg';
            app.SymptomaticTreatedEditField_3Label = uilabel(app.Above60Tab);
            app.SymptomaticTreatedEditField_3Label.Text = 'Symptomatic, Treated';
            app.SymptomaticTreatedEditField_3 = uieditfield(app.Above60Tab, 'numeric');
            app.SymptomaticTreatedEditField_3.Limits = [0 100];
            app.SymptomaticTreatedEditField_3.ValueChangedFcn = createCallbackFcn(app, @SymptomaticTreatedEditField_3ValueChanged, true);
            app.SymptomaticTreatedEditField_3.Value = 0.43;
            app.AsymptomaticTreatedEditField_3Label = uilabel(app.Above60Tab);
            app.AsymptomaticTreatedEditField_3Label.Text = 'Asymptomatic, Treated';
            app.AsymptomaticTreatedEditField_3 = uieditfield(app.Above60Tab, 'numeric');
            app.AsymptomaticTreatedEditField_3.Limits = [0 100];
            app.AsymptomaticTreatedEditField_3.ValueChangedFcn = createCallbackFcn(app, @AsymptomaticTreatedEditField_3ValueChanged, true);
            app.AsymptomaticTreatedEditField_3.Value = 0.43;
            app.AsymptomaticUntreatedEditField_3Label = uilabel(app.Above60Tab);
            app.AsymptomaticUntreatedEditField_3Label.Text = 'Asymptomatic, Untreated';
            app.AsymptomaticUntreatedEditField_3 = uieditfield(app.Above60Tab, 'numeric');
            app.AsymptomaticUntreatedEditField_3.Limits = [0 100];
            app.AsymptomaticUntreatedEditField_3.ValueChangedFcn = createCallbackFcn(app, @AsymptomaticUntreatedEditField_3ValueChanged, true);
            app.AsymptomaticUntreatedEditField_3.Value = 0.43;
            app.SymptomaticUntreatedEditField_3Label = uilabel(app.Above60Tab);
            app.SymptomaticUntreatedEditField_3Label.Text = 'Symptomatic, Untreated';
            app.SymptomaticUntreatedEditField_3 = uieditfield(app.Above60Tab, 'numeric');
            app.SymptomaticUntreatedEditField_3.ValueChangedFcn = createCallbackFcn(app, @SymptomaticUntreatedEditField_3ValueChanged, true);
            app.SymptomaticUntreatedEditField_3.Value = 0.43;
            app.DeathRateLabel = uilabel(app.InfectionRelatedInformationTab);
            app.DeathRateLabel.Text = 'Death Rate (%)';
            app.HowlongwillvaccineimmunitylastdaysEditFieldLabel = uilabel(app.InfectionRelatedInformationTab);
            app.HowlongwillvaccineimmunitylastdaysEditFieldLabel.Text = 'How long will vaccine immunity last? (days)';
            app.HowlongwillvaccineimmunitylastdaysEditField = uieditfield(app.InfectionRelatedInformationTab, 'numeric');
            app.HowlongwillvaccineimmunitylastdaysEditField.Limits = [90 3650];
            app.HowlongwillvaccineimmunitylastdaysEditField.RoundFractionalValues = 'on';
            app.HowlongwillvaccineimmunitylastdaysEditField.ValueDisplayFormat = '%.0f';
            app.HowlongwillvaccineimmunitylastdaysEditField.ValueChangedFcn = createCallbackFcn(app, @HowlongwillvaccineimmunitylastdaysEditFieldValueChanged, true);
            app.HowlongwillvaccineimmunitylastdaysEditField.Value = 750;
            app.DataTab = uitab(app.Information);
            app.DataTab.Title = 'Data';
            app.LoadDataFromxlsxEditFieldLabel = uilabel(app.DataTab);
            app.LoadDataFromxlsxEditFieldLabel.Enable = 'off';
            app.LoadDataFromxlsxEditFieldLabel.Text = 'Load Data From (*.xlsx)';
            app.LoadDataFromxlsxEditField = uieditfield(app.DataTab, 'text');
            app.LoadDataFromxlsxEditField.ValueChangedFcn = createCallbackFcn(app, @LoadDataFromxlsxEditFieldValueChanged, true);
            app.LoadDataFromxlsxEditField.Editable = 'off';
            app.LoadDataFromxlsxEditField.Enable = 'off';
            app.LoadDataFromxlsxEditField.Value = 'IndiaDataFormatted.xlsx';
            app.ShowButtonGroup = uibuttongroup(app.DataTab);
            app.ShowButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowButtonGroupSelectionChanged, true);
            app.ShowButtonGroup.Title = 'Show';
            app.ModelResultsOnlyButton = uiradiobutton(app.ShowButtonGroup);
            app.ModelResultsOnlyButton.Text = 'Model Results Only';
            app.ModelandActualDataButton = uiradiobutton(app.ShowButtonGroup);
            app.ModelandActualDataButton.Text = 'Model and Actual Data';
            app.ModelandActualDataButton.Value = true;
            app.DataBrowseButton = uibutton(app.DataTab, 'push');
            app.DataBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @DataBrowseButtonPushed, true);
            app.DataBrowseButton.Enable = 'off';
            app.DataBrowseButton.Text = 'Browse';
            app.StartingConditionsTab = uitab(app.Information);
            app.StartingConditionsTab.Title = 'Starting Conditions';
            app.exposedEditField_2Label = uilabel(app.StartingConditionsTab);
            app.exposedEditField_2Label.Text = '# exposed';
            app.exposedEditField_2 = uieditfield(app.StartingConditionsTab, 'numeric');
            app.exposedEditField_2.Limits = [0 100000];
            app.exposedEditField_2.ValueChangedFcn = createCallbackFcn(app, @exposedEditField_2ValueChanged, true);
            app.exposedEditField_2.Value = 5000;
            app.infectedshowingsymptomquarantinedEditField_2Label = uilabel(app.StartingConditionsTab);
            app.infectedshowingsymptomquarantinedEditField_2Label.Text = '# infected showing symptom, quarantined';
            app.infectedshowingsymptomquarantinedEditField_2 = uieditfield(app.StartingConditionsTab, 'numeric');
            app.infectedshowingsymptomquarantinedEditField_2.Limits = [0 100000];
            app.infectedshowingsymptomquarantinedEditField_2.ValueChangedFcn = createCallbackFcn(app, @infectedshowingsymptomquarantinedEditField_2ValueChanged, true);
            app.infectedshowingsymptomquarantinedEditField_2.Value = 1000;
            app.infectedshowingsymptomnotquarantinedEditField_2Label = uilabel(app.StartingConditionsTab);
            app.infectedshowingsymptomnotquarantinedEditField_2Label.Text = '# infected showing symptom, not quarantined';
            app.infectedshowingsymptomnotquarantinedEditField_2 = uieditfield(app.StartingConditionsTab, 'numeric');
            app.infectedshowingsymptomnotquarantinedEditField_2.Limits = [0 100000];
            app.infectedshowingsymptomnotquarantinedEditField_2.ValueChangedFcn = createCallbackFcn(app, @infectedshowingsymptomnotquarantinedEditField_2ValueChanged, true);
            app.infectedshowingsymptomnotquarantinedEditField_2.Value = 100;
            app.infectednosymptomquarantinedEditField_2Label = uilabel(app.StartingConditionsTab);
            app.infectednosymptomquarantinedEditField_2Label.Text = '# infected no symptom, quarantined';
            app.infectednosymptomquarantinedEditField_2 = uieditfield(app.StartingConditionsTab, 'numeric');
            app.infectednosymptomquarantinedEditField_2.Limits = [0 100000];
            app.infectednosymptomquarantinedEditField_2.ValueChangedFcn = createCallbackFcn(app, @infectednosymptomquarantinedEditField_2ValueChanged, true);
            app.infectednosymptomquarantinedEditField_2.Value = 100;
            app.infectednosymptomnotquarantinedEditField_2Label = uilabel(app.StartingConditionsTab);
            app.infectednosymptomnotquarantinedEditField_2Label.Text = '# infected no symptom, not quarantined';
            app.infectednosymptomnotquarantinedEditField_2 = uieditfield(app.StartingConditionsTab, 'numeric');
            app.infectednosymptomnotquarantinedEditField_2.Limits = [0 10000];
            app.infectednosymptomnotquarantinedEditField_2.ValueChangedFcn = createCallbackFcn(app, @infectednosymptomnotquarantinedEditField_2ValueChanged, true);
            app.infectednosymptomnotquarantinedEditField_2.Value = 5000;
            app.showingsymptomrecoveredEditField_2Label = uilabel(app.StartingConditionsTab);
            app.showingsymptomrecoveredEditField_2Label.Text = '# showing symptom, recovered';
            app.showingsymptomrecoveredEditField_2 = uieditfield(app.StartingConditionsTab, 'numeric');
            app.showingsymptomrecoveredEditField_2.Limits = [0 100000];
            app.showingsymptomrecoveredEditField_2.ValueChangedFcn = createCallbackFcn(app, @showingsymptomrecoveredEditField_2ValueChanged, true);
            app.showingsymptomrecoveredEditField_2.Value = 1000;
            app.showingnosymptomrecoveredEditField_2Label = uilabel(app.StartingConditionsTab);
            app.showingnosymptomrecoveredEditField_2Label.Text = '# showing no symptom, recovered';
            app.showingnosymptomrecoveredEditField_2 = uieditfield(app.StartingConditionsTab, 'numeric');
            app.showingnosymptomrecoveredEditField_2.Limits = [0 100000];
            app.showingnosymptomrecoveredEditField_2.ValueChangedFcn = createCallbackFcn(app, @showingnosymptomrecoveredEditField_2ValueChanged, true);
            app.showingnosymptomrecoveredEditField_2.Value = 1000;
            app.showingsymptomdeadEditField_2Label = uilabel(app.StartingConditionsTab);
            app.showingsymptomdeadEditField_2Label.Text = '# showing symptom, dead';
            app.showingsymptomdeadEditField_2 = uieditfield(app.StartingConditionsTab, 'numeric');
            app.showingsymptomdeadEditField_2.Limits = [0 100000];
            app.showingsymptomdeadEditField_2.ValueChangedFcn = createCallbackFcn(app, @showingsymptomdeadEditField_2ValueChanged, true);
            app.showingnosymptomdeadEditField_2Label = uilabel(app.StartingConditionsTab);
            app.showingnosymptomdeadEditField_2Label.Text = '# showing no symptom, dead';
            app.showingnosymptomdeadEditField_2 = uieditfield(app.StartingConditionsTab, 'numeric');
            app.showingnosymptomdeadEditField_2.Limits = [0 100000];
            app.showingnosymptomdeadEditField_2.ValueChangedFcn = createCallbackFcn(app, @showingnosymptomdeadEditField_2ValueChanged, true);
            app.VaccinatedEditFieldLabel = uilabel(app.StartingConditionsTab);
            app.VaccinatedEditFieldLabel.Text = '# Vaccinated';
            app.VaccinatedEditField = uieditfield(app.StartingConditionsTab, 'numeric');
            app.VaccinatedEditField.Limits = [0 100000];
            app.SpreadingVaccinationDetailsTab = uitab(app.Information);
            app.SpreadingVaccinationDetailsTab.Title = 'Spreading & Vaccination Details';
            app.TabGroup4_1 = uitabgroup(app.SpreadingVaccinationDetailsTab);
            app.Mar20Tab_1 = uitab(app.TabGroup4_1);
            app.Mar20Tab_1.Title = 'Mar 20';
            app.DuringthismonthPanel_01 = uipanel(app.Mar20Tab_1);
            app.DuringthismonthPanel_01.Title = 'During this month';
            app.BasicreproductionnumberLabel_01 = uilabel(app.DuringthismonthPanel_01);
            app.BasicreproductionnumberLabel_01.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_21Label = uilabel(app.DuringthismonthPanel_01);
            app.R0SymptomaticEditField_21Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_01 = uieditfield(app.DuringthismonthPanel_01, 'numeric');
            app.R0SymptomaticEditField_01.Limits = [0 1000];
            app.R0SymptomaticEditField_01.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_01ValueChanged, true);
            app.R0SymptomaticEditField_01.Value = 2.2;
            app.R0AsymptomaticEditField_20Label = uilabel(app.DuringthismonthPanel_01);
            app.R0AsymptomaticEditField_20Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_01 = uieditfield(app.DuringthismonthPanel_01, 'numeric');
            app.R0AsymptomaticEditField_01.Limits = [0 1000];
            app.R0AsymptomaticEditField_01.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_01ValueChanged, true);
            app.R0AsymptomaticEditField_01.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedLabel = uilabel(app.DuringthismonthPanel_01);
            app.FractionofsymptomaticpeopletreatedLabel.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_01 = uieditfield(app.DuringthismonthPanel_01, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_01.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_01.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_01ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_01.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_23Label = uilabel(app.DuringthismonthPanel_01);
            app.FractionofAsymptomaticpeopletreatedEditField_23Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_01 = uieditfield(app.DuringthismonthPanel_01, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_01.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_01.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_01ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_01.Value = 0.01;
            app.Apr20Tab_1 = uitab(app.TabGroup4_1);
            app.Apr20Tab_1.Title = 'Apr 20';
            app.DuringthismonthPanel_02 = uipanel(app.Apr20Tab_1);
            app.DuringthismonthPanel_02.Title = 'During this month';
            app.BasicreproductionnumberLabel_02 = uilabel(app.DuringthismonthPanel_02);
            app.BasicreproductionnumberLabel_02.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_22Label = uilabel(app.DuringthismonthPanel_02);
            app.R0SymptomaticEditField_22Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_02 = uieditfield(app.DuringthismonthPanel_02, 'numeric');
            app.R0SymptomaticEditField_02.Limits = [0 1000];
            app.R0SymptomaticEditField_02.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_02ValueChanged, true);
            app.R0SymptomaticEditField_02.Value = 2.2;
            app.R0AsymptomaticEditField_21Label = uilabel(app.DuringthismonthPanel_02);
            app.R0AsymptomaticEditField_21Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_02 = uieditfield(app.DuringthismonthPanel_02, 'numeric');
            app.R0AsymptomaticEditField_02.Limits = [0 1000];
            app.R0AsymptomaticEditField_02.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_02ValueChanged, true);
            app.R0AsymptomaticEditField_02.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditFieldLabel = uilabel(app.DuringthismonthPanel_02);
            app.FractionofsymptomaticpeopletreatedEditFieldLabel.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_02 = uieditfield(app.DuringthismonthPanel_02, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_02.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_02.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_02ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_02.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditFieldLabel = uilabel(app.DuringthismonthPanel_02);
            app.FractionofAsymptomaticpeopletreatedEditFieldLabel.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_02 = uieditfield(app.DuringthismonthPanel_02, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_02.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_02.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_02ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_02.Value = 0.01;
            app.May20Tab_1 = uitab(app.TabGroup4_1);
            app.May20Tab_1.Title = 'May 20';
            app.DuringthismonthPanel_03 = uipanel(app.May20Tab_1);
            app.DuringthismonthPanel_03.Title = 'During this month';
            app.BasicreproductionnumberLabel_03 = uilabel(app.DuringthismonthPanel_03);
            app.BasicreproductionnumberLabel_03.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_23Label = uilabel(app.DuringthismonthPanel_03);
            app.R0SymptomaticEditField_23Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_03 = uieditfield(app.DuringthismonthPanel_03, 'numeric');
            app.R0SymptomaticEditField_03.Limits = [0 1000];
            app.R0SymptomaticEditField_03.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_03ValueChanged, true);
            app.R0SymptomaticEditField_03.Value = 2.2;
            app.R0AsymptomaticEditField_22Label = uilabel(app.DuringthismonthPanel_03);
            app.R0AsymptomaticEditField_22Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_03 = uieditfield(app.DuringthismonthPanel_03, 'numeric');
            app.R0AsymptomaticEditField_03.Limits = [0 1000];
            app.R0AsymptomaticEditField_03.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_03ValueChanged, true);
            app.R0AsymptomaticEditField_03.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_2Label = uilabel(app.DuringthismonthPanel_03);
            app.FractionofsymptomaticpeopletreatedEditField_2Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_03 = uieditfield(app.DuringthismonthPanel_03, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_03.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_03.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_03ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_03.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_2Label = uilabel(app.DuringthismonthPanel_03);
            app.FractionofAsymptomaticpeopletreatedEditField_2Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_03 = uieditfield(app.DuringthismonthPanel_03, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_03.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_03.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_03ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_03.Value = 0.01;
            app.Jun20Tab = uitab(app.TabGroup4_1);
            app.Jun20Tab.Title = 'Jun 20';
            app.DuringthismonthPanel_04 = uipanel(app.Jun20Tab);
            app.DuringthismonthPanel_04.Title = 'During this month';
            app.BasicreproductionnumberLabel_04 = uilabel(app.DuringthismonthPanel_04);
            app.BasicreproductionnumberLabel_04.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_4Label = uilabel(app.DuringthismonthPanel_04);
            app.R0SymptomaticEditField_4Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_04 = uieditfield(app.DuringthismonthPanel_04, 'numeric');
            app.R0SymptomaticEditField_04.Limits = [0 1000];
            app.R0SymptomaticEditField_04.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_04ValueChanged, true);
            app.R0SymptomaticEditField_04.Value = 2.2;
            app.R0AsymptomaticEditField_4Label = uilabel(app.DuringthismonthPanel_04);
            app.R0AsymptomaticEditField_4Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_04 = uieditfield(app.DuringthismonthPanel_04, 'numeric');
            app.R0AsymptomaticEditField_04.Limits = [0 1000];
            app.R0AsymptomaticEditField_04.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_04ValueChanged, true);
            app.R0AsymptomaticEditField_04.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_3Label = uilabel(app.DuringthismonthPanel_04);
            app.FractionofsymptomaticpeopletreatedEditField_3Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_04 = uieditfield(app.DuringthismonthPanel_04, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_04.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_04.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_04ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_04.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_3Label = uilabel(app.DuringthismonthPanel_04);
            app.FractionofAsymptomaticpeopletreatedEditField_3Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_04 = uieditfield(app.DuringthismonthPanel_04, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_04.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_04.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_04ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_04.Value = 0.01;
            app.Jul20Tab = uitab(app.TabGroup4_1);
            app.Jul20Tab.Title = 'Jul 20';
            app.DuringthismonthPanel_5 = uipanel(app.Jul20Tab);
            app.DuringthismonthPanel_5.Title = 'During this month';
            app.BasicreproductionnumberLabel_5 = uilabel(app.DuringthismonthPanel_5);
            app.BasicreproductionnumberLabel_5.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_5Label = uilabel(app.DuringthismonthPanel_5);
            app.R0SymptomaticEditField_5Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_05 = uieditfield(app.DuringthismonthPanel_5, 'numeric');
            app.R0SymptomaticEditField_05.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_05ValueChanged, true);
            app.R0SymptomaticEditField_05.Value = 0.2;
            app.R0AsymptomaticEditField_5Label = uilabel(app.DuringthismonthPanel_5);
            app.R0AsymptomaticEditField_5Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_05 = uieditfield(app.DuringthismonthPanel_5, 'numeric');
            app.R0AsymptomaticEditField_05.Limits = [0 1000];
            app.R0AsymptomaticEditField_05.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_05ValueChanged2, true);
            app.R0AsymptomaticEditField_05.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_4Label = uilabel(app.DuringthismonthPanel_5);
            app.FractionofsymptomaticpeopletreatedEditField_4Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_05 = uieditfield(app.DuringthismonthPanel_5, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_05.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_05.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_05ValueChanged2, true);
            app.FractionofsymptomaticpeopletreatedEditField_05.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_4Label = uilabel(app.DuringthismonthPanel_5);
            app.FractionofAsymptomaticpeopletreatedEditField_4Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_05 = uieditfield(app.DuringthismonthPanel_5, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_05.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_05.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_05ValueChanged2, true);
            app.FractionofAsymptomaticpeopletreatedEditField_05.Value = 0.01;
            app.Aug20Tab = uitab(app.TabGroup4_1);
            app.Aug20Tab.Title = 'Aug 20';
            app.DuringthismonthPanel_6 = uipanel(app.Aug20Tab);
            app.DuringthismonthPanel_6.Title = 'During this month';
            app.BasicreproductionnumberLabel_6 = uilabel(app.DuringthismonthPanel_6);
            app.BasicreproductionnumberLabel_6.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_6Label = uilabel(app.DuringthismonthPanel_6);
            app.R0SymptomaticEditField_6Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_06 = uieditfield(app.DuringthismonthPanel_6, 'numeric');
            app.R0SymptomaticEditField_06.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_06ValueChanged2, true);
            app.R0SymptomaticEditField_06.Value = 0.2;
            app.R0AsymptomaticEditField_6Label = uilabel(app.DuringthismonthPanel_6);
            app.R0AsymptomaticEditField_6Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_06 = uieditfield(app.DuringthismonthPanel_6, 'numeric');
            app.R0AsymptomaticEditField_06.Limits = [0 1000];
            app.R0AsymptomaticEditField_06.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_06ValueChanged2, true);
            app.R0AsymptomaticEditField_06.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_5Label = uilabel(app.DuringthismonthPanel_6);
            app.FractionofsymptomaticpeopletreatedEditField_5Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_06 = uieditfield(app.DuringthismonthPanel_6, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_06.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_06.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_06ValueChanged2, true);
            app.FractionofsymptomaticpeopletreatedEditField_06.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_5Label = uilabel(app.DuringthismonthPanel_6);
            app.FractionofAsymptomaticpeopletreatedEditField_5Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_06 = uieditfield(app.DuringthismonthPanel_6, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_06.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_06.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_06ValueChanged2, true);
            app.FractionofAsymptomaticpeopletreatedEditField_06.Value = 0.01;
            app.Sep20Tab = uitab(app.TabGroup4_1);
            app.Sep20Tab.Title = 'Sep 20';
            app.DuringthismonthPanel_7 = uipanel(app.Sep20Tab);
            app.DuringthismonthPanel_7.Title = 'During this month';
            app.BasicreproductionnumberLabel_7 = uilabel(app.DuringthismonthPanel_7);
            app.BasicreproductionnumberLabel_7.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_7Label = uilabel(app.DuringthismonthPanel_7);
            app.R0SymptomaticEditField_7Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_07 = uieditfield(app.DuringthismonthPanel_7, 'numeric');
            app.R0SymptomaticEditField_07.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_07ValueChanged2, true);
            app.R0SymptomaticEditField_07.Value = 0.2;
            app.R0AsymptomaticEditField_7Label = uilabel(app.DuringthismonthPanel_7);
            app.R0AsymptomaticEditField_7Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_07 = uieditfield(app.DuringthismonthPanel_7, 'numeric');
            app.R0AsymptomaticEditField_07.Limits = [0 1000];
            app.R0AsymptomaticEditField_07.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_07ValueChanged, true);
            app.R0AsymptomaticEditField_07.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_6Label = uilabel(app.DuringthismonthPanel_7);
            app.FractionofsymptomaticpeopletreatedEditField_6Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_07 = uieditfield(app.DuringthismonthPanel_7, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_07.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_07.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_07ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_07.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_6Label = uilabel(app.DuringthismonthPanel_7);
            app.FractionofAsymptomaticpeopletreatedEditField_6Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_07 = uieditfield(app.DuringthismonthPanel_7, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_07.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_07.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_07ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_07.Value = 0.01;
            app.Oct20Tab = uitab(app.TabGroup4_1);
            app.Oct20Tab.Title = 'Oct 20';
            app.DuringthismonthPanel_8 = uipanel(app.Oct20Tab);
            app.DuringthismonthPanel_8.Title = 'During this month';
            app.BasicreproductionnumberLabel_8 = uilabel(app.DuringthismonthPanel_8);
            app.BasicreproductionnumberLabel_8.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_8Label = uilabel(app.DuringthismonthPanel_8);
            app.R0SymptomaticEditField_8Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_08 = uieditfield(app.DuringthismonthPanel_8, 'numeric');
            app.R0SymptomaticEditField_08.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_08ValueChanged, true);
            app.R0SymptomaticEditField_08.Value = 0.2;
            app.R0AsymptomaticEditField_8Label = uilabel(app.DuringthismonthPanel_8);
            app.R0AsymptomaticEditField_8Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_08 = uieditfield(app.DuringthismonthPanel_8, 'numeric');
            app.R0AsymptomaticEditField_08.Limits = [0 1000];
            app.R0AsymptomaticEditField_08.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_08ValueChanged, true);
            app.R0AsymptomaticEditField_08.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_7Label = uilabel(app.DuringthismonthPanel_8);
            app.FractionofsymptomaticpeopletreatedEditField_7Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_08 = uieditfield(app.DuringthismonthPanel_8, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_08.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_08.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_08ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_08.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_7Label = uilabel(app.DuringthismonthPanel_8);
            app.FractionofAsymptomaticpeopletreatedEditField_7Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_08 = uieditfield(app.DuringthismonthPanel_8, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_08.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_08.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_08ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_08.Value = 0.01;
            app.Nov20Tab = uitab(app.TabGroup4_1);
            app.Nov20Tab.Title = 'Nov 20';
            app.DuringthismonthPanel_9 = uipanel(app.Nov20Tab);
            app.DuringthismonthPanel_9.Title = 'During this month';
            app.BasicreproductionnumberLabel_9 = uilabel(app.DuringthismonthPanel_9);
            app.BasicreproductionnumberLabel_9.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_9Label = uilabel(app.DuringthismonthPanel_9);
            app.R0SymptomaticEditField_9Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_09 = uieditfield(app.DuringthismonthPanel_9, 'numeric');
            app.R0SymptomaticEditField_09.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_09ValueChanged, true);
            app.R0SymptomaticEditField_09.Value = 0.2;
            app.R0AsymptomaticEditField_9Label = uilabel(app.DuringthismonthPanel_9);
            app.R0AsymptomaticEditField_9Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_09 = uieditfield(app.DuringthismonthPanel_9, 'numeric');
            app.R0AsymptomaticEditField_09.Limits = [0 1000];
            app.R0AsymptomaticEditField_09.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_09ValueChanged, true);
            app.R0AsymptomaticEditField_09.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_8Label = uilabel(app.DuringthismonthPanel_9);
            app.FractionofsymptomaticpeopletreatedEditField_8Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_09 = uieditfield(app.DuringthismonthPanel_9, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_09.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_09.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_09ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_09.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_8Label = uilabel(app.DuringthismonthPanel_9);
            app.FractionofAsymptomaticpeopletreatedEditField_8Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_09 = uieditfield(app.DuringthismonthPanel_9, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_09.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_09.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_09ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_09.Value = 0.01;
            app.Dec20Tab = uitab(app.TabGroup4_1);
            app.Dec20Tab.Title = 'Dec 20';
            app.DuringthismonthPanel_10 = uipanel(app.Dec20Tab);
            app.DuringthismonthPanel_10.Title = 'During this month';
            app.BasicreproductionnumberLabel_10 = uilabel(app.DuringthismonthPanel_10);
            app.BasicreproductionnumberLabel_10.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_10Label = uilabel(app.DuringthismonthPanel_10);
            app.R0SymptomaticEditField_10Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_10 = uieditfield(app.DuringthismonthPanel_10, 'numeric');
            app.R0SymptomaticEditField_10.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_10ValueChanged, true);
            app.R0SymptomaticEditField_10.Value = 0.2;
            app.R0AsymptomaticEditField_10Label = uilabel(app.DuringthismonthPanel_10);
            app.R0AsymptomaticEditField_10Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_10 = uieditfield(app.DuringthismonthPanel_10, 'numeric');
            app.R0AsymptomaticEditField_10.Limits = [0 1000];
            app.R0AsymptomaticEditField_10.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_10ValueChanged, true);
            app.R0AsymptomaticEditField_10.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_9Label = uilabel(app.DuringthismonthPanel_10);
            app.FractionofsymptomaticpeopletreatedEditField_9Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_10 = uieditfield(app.DuringthismonthPanel_10, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_10.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_10.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_10ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_10.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_9Label = uilabel(app.DuringthismonthPanel_10);
            app.FractionofAsymptomaticpeopletreatedEditField_9Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_10 = uieditfield(app.DuringthismonthPanel_10, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_10.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_10.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_10ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_10.Value = 0.01;
            app.Jan21Tab = uitab(app.TabGroup4_1);
            app.Jan21Tab.Title = 'Jan 21';
            app.DuringthismonthPanel_11 = uipanel(app.Jan21Tab);
            app.DuringthismonthPanel_11.Title = 'During this month';
            app.BasicreproductionnumberLabel_11 = uilabel(app.DuringthismonthPanel_11);
            app.BasicreproductionnumberLabel_11.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_11Label = uilabel(app.DuringthismonthPanel_11);
            app.R0SymptomaticEditField_11Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_11 = uieditfield(app.DuringthismonthPanel_11, 'numeric');
            app.R0SymptomaticEditField_11.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_11ValueChanged, true);
            app.R0SymptomaticEditField_11.Value = 0.2;
            app.R0AsymptomaticEditField_11Label = uilabel(app.DuringthismonthPanel_11);
            app.R0AsymptomaticEditField_11Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_11 = uieditfield(app.DuringthismonthPanel_11, 'numeric');
            app.R0AsymptomaticEditField_11.Limits = [0 1000];
            app.R0AsymptomaticEditField_11.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_11ValueChanged, true);
            app.R0AsymptomaticEditField_11.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_10Label = uilabel(app.DuringthismonthPanel_11);
            app.FractionofsymptomaticpeopletreatedEditField_10Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_11 = uieditfield(app.DuringthismonthPanel_11, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_11.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_11.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_11ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_11.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_10Label = uilabel(app.DuringthismonthPanel_11);
            app.FractionofAsymptomaticpeopletreatedEditField_10Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_11 = uieditfield(app.DuringthismonthPanel_11, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_11.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_11.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_11ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_11.Value = 0.01;
            app.FractionVaccinatedLabel_11 = uilabel(app.DuringthismonthPanel_11);
            app.FractionVaccinatedLabel_11.Text = 'Fraction Vaccinated';
            app.FractionAdultsEditFieldLabel = uilabel(app.DuringthismonthPanel_11);
            app.FractionAdultsEditFieldLabel.Text = 'Fraction Adults';
            app.FractionAdultsEditField_11 = uieditfield(app.DuringthismonthPanel_11, 'numeric');
            app.FractionAdultsEditField_11.Limits = [0 1];
            app.FractionAdultsEditField_11.ValueChangedFcn = createCallbackFcn(app, @FractionAdultsEditField_11ValueChanged, true);
            app.FractionAdultsEditField_11.Value = 0.01;
            app.FractionSeniorCitizensEditFieldLabel = uilabel(app.DuringthismonthPanel_11);
            app.FractionSeniorCitizensEditFieldLabel.Text = 'Fraction Senior Citizens';
            app.FractionSeniorCitizensEditField_11 = uieditfield(app.DuringthismonthPanel_11, 'numeric');
            app.FractionSeniorCitizensEditField_11.Limits = [0 1];
            app.FractionSeniorCitizensEditField_11.ValueChangedFcn = createCallbackFcn(app, @FractionSeniorCitizensEditField_11ValueChanged, true);
            app.FractionSeniorCitizensEditField_11.Value = 0.01;
            app.Feb21Tab = uitab(app.TabGroup4_1);
            app.Feb21Tab.Title = 'Feb 21';
            app.DuringthismonthPanel_12 = uipanel(app.Feb21Tab);
            app.DuringthismonthPanel_12.Title = 'During this month';
            app.BasicreproductionnumberLabel_12 = uilabel(app.DuringthismonthPanel_12);
            app.BasicreproductionnumberLabel_12.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_12Label = uilabel(app.DuringthismonthPanel_12);
            app.R0SymptomaticEditField_12Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_12 = uieditfield(app.DuringthismonthPanel_12, 'numeric');
            app.R0SymptomaticEditField_12.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_12ValueChanged, true);
            app.R0SymptomaticEditField_12.Value = 0.2;
            app.R0AsymptomaticEditField_12Label = uilabel(app.DuringthismonthPanel_12);
            app.R0AsymptomaticEditField_12Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_12 = uieditfield(app.DuringthismonthPanel_12, 'numeric');
            app.R0AsymptomaticEditField_12.Limits = [0 1000];
            app.R0AsymptomaticEditField_12.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_12ValueChanged, true);
            app.R0AsymptomaticEditField_12.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_11Label = uilabel(app.DuringthismonthPanel_12);
            app.FractionofsymptomaticpeopletreatedEditField_11Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_12 = uieditfield(app.DuringthismonthPanel_12, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_12.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_12.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_12ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_12.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_11Label = uilabel(app.DuringthismonthPanel_12);
            app.FractionofAsymptomaticpeopletreatedEditField_11Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_12 = uieditfield(app.DuringthismonthPanel_12, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_12.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_12.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_12ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_12.Value = 0.01;
            app.FractionVaccinatedLabel_12 = uilabel(app.DuringthismonthPanel_12);
            app.FractionVaccinatedLabel_12.Text = 'Fraction Vaccinated';
            app.FractionAdultsEditField_12Label = uilabel(app.DuringthismonthPanel_12);
            app.FractionAdultsEditField_12Label.Text = 'Fraction Adults';
            app.FractionAdultsEditField_12 = uieditfield(app.DuringthismonthPanel_12, 'numeric');
            app.FractionAdultsEditField_12.Limits = [0 1];
            app.FractionAdultsEditField_12.ValueChangedFcn = createCallbackFcn(app, @FractionAdultsEditField_12ValueChanged, true);
            app.FractionAdultsEditField_12.Value = 0.01;
            app.FractionSeniorCitizensEditField_12Label = uilabel(app.DuringthismonthPanel_12);
            app.FractionSeniorCitizensEditField_12Label.Text = 'Fraction Senior Citizens';
            app.FractionSeniorCitizensEditField_12 = uieditfield(app.DuringthismonthPanel_12, 'numeric');
            app.FractionSeniorCitizensEditField_12.Limits = [0 1];
            app.FractionSeniorCitizensEditField_12.ValueChangedFcn = createCallbackFcn(app, @FractionSeniorCitizensEditField_12ValueChanged, true);
            app.FractionSeniorCitizensEditField_12.Value = 0.01;
            app.Mar21Tab = uitab(app.TabGroup4_1);
            app.Mar21Tab.Title = 'Mar 21';
            app.DuringthismonthPanel_13 = uipanel(app.Mar21Tab);
            app.DuringthismonthPanel_13.Title = 'During this month';
            app.BasicreproductionnumberLabel_13 = uilabel(app.DuringthismonthPanel_13);
            app.BasicreproductionnumberLabel_13.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_13Label = uilabel(app.DuringthismonthPanel_13);
            app.R0SymptomaticEditField_13Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_13 = uieditfield(app.DuringthismonthPanel_13, 'numeric');
            app.R0SymptomaticEditField_13.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_13ValueChanged, true);
            app.R0SymptomaticEditField_13.Value = 0.2;
            app.R0AsymptomaticEditField_13Label = uilabel(app.DuringthismonthPanel_13);
            app.R0AsymptomaticEditField_13Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_13 = uieditfield(app.DuringthismonthPanel_13, 'numeric');
            app.R0AsymptomaticEditField_13.Limits = [0 1000];
            app.R0AsymptomaticEditField_13.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_13ValueChanged, true);
            app.R0AsymptomaticEditField_13.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_12Label = uilabel(app.DuringthismonthPanel_13);
            app.FractionofsymptomaticpeopletreatedEditField_12Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_13 = uieditfield(app.DuringthismonthPanel_13, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_13.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_13.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_13ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_13.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_12Label = uilabel(app.DuringthismonthPanel_13);
            app.FractionofAsymptomaticpeopletreatedEditField_12Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_13 = uieditfield(app.DuringthismonthPanel_13, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_13.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_13.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_13ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_13.Value = 0.01;
            app.FractionVaccinatedLabel_13 = uilabel(app.DuringthismonthPanel_13);
            app.FractionVaccinatedLabel_13.Text = 'Fraction Vaccinated';
            app.FractionAdultsEditField_13Label = uilabel(app.DuringthismonthPanel_13);
            app.FractionAdultsEditField_13Label.Text = 'Fraction Adults';
            app.FractionAdultsEditField_13 = uieditfield(app.DuringthismonthPanel_13, 'numeric');
            app.FractionAdultsEditField_13.Limits = [0 1];
            app.FractionAdultsEditField_13.ValueChangedFcn = createCallbackFcn(app, @FractionAdultsEditField_13ValueChanged, true);
            app.FractionAdultsEditField_13.Value = 0.01;
            app.FractionSeniorCitizensEditField_13Label = uilabel(app.DuringthismonthPanel_13);
            app.FractionSeniorCitizensEditField_13Label.Text = 'Fraction Senior Citizens';
            app.FractionSeniorCitizensEditField_13 = uieditfield(app.DuringthismonthPanel_13, 'numeric');
            app.FractionSeniorCitizensEditField_13.Limits = [0 1];
            app.FractionSeniorCitizensEditField_13.ValueChangedFcn = createCallbackFcn(app, @FractionSeniorCitizensEditField_13ValueChanged, true);
            app.FractionSeniorCitizensEditField_13.Value = 0.01;
            app.Apr21Tab = uitab(app.TabGroup4_1);
            app.Apr21Tab.Title = 'Apr 21';
            app.DuringthismonthPanel_14 = uipanel(app.Apr21Tab);
            app.DuringthismonthPanel_14.Title = 'During this month';
            app.BasicreproductionnumberLabel_14 = uilabel(app.DuringthismonthPanel_14);
            app.BasicreproductionnumberLabel_14.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_14Label = uilabel(app.DuringthismonthPanel_14);
            app.R0SymptomaticEditField_14Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_14 = uieditfield(app.DuringthismonthPanel_14, 'numeric');
            app.R0SymptomaticEditField_14.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_14ValueChanged, true);
            app.R0SymptomaticEditField_14.Value = 0.2;
            app.R0AsymptomaticEditField_14Label = uilabel(app.DuringthismonthPanel_14);
            app.R0AsymptomaticEditField_14Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_14 = uieditfield(app.DuringthismonthPanel_14, 'numeric');
            app.R0AsymptomaticEditField_14.Limits = [0 1000];
            app.R0AsymptomaticEditField_14.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_14ValueChanged, true);
            app.R0AsymptomaticEditField_14.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_13Label = uilabel(app.DuringthismonthPanel_14);
            app.FractionofsymptomaticpeopletreatedEditField_13Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_14 = uieditfield(app.DuringthismonthPanel_14, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_14.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_14.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_14ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_14.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_13Label = uilabel(app.DuringthismonthPanel_14);
            app.FractionofAsymptomaticpeopletreatedEditField_13Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_14 = uieditfield(app.DuringthismonthPanel_14, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_14.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_14.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_14ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_14.Value = 0.01;
            app.FractionVaccinatedLabel_14 = uilabel(app.DuringthismonthPanel_14);
            app.FractionVaccinatedLabel_14.Text = 'Fraction Vaccinated';
            app.FractionAdultsEditField_14Label = uilabel(app.DuringthismonthPanel_14);
            app.FractionAdultsEditField_14Label.Text = 'Fraction Adults';
            app.FractionAdultsEditField_14 = uieditfield(app.DuringthismonthPanel_14, 'numeric');
            app.FractionAdultsEditField_14.Limits = [0 1];
            app.FractionAdultsEditField_14.ValueChangedFcn = createCallbackFcn(app, @FractionAdultsEditField_14ValueChanged, true);
            app.FractionAdultsEditField_14.Value = 0.01;
            app.FractionSeniorCitizensEditField_14Label = uilabel(app.DuringthismonthPanel_14);
            app.FractionSeniorCitizensEditField_14Label.Text = 'Fraction Senior Citizens';
            app.FractionSeniorCitizensEditField_14 = uieditfield(app.DuringthismonthPanel_14, 'numeric');
            app.FractionSeniorCitizensEditField_14.Limits = [0 1];
            app.FractionSeniorCitizensEditField_14.ValueChangedFcn = createCallbackFcn(app, @FractionSeniorCitizensEditField_14ValueChanged, true);
            app.FractionSeniorCitizensEditField_14.Value = 0.01;
            app.May21Tab = uitab(app.TabGroup4_1);
            app.May21Tab.Title = 'May 21';
            app.DuringthismonthPanel_15 = uipanel(app.May21Tab);
            app.DuringthismonthPanel_15.Title = 'During this month';
            app.BasicreproductionnumberLabel_15 = uilabel(app.DuringthismonthPanel_15);
            app.BasicreproductionnumberLabel_15.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_15Label = uilabel(app.DuringthismonthPanel_15);
            app.R0SymptomaticEditField_15Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_15 = uieditfield(app.DuringthismonthPanel_15, 'numeric');
            app.R0SymptomaticEditField_15.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_15ValueChanged, true);
            app.R0SymptomaticEditField_15.Value = 0.2;
            app.R0AsymptomaticEditField_15Label = uilabel(app.DuringthismonthPanel_15);
            app.R0AsymptomaticEditField_15Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_15 = uieditfield(app.DuringthismonthPanel_15, 'numeric');
            app.R0AsymptomaticEditField_15.Limits = [0 1000];
            app.R0AsymptomaticEditField_15.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_15ValueChanged, true);
            app.R0AsymptomaticEditField_15.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_14Label = uilabel(app.DuringthismonthPanel_15);
            app.FractionofsymptomaticpeopletreatedEditField_14Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_15 = uieditfield(app.DuringthismonthPanel_15, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_15.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_15.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_15ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_15.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_14Label = uilabel(app.DuringthismonthPanel_15);
            app.FractionofAsymptomaticpeopletreatedEditField_14Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_15 = uieditfield(app.DuringthismonthPanel_15, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_15.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_15.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_15ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_15.Value = 0.01;
            app.FractionVaccinatedLabel_15 = uilabel(app.DuringthismonthPanel_15);
            app.FractionVaccinatedLabel_15.Text = 'Fraction Vaccinated';
            app.FractionAdultsEditField_15Label = uilabel(app.DuringthismonthPanel_15);
            app.FractionAdultsEditField_15Label.Text = 'Fraction Adults';
            app.FractionAdultsEditField_15 = uieditfield(app.DuringthismonthPanel_15, 'numeric');
            app.FractionAdultsEditField_15.Limits = [0 1];
            app.FractionAdultsEditField_15.ValueChangedFcn = createCallbackFcn(app, @FractionAdultsEditField_15ValueChanged, true);
            app.FractionAdultsEditField_15.Value = 0.01;
            app.FractionSeniorCitizensEditField_15Label = uilabel(app.DuringthismonthPanel_15);
            app.FractionSeniorCitizensEditField_15Label.Text = 'Fraction Senior Citizens';
            app.FractionSeniorCitizensEditField_15 = uieditfield(app.DuringthismonthPanel_15, 'numeric');
            app.FractionSeniorCitizensEditField_15.Limits = [0 1];
            app.FractionSeniorCitizensEditField_15.ValueChangedFcn = createCallbackFcn(app, @FractionSeniorCitizensEditField_15ValueChanged, true);
            app.FractionSeniorCitizensEditField_15.Value = 0.01;
            app.Jun21Tab = uitab(app.TabGroup4_1);
            app.Jun21Tab.Title = 'Jun 21';
            app.DuringthismonthPanel_16 = uipanel(app.Jun21Tab);
            app.DuringthismonthPanel_16.Title = 'During this month';
            app.BasicreproductionnumberLabel_16 = uilabel(app.DuringthismonthPanel_16);
            app.BasicreproductionnumberLabel_16.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_16Label = uilabel(app.DuringthismonthPanel_16);
            app.R0SymptomaticEditField_16Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_16 = uieditfield(app.DuringthismonthPanel_16, 'numeric');
            app.R0SymptomaticEditField_16.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_16ValueChanged, true);
            app.R0SymptomaticEditField_16.Value = 0.2;
            app.R0AsymptomaticEditField_16Label = uilabel(app.DuringthismonthPanel_16);
            app.R0AsymptomaticEditField_16Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_16 = uieditfield(app.DuringthismonthPanel_16, 'numeric');
            app.R0AsymptomaticEditField_16.Limits = [0 1000];
            app.R0AsymptomaticEditField_16.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_16ValueChanged, true);
            app.R0AsymptomaticEditField_16.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_15Label = uilabel(app.DuringthismonthPanel_16);
            app.FractionofsymptomaticpeopletreatedEditField_15Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_16 = uieditfield(app.DuringthismonthPanel_16, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_16.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_16.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_16ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_16.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_15Label = uilabel(app.DuringthismonthPanel_16);
            app.FractionofAsymptomaticpeopletreatedEditField_15Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_16 = uieditfield(app.DuringthismonthPanel_16, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_16.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_16.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_16ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_16.Value = 0.01;
            app.FractionVaccinatedLabel_16 = uilabel(app.DuringthismonthPanel_16);
            app.FractionVaccinatedLabel_16.Text = 'Fraction Vaccinated';
            app.FractionAdultsEditField_16Label = uilabel(app.DuringthismonthPanel_16);
            app.FractionAdultsEditField_16Label.Text = 'Fraction Adults';
            app.FractionAdultsEditField_16 = uieditfield(app.DuringthismonthPanel_16, 'numeric');
            app.FractionAdultsEditField_16.Limits = [0 1];
            app.FractionAdultsEditField_16.ValueChangedFcn = createCallbackFcn(app, @FractionAdultsEditField_16ValueChanged, true);
            app.FractionAdultsEditField_16.Value = 0.01;
            app.FractionSeniorCitizensEditField_16Label = uilabel(app.DuringthismonthPanel_16);
            app.FractionSeniorCitizensEditField_16Label.Text = 'Fraction Senior Citizens';
            app.FractionSeniorCitizensEditField_16 = uieditfield(app.DuringthismonthPanel_16, 'numeric');
            app.FractionSeniorCitizensEditField_16.Limits = [0 1];
            app.FractionSeniorCitizensEditField_16.ValueChangedFcn = createCallbackFcn(app, @FractionSeniorCitizensEditField_16ValueChanged, true);
            app.FractionSeniorCitizensEditField_16.Value = 0.01;
            app.Jul21Tab = uitab(app.TabGroup4_1);
            app.Jul21Tab.Title = 'Jul 21';
            app.DuringthismonthPanel_17 = uipanel(app.Jul21Tab);
            app.DuringthismonthPanel_17.Title = 'During this month';
            app.BasicreproductionnumberLabel_17 = uilabel(app.DuringthismonthPanel_17);
            app.BasicreproductionnumberLabel_17.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_17Label = uilabel(app.DuringthismonthPanel_17);
            app.R0SymptomaticEditField_17Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_17 = uieditfield(app.DuringthismonthPanel_17, 'numeric');
            app.R0SymptomaticEditField_17.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_17ValueChanged, true);
            app.R0SymptomaticEditField_17.Value = 0.2;
            app.R0AsymptomaticEditField_17Label = uilabel(app.DuringthismonthPanel_17);
            app.R0AsymptomaticEditField_17Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_17 = uieditfield(app.DuringthismonthPanel_17, 'numeric');
            app.R0AsymptomaticEditField_17.Limits = [0 1000];
            app.R0AsymptomaticEditField_17.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_17ValueChanged, true);
            app.R0AsymptomaticEditField_17.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_16Label = uilabel(app.DuringthismonthPanel_17);
            app.FractionofsymptomaticpeopletreatedEditField_16Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_17 = uieditfield(app.DuringthismonthPanel_17, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_17.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_17.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_17ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_17.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_16Label = uilabel(app.DuringthismonthPanel_17);
            app.FractionofAsymptomaticpeopletreatedEditField_16Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_17 = uieditfield(app.DuringthismonthPanel_17, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_17.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_17.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_17ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_17.Value = 0.01;
            app.FractionVaccinatedLabel_17 = uilabel(app.DuringthismonthPanel_17);
            app.FractionVaccinatedLabel_17.Text = 'Fraction Vaccinated';
            app.FractionAdultsEditField_17Label = uilabel(app.DuringthismonthPanel_17);
            app.FractionAdultsEditField_17Label.Text = 'Fraction Adults';
            app.FractionAdultsEditField_17 = uieditfield(app.DuringthismonthPanel_17, 'numeric');
            app.FractionAdultsEditField_17.Limits = [0 1];
            app.FractionAdultsEditField_17.ValueChangedFcn = createCallbackFcn(app, @FractionAdultsEditField_17ValueChanged, true);
            app.FractionAdultsEditField_17.Value = 0.01;
            app.FractionSeniorCitizensEditField_17Label = uilabel(app.DuringthismonthPanel_17);
            app.FractionSeniorCitizensEditField_17Label.Text = 'Fraction Senior Citizens';
            app.FractionSeniorCitizensEditField_17 = uieditfield(app.DuringthismonthPanel_17, 'numeric');
            app.FractionSeniorCitizensEditField_17.Limits = [0 1];
            app.FractionSeniorCitizensEditField_17.ValueChangedFcn = createCallbackFcn(app, @FractionSeniorCitizensEditField_17ValueChanged, true);
            app.FractionSeniorCitizensEditField_17.Value = 0.01;
            app.Aug21Tab = uitab(app.TabGroup4_1);
            app.Aug21Tab.Title = 'Aug 21';
            app.DuringthismonthPanel_18 = uipanel(app.Aug21Tab);
            app.DuringthismonthPanel_18.Title = 'During this month';
            app.BasicreproductionnumberLabel_18 = uilabel(app.DuringthismonthPanel_18);
            app.BasicreproductionnumberLabel_18.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_18Label = uilabel(app.DuringthismonthPanel_18);
            app.R0SymptomaticEditField_18Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_18 = uieditfield(app.DuringthismonthPanel_18, 'numeric');
            app.R0SymptomaticEditField_18.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_18ValueChanged, true);
            app.R0SymptomaticEditField_18.Value = 0.2;
            app.R0AsymptomaticEditField_18Label = uilabel(app.DuringthismonthPanel_18);
            app.R0AsymptomaticEditField_18Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_18 = uieditfield(app.DuringthismonthPanel_18, 'numeric');
            app.R0AsymptomaticEditField_18.Limits = [0 1000];
            app.R0AsymptomaticEditField_18.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_18ValueChanged, true);
            app.R0AsymptomaticEditField_18.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_17Label = uilabel(app.DuringthismonthPanel_18);
            app.FractionofsymptomaticpeopletreatedEditField_17Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_18 = uieditfield(app.DuringthismonthPanel_18, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_18.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_18.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_18ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_18.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_17Label = uilabel(app.DuringthismonthPanel_18);
            app.FractionofAsymptomaticpeopletreatedEditField_17Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_18 = uieditfield(app.DuringthismonthPanel_18, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_18.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_18.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_18ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_18.Value = 0.01;
            app.FractionVaccinatedLabel_18 = uilabel(app.DuringthismonthPanel_18);
            app.FractionVaccinatedLabel_18.Text = 'Fraction Vaccinated';
            app.FractionAdultsEditField_18Label = uilabel(app.DuringthismonthPanel_18);
            app.FractionAdultsEditField_18Label.Text = 'Fraction Adults';
            app.FractionAdultsEditField_18 = uieditfield(app.DuringthismonthPanel_18, 'numeric');
            app.FractionAdultsEditField_18.Limits = [0 1];
            app.FractionAdultsEditField_18.ValueChangedFcn = createCallbackFcn(app, @FractionAdultsEditField_18ValueChanged, true);
            app.FractionAdultsEditField_18.Value = 0.01;
            app.FractionSeniorCitizensEditField_18Label = uilabel(app.DuringthismonthPanel_18);
            app.FractionSeniorCitizensEditField_18Label.Text = 'Fraction Senior Citizens';
            app.FractionSeniorCitizensEditField_18 = uieditfield(app.DuringthismonthPanel_18, 'numeric');
            app.FractionSeniorCitizensEditField_18.Limits = [0 1];
            app.FractionSeniorCitizensEditField_18.ValueChangedFcn = createCallbackFcn(app, @FractionSeniorCitizensEditField_18ValueChanged, true);
            app.FractionSeniorCitizensEditField_18.Value = 0.01;
            app.Sep21Tab = uitab(app.TabGroup4_1);
            app.Sep21Tab.Title = 'Sep 21';
            app.DuringthismonthPanel_19 = uipanel(app.Sep21Tab);
            app.DuringthismonthPanel_19.Title = 'During this month';
            app.BasicreproductionnumberLabel_19 = uilabel(app.DuringthismonthPanel_19);
            app.BasicreproductionnumberLabel_19.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_19Label = uilabel(app.DuringthismonthPanel_19);
            app.R0SymptomaticEditField_19Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_19 = uieditfield(app.DuringthismonthPanel_19, 'numeric');
            app.R0SymptomaticEditField_19.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_19ValueChanged, true);
            app.R0SymptomaticEditField_19.Value = 0.2;
            app.R0AsymptomaticEditField_19Label = uilabel(app.DuringthismonthPanel_19);
            app.R0AsymptomaticEditField_19Label.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_19 = uieditfield(app.DuringthismonthPanel_19, 'numeric');
            app.R0AsymptomaticEditField_19.Limits = [0 1000];
            app.R0AsymptomaticEditField_19.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_19ValueChanged, true);
            app.R0AsymptomaticEditField_19.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_18Label = uilabel(app.DuringthismonthPanel_19);
            app.FractionofsymptomaticpeopletreatedEditField_18Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_19 = uieditfield(app.DuringthismonthPanel_19, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_19.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_19.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_19ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_19.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_18Label = uilabel(app.DuringthismonthPanel_19);
            app.FractionofAsymptomaticpeopletreatedEditField_18Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_19 = uieditfield(app.DuringthismonthPanel_19, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_19.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_19.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_19ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_19.Value = 0.01;
            app.FractionVaccinatedLabel_19 = uilabel(app.DuringthismonthPanel_19);
            app.FractionVaccinatedLabel_19.Text = 'Fraction Vaccinated';
            app.FractionAdultsEditField_19Label = uilabel(app.DuringthismonthPanel_19);
            app.FractionAdultsEditField_19Label.Text = 'Fraction Adults';
            app.FractionAdultsEditField_19 = uieditfield(app.DuringthismonthPanel_19, 'numeric');
            app.FractionAdultsEditField_19.Limits = [0 1];
            app.FractionAdultsEditField_19.ValueChangedFcn = createCallbackFcn(app, @FractionAdultsEditField_19ValueChanged, true);
            app.FractionAdultsEditField_19.Value = 0.01;
            app.FractionSeniorCitizensEditField_19Label = uilabel(app.DuringthismonthPanel_19);
            app.FractionSeniorCitizensEditField_19Label.Text = 'Fraction Senior Citizens';
            app.FractionSeniorCitizensEditField_19 = uieditfield(app.DuringthismonthPanel_19, 'numeric');
            app.FractionSeniorCitizensEditField_19.Limits = [0 1];
            app.FractionSeniorCitizensEditField_19.ValueChangedFcn = createCallbackFcn(app, @FractionSeniorCitizensEditField_19ValueChanged, true);
            app.FractionSeniorCitizensEditField_19.Value = 0.01;
            app.Oct21Tab = uitab(app.TabGroup4_1);
            app.Oct21Tab.Title = 'Oct 21';
            app.DuringthismonthPanel_20 = uipanel(app.Oct21Tab);
            app.DuringthismonthPanel_20.Title = 'During this month';
            app.BasicreproductionnumberLabel_20 = uilabel(app.DuringthismonthPanel_20);
            app.BasicreproductionnumberLabel_20.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_20Label = uilabel(app.DuringthismonthPanel_20);
            app.R0SymptomaticEditField_20Label.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_20 = uieditfield(app.DuringthismonthPanel_20, 'numeric');
            app.R0SymptomaticEditField_20.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_20ValueChanged, true);
            app.R0SymptomaticEditField_20.Value = 0.2;
            app.R0AsymptomaticEditField_20Label_2 = uilabel(app.DuringthismonthPanel_20);
            app.R0AsymptomaticEditField_20Label_2.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_20 = uieditfield(app.DuringthismonthPanel_20, 'numeric');
            app.R0AsymptomaticEditField_20.Limits = [0 1000];
            app.R0AsymptomaticEditField_20.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_20ValueChanged, true);
            app.R0AsymptomaticEditField_20.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_19Label = uilabel(app.DuringthismonthPanel_20);
            app.FractionofsymptomaticpeopletreatedEditField_19Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_20 = uieditfield(app.DuringthismonthPanel_20, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_20.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_20.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_20ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_20.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_19Label = uilabel(app.DuringthismonthPanel_20);
            app.FractionofAsymptomaticpeopletreatedEditField_19Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_20 = uieditfield(app.DuringthismonthPanel_20, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_20.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_20.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_20ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_20.Value = 0.01;
            app.FractionVaccinatedLabel_20 = uilabel(app.DuringthismonthPanel_20);
            app.FractionVaccinatedLabel_20.Text = 'Fraction Vaccinated';
            app.FractionAdultsEditField_20Label = uilabel(app.DuringthismonthPanel_20);
            app.FractionAdultsEditField_20Label.Text = 'Fraction Adults';
            app.FractionAdultsEditField_20 = uieditfield(app.DuringthismonthPanel_20, 'numeric');
            app.FractionAdultsEditField_20.Limits = [0 1];
            app.FractionAdultsEditField_20.ValueChangedFcn = createCallbackFcn(app, @FractionAdultsEditField_20ValueChanged, true);
            app.FractionAdultsEditField_20.Value = 0.01;
            app.FractionSeniorCitizensEditField_20Label = uilabel(app.DuringthismonthPanel_20);
            app.FractionSeniorCitizensEditField_20Label.Text = 'Fraction Senior Citizens';
            app.FractionSeniorCitizensEditField_20 = uieditfield(app.DuringthismonthPanel_20, 'numeric');
            app.FractionSeniorCitizensEditField_20.Limits = [0 1];
            app.FractionSeniorCitizensEditField_20.ValueChangedFcn = createCallbackFcn(app, @FractionSeniorCitizensEditField_20ValueChanged, true);
            app.FractionSeniorCitizensEditField_20.Value = 0.01;
            app.Nov21Tab = uitab(app.TabGroup4_1);
            app.Nov21Tab.Title = 'Nov 21';
            app.DuringthismonthPanel_21 = uipanel(app.Nov21Tab);
            app.DuringthismonthPanel_21.Title = 'During this month';
            app.BasicreproductionnumberLabel_21 = uilabel(app.DuringthismonthPanel_21);
            app.BasicreproductionnumberLabel_21.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_21Label_2 = uilabel(app.DuringthismonthPanel_21);
            app.R0SymptomaticEditField_21Label_2.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_21 = uieditfield(app.DuringthismonthPanel_21, 'numeric');
            app.R0SymptomaticEditField_21.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_21ValueChanged, true);
            app.R0SymptomaticEditField_21.Value = 0.2;
            app.R0AsymptomaticEditField_21Label_2 = uilabel(app.DuringthismonthPanel_21);
            app.R0AsymptomaticEditField_21Label_2.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_21 = uieditfield(app.DuringthismonthPanel_21, 'numeric');
            app.R0AsymptomaticEditField_21.Limits = [0 1000];
            app.R0AsymptomaticEditField_21.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_21ValueChanged, true);
            app.R0AsymptomaticEditField_21.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_20Label = uilabel(app.DuringthismonthPanel_21);
            app.FractionofsymptomaticpeopletreatedEditField_20Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_21 = uieditfield(app.DuringthismonthPanel_21, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_21.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_21.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_21ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_21.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_20Label = uilabel(app.DuringthismonthPanel_21);
            app.FractionofAsymptomaticpeopletreatedEditField_20Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_21 = uieditfield(app.DuringthismonthPanel_21, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_21.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_21.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_21ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_21.Value = 0.01;
            app.FractionVaccinatedLabel_21 = uilabel(app.DuringthismonthPanel_21);
            app.FractionVaccinatedLabel_21.Text = 'Fraction Vaccinated';
            app.FractionAdultsEditField_21Label = uilabel(app.DuringthismonthPanel_21);
            app.FractionAdultsEditField_21Label.Text = 'Fraction Adults';
            app.FractionAdultsEditField_21 = uieditfield(app.DuringthismonthPanel_21, 'numeric');
            app.FractionAdultsEditField_21.Limits = [0 1];
            app.FractionAdultsEditField_21.ValueChangedFcn = createCallbackFcn(app, @FractionAdultsEditField_21ValueChanged, true);
            app.FractionAdultsEditField_21.Value = 0.01;
            app.FractionSeniorCitizensEditField_21Label = uilabel(app.DuringthismonthPanel_21);
            app.FractionSeniorCitizensEditField_21Label.Text = 'Fraction Senior Citizens';
            app.FractionSeniorCitizensEditField_21 = uieditfield(app.DuringthismonthPanel_21, 'numeric');
            app.FractionSeniorCitizensEditField_21.Limits = [0 1];
            app.FractionSeniorCitizensEditField_21.ValueChangedFcn = createCallbackFcn(app, @FractionSeniorCitizensEditField_21ValueChanged, true);
            app.FractionSeniorCitizensEditField_21.Value = 0.01;
            app.Dec21Tab = uitab(app.TabGroup4_1);
            app.Dec21Tab.Title = 'Dec 21';
            app.DuringthismonthPanel_22 = uipanel(app.Dec21Tab);
            app.DuringthismonthPanel_22.Title = 'During this month';
            app.BasicreproductionnumberLabel_22 = uilabel(app.DuringthismonthPanel_22);
            app.BasicreproductionnumberLabel_22.Text = 'Basic reproduction number';
            app.R0SymptomaticEditField_22Label_2 = uilabel(app.DuringthismonthPanel_22);
            app.R0SymptomaticEditField_22Label_2.Text = 'R0 - Symptomatic';
            app.R0SymptomaticEditField_22 = uieditfield(app.DuringthismonthPanel_22, 'numeric');
            app.R0SymptomaticEditField_22.ValueChangedFcn = createCallbackFcn(app, @R0SymptomaticEditField_22ValueChanged, true);
            app.R0SymptomaticEditField_22.Value = 0.2;
            app.R0AsymptomaticEditField_22Label_2 = uilabel(app.DuringthismonthPanel_22);
            app.R0AsymptomaticEditField_22Label_2.Text = 'R0 - Asymptomatic';
            app.R0AsymptomaticEditField_22 = uieditfield(app.DuringthismonthPanel_22, 'numeric');
            app.R0AsymptomaticEditField_22.Limits = [0 1000];
            app.R0AsymptomaticEditField_22.ValueChangedFcn = createCallbackFcn(app, @R0AsymptomaticEditField_22ValueChanged, true);
            app.R0AsymptomaticEditField_22.Value = 2.28;
            app.FractionofsymptomaticpeopletreatedEditField_21Label = uilabel(app.DuringthismonthPanel_22);
            app.FractionofsymptomaticpeopletreatedEditField_21Label.Text = 'Fraction of symptomatic people treated';
            app.FractionofsymptomaticpeopletreatedEditField_22 = uieditfield(app.DuringthismonthPanel_22, 'numeric');
            app.FractionofsymptomaticpeopletreatedEditField_22.Limits = [0 1];
            app.FractionofsymptomaticpeopletreatedEditField_22.ValueChangedFcn = createCallbackFcn(app, @FractionofsymptomaticpeopletreatedEditField_22ValueChanged, true);
            app.FractionofsymptomaticpeopletreatedEditField_22.Value = 0.9;
            app.FractionofAsymptomaticpeopletreatedEditField_21Label = uilabel(app.DuringthismonthPanel_22);
            app.FractionofAsymptomaticpeopletreatedEditField_21Label.Text = 'Fraction of Asymptomatic people treated';
            app.FractionofAsymptomaticpeopletreatedEditField_22 = uieditfield(app.DuringthismonthPanel_22, 'numeric');
            app.FractionofAsymptomaticpeopletreatedEditField_22.Limits = [0 1];
            app.FractionofAsymptomaticpeopletreatedEditField_22.ValueChangedFcn = createCallbackFcn(app, @FractionofAsymptomaticpeopletreatedEditField_22ValueChanged, true);
            app.FractionofAsymptomaticpeopletreatedEditField_22.Value = 0.01;
            app.FractionVaccinatedLabel_22 = uilabel(app.DuringthismonthPanel_22);
            app.FractionVaccinatedLabel_22.Text = 'Fraction Vaccinated';
            app.FractionAdultsEditField_22Label = uilabel(app.DuringthismonthPanel_22);
            app.FractionAdultsEditField_22Label.Text = 'Fraction Adults';
            app.FractionAdultsEditField_22 = uieditfield(app.DuringthismonthPanel_22, 'numeric');
            app.FractionAdultsEditField_22.Limits = [0 1];
            app.FractionAdultsEditField_22.ValueChangedFcn = createCallbackFcn(app, @FractionAdultsEditField_22ValueChanged, true);
            app.FractionAdultsEditField_22.Value = 0.01;
            app.FractionSeniorCitizensEditField_22Label = uilabel(app.DuringthismonthPanel_22);
            app.FractionSeniorCitizensEditField_22Label.Text = 'Fraction Senior Citizens';
            app.FractionSeniorCitizensEditField_22 = uieditfield(app.DuringthismonthPanel_22, 'numeric');
            app.FractionSeniorCitizensEditField_22.Limits = [0 1];
            app.FractionSeniorCitizensEditField_22.ValueChangedFcn = createCallbackFcn(app, @FractionSeniorCitizensEditField_22ValueChanged, true);
            app.FractionSeniorCitizensEditField_22.Value = 0.01;
            app.Image = uiimage(app.CTMUIFigure);
            app.Image.ImageSource = 'IndiaRegion.jpg';
            app.VariationEditFieldLabel = uilabel(app.CTMUIFigure);
            app.VariationEditFieldLabel.Text = 'Variation %';
            app.VariationEditField = uieditfield(app.CTMUIFigure, 'numeric');
            app.VariationEditField.Limits = [0.01 20];
            app.VariationEditField.ValueDisplayFormat = '%11.2g';
            app.VariationEditField.ValueChangedFcn = createCallbackFcn(app, @VariationEditFieldValueChanged, true);
            app.VariationEditField.Value = 5;
            app.SimulationTypeButtonGroup = uibuttongroup(app.CTMUIFigure);
            app.SimulationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SimulationTypeButtonGroupSelectionChanged, true);
            app.SimulationTypeButtonGroup.Title = 'Simulation Type';
            app.DeterministicDefaultButton = uiradiobutton(app.SimulationTypeButtonGroup);
            app.DeterministicDefaultButton.Text = 'Deterministic (Default)';
            app.DeterministicDefaultButton.Value = true;
            app.MonteCarloButton = uiradiobutton(app.SimulationTypeButtonGroup);
            app.MonteCarloButton.Text = 'Monte Carlo';
            app.RegionLabel = uilabel(app.CTMUIFigure);
            app.RegionLabel.Text = 'Region';
            app.Image7 = uiimage(app.CTMUIFigure);
            app.Image7.ImageSource = 'IITMLogo.png';
            app.Image8 = uiimage(app.CTMUIFigure);
            app.Image8.ImageSource = 'Mid.png';
            app.Image9 = uiimage(app.CTMUIFigure);
            app.Image9.ImageSource = 'SERBLogo.png';
            app.LoadRegionInfoButton = uibutton(app.CTMUIFigure, 'push');
            app.LoadRegionInfoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRegionInfoButtonPushed, true);
            app.LoadRegionInfoButton.Text = 'Load Region Info';
            % Show the figure after all components are created
            app.CTMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid19Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CTMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CTMUIFigure)
        end
    end
end